classdef Material_Modeler_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        mainTab                         matlab.ui.container.Tab
        DropDown_density                matlab.ui.control.DropDown
        specimen_type                   matlab.ui.control.DropDown
        tensilespecimentypeLabel        matlab.ui.control.Label
        MaterialEditFieldLabel          matlab.ui.control.Label
        MaterialEditField               matlab.ui.control.EditField
        DensityEditFieldLabel           matlab.ui.control.Label
        DensityEditField                matlab.ui.control.NumericEditField
        PerformedtestDropDownLabel      matlab.ui.control.Label
        PerformedtestDropDown           matlab.ui.control.DropDown
        load_status                     matlab.ui.control.Lamp
        DataselectiontensilebulgetestPanel  matlab.ui.container.Panel
        Select_TT                       matlab.ui.control.Button
        DirectiondegreeLabel            matlab.ui.control.Label
        DatafilebulgetestLabel          matlab.ui.control.Label
        Select_bulge                    matlab.ui.control.Button
        Deletelast_TT                   matlab.ui.control.Button
        degree_EditField                matlab.ui.control.NumericEditField
        UITable_TT                      matlab.ui.control.Table
        DefineBaseVektorfortheaveragingofthemeasurementdataLabel  matlab.ui.control.Label
        basevectorButton                matlab.ui.control.Button
        beginmmLabel                    matlab.ui.control.Label
        bv_begin                        matlab.ui.control.EditField
        stepsizemmLabel                 matlab.ui.control.Label
        bv_stepsize                     matlab.ui.control.EditField
        endmmLabel                      matlab.ui.control.Label
        bv_end                          matlab.ui.control.EditField
        loaddataButton                  matlab.ui.control.Button
        manipulatedataButton            matlab.ui.control.Button
        TextArea2                       matlab.ui.control.TextArea
        ExportAbaqusinpButton           matlab.ui.control.Button
        UITable_crush                   matlab.ui.control.Table
        DropDown3                       matlab.ui.control.DropDown
        export_status                   matlab.ui.control.Lamp
        helpButton_2                    matlab.ui.control.Button
        Panel_TT                        matlab.ui.container.Panel
        pronouncedyieldstrenghCheckBox  matlab.ui.control.CheckBox
        calculateEModulusRmvalueButton  matlab.ui.control.Button
        calc_status                     matlab.ui.control.Lamp
        select_region_yield             matlab.ui.control.Button
        selection_status                matlab.ui.control.Lamp
        UITable_E_Rm                    matlab.ui.control.Table
        calculateyieldcurvervaluenvalueButton  matlab.ui.control.Button
        select_rangeforrvaluecalculationButton  matlab.ui.control.Button
        calc_yield_status               matlab.ui.control.Lamp
        epsilonRpEditField              matlab.ui.control.NumericEditField
        RpValueLabel                    matlab.ui.control.Label
        recalculatenvalueButton         matlab.ui.control.Button
        select_rangefornvaluecalculationButton  matlab.ui.control.Button
        UITable_rn_value                matlab.ui.control.Table
        calc_n_value                    matlab.ui.control.Lamp
        extrapolationButton             matlab.ui.control.Button
        selectextrapolationapproachButton  matlab.ui.control.Button
        TextEModulus                    matlab.ui.control.TextArea
        bulge_extrapolation             matlab.ui.control.Lamp
        helpButton_3                    matlab.ui.control.Button
        Text_extrapolation              matlab.ui.control.TextArea
        InsertEModulushereLabel         matlab.ui.control.Label
        helpButton                      matlab.ui.control.Button
        PoissonsnumberEditField_2Label  matlab.ui.control.Label
        Poissonsnumber                  matlab.ui.control.NumericEditField
        visualizeresultsButton          matlab.ui.control.Button
        SavedatabaseButton              matlab.ui.control.Button
        helpButton_4                    matlab.ui.control.Button
        Text_save                       matlab.ui.control.TextArea
        DataselectioncrushtestPanel     matlab.ui.container.Panel
        select_crush                    matlab.ui.control.Button
        DatafilecrushtestLabel          matlab.ui.control.Label
        Stiffnes_needed                 matlab.ui.control.CheckBox
        StiffnescorrelationneededLabel  matlab.ui.control.Label
        select_stiffness                matlab.ui.control.Button
        DatafilestiffnesstestLabel      matlab.ui.control.Label
        Panel_CT                        matlab.ui.container.Panel
        pronouncedyieldstrenghCheckBox_crush  matlab.ui.control.CheckBox
        select_region_yield_crush       matlab.ui.control.Button
        selection_status_crush          matlab.ui.control.Lamp
        epsilonRpEditField_crush        matlab.ui.control.NumericEditField
        RpValueLabel_crush              matlab.ui.control.Label
        calculation_crush               matlab.ui.control.Button
        calculateyieldcurve_crush       matlab.ui.control.Button
        Table_EMod_Crush                matlab.ui.control.Table
        helpButton_crush                matlab.ui.control.Button
        calc_yield_crush                matlab.ui.control.Lamp
        calc_EMod_crush                 matlab.ui.control.Lamp
        InsertEModulushereLabel_2       matlab.ui.control.Label
        TextEModulus_2                  matlab.ui.control.TextArea
        TabGroup2                       matlab.ui.container.TabGroup
        labelstensiletestTab            matlab.ui.container.Tab
        displacementLabelEditFieldLabel  matlab.ui.control.Label
        displacementLabelEditField      matlab.ui.control.EditField
        forceLabelEditFieldLabel        matlab.ui.control.Label
        forceLabelEditField             matlab.ui.control.EditField
        timeLabelEditFieldLabel         matlab.ui.control.Label
        timeLabelEditField              matlab.ui.control.EditField
        widthLabel                      matlab.ui.control.Label
        widthLabelEditField             matlab.ui.control.EditField
        thicknessInitialLabelEditField_2Label  matlab.ui.control.Label
        thicknessInitialLabelEditField  matlab.ui.control.EditField
        lengthInitialLabelEditField_2Label  matlab.ui.control.Label
        lengthInitialLabelEditField     matlab.ui.control.EditField
        widthInitialLabelEditField_2Label  matlab.ui.control.Label
        widthInitialLabelEditField      matlab.ui.control.EditField
        helpButton_labelsTT             matlab.ui.control.Button
        labelsbulgetestTab              matlab.ui.container.Tab
        trueStressLabelEditFieldLabel   matlab.ui.control.Label
        trueStressLabelEditField        matlab.ui.control.EditField
        trueStrainLabelEditFieldLabel   matlab.ui.control.Label
        trueStrainLabelEditField        matlab.ui.control.EditField
        timeLabelEditField_2Label       matlab.ui.control.Label
        timeLabelEditField_bulge        matlab.ui.control.EditField
        labelscrushtestandstiffnesscorrelationTab  matlab.ui.container.Tab
        displacementLabelEditField_2Label  matlab.ui.control.Label
        displacementLabelEditField_crush  matlab.ui.control.EditField
        forceLabelEditField_2Label      matlab.ui.control.Label
        forceLabelEditField_crush       matlab.ui.control.EditField
        diameterInitialLabel            matlab.ui.control.Label
        diameterInitialLabelEditField   matlab.ui.control.EditField
        lengthInitialLabelEditField_2Label_2  matlab.ui.control.Label
        lengthInitialLabelEditField_crush  matlab.ui.control.EditField
        timeLabelEditField_2Label_2     matlab.ui.control.Label
        timeLabelEditField_crush        matlab.ui.control.EditField
        helpButton_labelsTT_2           matlab.ui.control.Button
        datamanipulationTab             matlab.ui.container.Tab
        Panel_tensile_bulge             matlab.ui.container.Panel
        DropDown_emodulus               matlab.ui.control.DropDown
        EModulusButton                  matlab.ui.control.Button
        choosedataforEModuluscalculationLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        reaveragedataButton             matlab.ui.control.Button
        manipulate_basic                matlab.ui.control.Button
        DropDown_basic                  matlab.ui.control.DropDown
        manipulatefitthedataseriesbevoraveragingLabel  matlab.ui.control.Label
        maipualte_averaged              matlab.ui.control.Button
        DropDown_averaged               matlab.ui.control.DropDown
        manipulatefitaverageddataseriesLabel  matlab.ui.control.Label
        comparebasicdataseriesButton    matlab.ui.control.StateButton
        displayButton                   matlab.ui.control.StateButton
        ButtonGroup                     matlab.ui.container.ButtonGroup
        tensiletestButton               matlab.ui.control.ToggleButton
        bulgetestButton                 matlab.ui.control.ToggleButton
        UIAxes                          matlab.ui.control.UIAxes
        load_data_label                 matlab.ui.control.Label
        returnButton_4                  matlab.ui.control.Button
        Panel_crush                     matlab.ui.container.Panel
        manipulatefitaveragedcrushdataLabel  matlab.ui.control.Label
        maipualte_averaged_crush        matlab.ui.control.Button
        manipulatefitcrushdatabevoreaveragingLabel  matlab.ui.control.Label
        manipulate_basic_crush          matlab.ui.control.Button
        replotreaveragedataButton_crush  matlab.ui.control.Button
        choosedataforEModuluscalculationLabel_2  matlab.ui.control.Label
        EModulusButton_crush            matlab.ui.control.Button
        displayButton_2                 matlab.ui.control.StateButton
        resetdataButton                 matlab.ui.control.Button
        importindicesButton             matlab.ui.control.Button
        DropDown_index                  matlab.ui.control.DropDown
        saveindicesButton               matlab.ui.control.Button
        rnvalueboundarydeterminationTab  matlab.ui.container.Tab
        rvalueboundarydeterminationinpercentLabel  matlab.ui.control.Label
        UIAxes_r                        matlab.ui.control.UIAxes
        UIAxes_n                        matlab.ui.control.UIAxes
        UITable_rvalue                  matlab.ui.control.Table
        TextArea3                       matlab.ui.control.TextArea
        PoissonsnumberEditFieldLabel    matlab.ui.control.Label
        PoissonsnumberEditField         matlab.ui.control.NumericEditField
        returnButton_3                  matlab.ui.control.Button
        load_data_label_r               matlab.ui.control.Label
        UITable_nvalue                  matlab.ui.control.Table
        nvalueboundarydeterminationinnumbersyouseeintheplotLabel  matlab.ui.control.Label
        extrapolationapproachTab        matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        DirectionLabel                  matlab.ui.control.Label
        DirectionDropDown               matlab.ui.control.DropDown
        LudwikCheckBox                  matlab.ui.control.CheckBox
        GoshCheckBox                    matlab.ui.control.CheckBox
        HockettSherbyCheckBox           matlab.ui.control.CheckBox
        SwiftCheckBox                   matlab.ui.control.CheckBox
        VoceCheckBox                    matlab.ui.control.CheckBox
        ApproachLabel                   matlab.ui.control.Label
        firstselectionDropDown          matlab.ui.control.DropDown
        secondselectionDropDown         matlab.ui.control.DropDown
        SelectedapproachforbulgeextrapolationLabel  matlab.ui.control.Label
        returnButton_2                  matlab.ui.control.Button
        UITable_approach                matlab.ui.control.Table
        DirectionDropDown_2Label        matlab.ui.control.Label
        DirectionDropDown_2             matlab.ui.control.DropDown
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        exportextrapolationapproachButton  matlab.ui.control.ToggleButton
        bulgeextrapolaitionapproachButton  matlab.ui.control.ToggleButton
        SelectedapproachforexportextrapolationLabel  matlab.ui.control.Label
        UITable_approach_export         matlab.ui.control.Table
        approachDropDown                matlab.ui.control.DropDown
        FirstapproachLabel              matlab.ui.control.Label
        SecondapproachLabel             matlab.ui.control.Label
        extrapolationparametersTab      matlab.ui.container.Tab
        ExtrapolationApproachStartPointsPanel  matlab.ui.container.Panel
        L1                              matlab.ui.control.NumericEditField
        L2                              matlab.ui.control.NumericEditField
        LudwikLabel                     matlab.ui.control.Label
        G1                              matlab.ui.control.NumericEditField
        G2                              matlab.ui.control.NumericEditField
        G3                              matlab.ui.control.NumericEditField
        GoshLabel                       matlab.ui.control.Label
        HS1                             matlab.ui.control.NumericEditField
        HS2                             matlab.ui.control.NumericEditField
        HS3                             matlab.ui.control.NumericEditField
        HocketSherbyLabel_3             matlab.ui.control.Label
        S1                              matlab.ui.control.NumericEditField
        S2                              matlab.ui.control.NumericEditField
        S3                              matlab.ui.control.NumericEditField
        SwiftLabel                      matlab.ui.control.Label
        V1                              matlab.ui.control.NumericEditField
        V2                              matlab.ui.control.NumericEditField
        VoceLabel                       matlab.ui.control.Label
        UpdateParametersButton          matlab.ui.control.Button
        FreespacetoaddsomemoreparametersLabel  matlab.ui.control.Label
        ParameterUpdated                matlab.ui.control.Lamp
        VisualisationTab                matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        selectdatatoplotDropDownLabel   matlab.ui.control.Label
        selectdatatoplotDropDown        matlab.ui.control.DropDown
        DirectionDropDown_3             matlab.ui.control.DropDown
        createfigureButton              matlab.ui.control.Button
        TextArea4                       matlab.ui.control.TextArea
        returnButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        TT_Database % number of directions x 2 cell array in wich the tensile eval. data for each direction is stored: [direction[°];databaseTT]
        TT_filepath % number of directions x 3 cell array [direction[°];fn;path]
        BT_Database % number of directions x 2 cell array in wich the bulge interpolation data for each direction is sored [[direction[°];databaseB]]
        BT_filepath % number of directions x 2 cell array [fn;path]
        CT_Database % cell array in wich the crush test data is stored: [databaseCT]
        CT_filepath % number of directions x 3 cell array [direction[°];fn;path]
        Stiff_filepath % filepath of the stiffness test
        tabledata % Data displayed in the table
        basevector % base vector for averaging the expemiremt data. [begin, step_size, end]
        eModulusIndexTT
        eModulusIndexCT
        indexPronouncedYieldStrength % Index for the calulation of the yield strengh, if a pornounced yield streng has been selected.
        E_Rm_table % data of the Table to display the E-Modulus and the Rm value
        E_input_correct % checks if the input for the E-Modulus is correct
        r_input_correct % checks if the input for the r value calculation range is correct
        n_input_correct % checks if the input for the n value calculation range is correct
        exportdata
        averagedindex % index for the averaged data
        baseindex % index for the base data
        expParameter % stores the parameters for the different extrapolation approaches. (Start Points)
    end

    methods (Access = private)
    
        function plot_label = plot_TT(app)
            %% plot average Experimental Data
            % ================================            
            TT_x_data = cell(1,size(app.TT_Database,1));
            TT_y_data = cell(1,size(app.TT_Database,1));
            
            rows = checkLabels(database, 'lengthTensileTest', 'forceTensileTest');
                        
            for i_direction = 1:size(app.TT_Database,1)
                databaseTT = app.TT_Database{i_direction,2};
                
                TT_x_data(1,i_direction) = databaseTT(rows(1),2);
                TT_y_data(1,i_direction) = databaseTT(rows(2),2);
            
                i_label = [num2str(app.TT_Database{i_direction,1}),'-degree'];
                
                if i_direction == 1 
                    plot(app.UIAxes,TT_x_data{1,i_direction},TT_y_data{1,i_direction},'LineWidth',1.5);
                    hold(app.UIAxes,'on');
                    plot_label = {i_label};
                else 
                    plot(app.UIAxes,TT_x_data{1,i_direction},TT_y_data{1,i_direction},'LineWidth',1.5);
                    plot_label{1,end+1} = i_label;
                end
            end         
            legend(app.UIAxes,plot_label,'Orientation','horizontal','Location','southeast');
            app.UIAxes.XLabel.String = 'displacement [mm]';
            app.UIAxes.XLabel.FontSize = 12;
            app.UIAxes.YLabel.String = 'force [N]';
            app.UIAxes.YLabel.FontSize = 12;
            app.UIAxes.LineWidth = 1;
            app.UIAxes.Title.String = 'averaged tensile test data';
            hold(app.UIAxes,'off');
               
        end
        
        function f = javawaitbar(~,controller,Message)
            % creates the waitbar in java.
            % controller = 0: close waitbar
            %            = 1: open new figure
            if controller == 1
                % Waitbar (javacomponent)
                f = figure('NumberTitle','off','MenuBar','none','ToolBar','none',...
                    'Resize','off','Units','normalized','Position',[0.45 0.5 0.15 0.03],...
                    'Name',Message);
                   
                [progressBar,container] = javacomponent('javax.swing.JProgressBar',[],f);
                container.Units = 'normalized';
                container.Position = [0, 0, 1, 1];
                progressBar.setIndeterminate(true);
                figure(f)
            elseif ishandle(f)
                close(f);
            end
            
        end
        
        function plot_CT(app)
            %% plot average Experimental Data
            % ================================            
            rows = checkLabels(app.CT_Database, 'lengthCrushTestEdit', 'forceCrushTestEdit', 'lengthCrushTestOriginal', 'forceCrushTestOriginal', ...
                                                'lengthCrushTest', 'forceCrushTest');
            
            % plot the original data
            x_data = app.CT_Database{rows(3),2};
            y_data = app.CT_Database{rows(4),2};
            plot(app.UIAxes,x_data,y_data,'LineWidth',1.5,'Color','blue');
            hold(app.UIAxes,'on');
            % plot the averaged data
            x_data = app.CT_Database{rows(5),2};
            y_data = app.CT_Database{rows(6),2};
            plot(app.UIAxes,x_data,y_data,'LineWidth',1.5,'Color','red');
            
            if app.Stiffnes_needed.Value == 1
                % plot the correlated data
                x_data = app.CT_Database{rows(1),2};
                y_data = app.CT_Database{rows(2),2};
                plot(app.UIAxes,x_data,y_data,'LineWidth',1,'LineStyle','--','Color','black');
                plot_label = {'crush-original','crush-averaged','crush-stiffness-correlated'};
            else
                plot_label = {'crush-original','crush-averaged'};
            end
            
            legend(app.UIAxes,plot_label,'Orientation','horizontal','Location','southeast');
            app.UIAxes.XLabel.String = 'displacement [mm]';
            app.UIAxes.XLabel.FontSize = 12;
            app.UIAxes.YLabel.String = 'force [N]';
            app.UIAxes.YLabel.FontSize = 12;
            app.UIAxes.LineWidth = 1;
            app.UIAxes.Title.String = 'averaged crush data';
            hold(app.UIAxes,'off');
           
        end
        
        function plot_BT(app)
            %% plot average Experimental Data
            % ================================            
            rows = checkLabels(app.BT_Database, 'epsilonTruePlBulge', 'sigmaTruePlBulge', 'timeBulge');
            
            % plot the original data
            x_data = app.BT_Database{rows(1),2};
            y_data = app.BT_Database{rows(2),2};
            
            plot(app.UIAxes,x_data,y_data,'LineWidth',1.5);
            hold(app.UIAxes,'on');
            plot_label = {'bulge-averaged'};
            legend(app.UIAxes,plot_label,'Orientation','horizontal','Location','southeast');
            app.UIAxes.XLabel.String = 'strain [mm]';
            app.UIAxes.XLabel.FontSize = 12;
            app.UIAxes.YLabel.String = 'stress [N/mm]';
            app.UIAxes.YLabel.FontSize = 12;
            app.UIAxes.LineWidth = 1;
            app.UIAxes.Title.String = 'averaged bulge data';
            hold(app.UIAxes,'off');
        end
        
        function plot_label = plot_r(app)
            epsilon_max = 0;
            for i_direction = 1:size(app.TT_Database,1)
                databaseTT = app.TT_Database{i_direction,2};
                rows = checkLabels(databaseTT, 'epsilonLEng', 'sigmaLEng');
                epsilon = databaseTT{rows(1),2};
                epsilon_max = max(epsilon_max,epsilon(end));
                sigma = databaseTT{rows(2),2}; 
                i_label = [num2str(app.TT_Database{i_direction,1}),'-degree']; 
                if i_direction == 1 
                    plot(app.UIAxes_r,epsilon*100,sigma,'LineWidth',1.5);
                    hold(app.UIAxes_r,'on');
                    plot_label = {i_label};
                else 
                    plot(app.UIAxes_r,epsilon*100,sigma,'LineWidth',1.5);
                    plot_label{1,end+1} = i_label;
                end
            end
            legend(app.UIAxes_r,plot_label,'Orientation','horizontal','Location','southeast');
            app.UIAxes_r.XLabel.String = '\epsilon_L^{eng} [%]';
            app.UIAxes_r.YLabel.String = '\sigma_L^{eng} [N/mm^2]';
            app.UIAxes_r.XLabel.FontSize = 12;
            app.UIAxes_r.YLabel.FontSize = 12;
            app.UIAxes_r.LineWidth = 1;
            x_max = round(epsilon_max*100,-1);
            if x_max < epsilon_max*100
                x_max = x_max + 10;
            end
            app.UIAxes_r.XLim = [0 x_max];
            hold(app.UIAxes_r,'off');
       end
        
       function plot_label = plot_n(app)
           hold(app.UIAxes_n,'off');
           for i_direction = 1:size(app.TT_Database,1)
               databaseTT = app.TT_Database{i_direction,2};
               rows = checkLabels(databaseTT, 'epsilonLTruePl', 'sigmaLTruePl');
               epsilonLTruePlLog = log(databaseTT{rows(1),2});
               sigmaLTruePlLog = log(databaseTT{rows(2),2});
               i_label = [num2str(app.TT_Database{i_direction,1}),'-degree'];
               if i_direction == 1 
                    plot(app.UIAxes_n,epsilonLTruePlLog,sigmaLTruePlLog,'LineWidth',1.5);
                    hold(app.UIAxes_n,'on');
                    plot_label = {i_label};
               else 
                    plot(app.UIAxes_n,epsilonLTruePlLog,sigmaLTruePlLog,'LineWidth',1.5);
                    plot_label{1,end+1} = i_label;
               end
           end
           legend(app.UIAxes_n,plot_label,'Orientation','horizontal','Location','southeast');
           app.UIAxes_n.XLabel.String = 'logarithmic \epsilon_L^{eng} [%]';
           app.UIAxes_n.YLabel.String = 'logarithmic \sigma_L^{eng} [N/mm^2]';
           app.UIAxes_n.XLabel.FontSize = 12;
           app.UIAxes_n.YLabel.FontSize = 12;
           app.UIAxes_n.LineWidth = 1;
           hold(app.UIAxes_n,'off');
       end
        
        function plot_basic_TT(app)
            % select the choosen direction
            dd_value = app.DropDown_basic.Value;
            degree = strsplit(dd_value,'-');
            degree = degree{1,1};
            i_degree = find([app.TT_Database{:,1}] == str2double(degree));
            databaseTT = app.TT_Database{i_degree,2};
            numb = app.TT_filepath{i_degree,2};
            rows = checkLabels(databaseTT, 'lengthTensileTestOriginal', 'forceTensileTestOriginal');
            for filenumb = 1:(size(numb,2))
               x_data = databaseTT{rows(1),filenumb+1};
               y_data = databaseTT{rows(2),filenumb+1};
               i_label = ['file: ' num2str(filenumb)];
               if filenumb == 1 
                    plot(app.UIAxes,x_data,y_data,'LineWidth',1.5);
                    hold(app.UIAxes,'on');
                    plot_label = {i_label};
               else 
                    plot(app.UIAxes,x_data,y_data,'LineWidth',1.5);
                    plot_label{1,end+1} = i_label;
               end
           end
           legend(app.UIAxes,plot_label,'Orientation','horizontal','Location','southeast');
           direction = app.TT_Database{i_degree,1};
           app.UIAxes.Title.String = ['comparison basic tensile test data in ' num2str(direction) '°-direction'];
           app.UIAxes.XLabel.String = 'displacement [mm]';
           app.UIAxes.YLabel.String = 'force [N]';
           app.UIAxes.XLabel.FontSize = 12;
           app.UIAxes.YLabel.FontSize = 12;
           app.UIAxes.LineWidth = 1;
           hold(app.UIAxes,'off');
           
        end
        
        function plot_basic_BT(app)
            numb = app.BT_filepath{1,2};
            rows = checkLabels(app.BT_Database, 'epsilonTruePlBulgeOriginal', 'sigmaTruePlBulgeOriginal');
            for filenumb = 1:(size(numb,2))
               x_data = app.BT_Database{rows(1),filenumb+1};
               y_data = app.BT_Database{rows(2),filenumb+1};
               i_label = ['file: ' num2str(filenumb)];
               if filenumb == 1 
                    plot(app.UIAxes,x_data,y_data,'LineWidth',1.5);
                    hold(app.UIAxes,'on');
                    plot_label = {i_label};
               else 
                    plot(app.UIAxes,x_data,y_data,'LineWidth',1.5);
                    plot_label{1,end+1} = i_label;
               end
           end
           legend(app.UIAxes,plot_label,'Orientation','horizontal','Location','southeast');
           app.UIAxes.Title.String = 'comparison basic bulge test data';
           app.UIAxes.XLabel.String = 'strain [mm]';
           app.UIAxes.YLabel.String = 'stress [N/mm]';
           app.UIAxes.XLabel.FontSize = 12;
           app.UIAxes.YLabel.FontSize = 12;
           app.UIAxes.LineWidth = 1;
           hold(app.UIAxes,'off');
        end
         
        function plot_approaches(app)
            hold(app.UIAxes4,'off');
            % find direction
            % select the choosen direction
            dd_value = app.DirectionDropDown.Value;
            degree = strsplit(dd_value,'-');
            degree = degree{1,1};
            i_degree = find([app.TT_Database{:,1}] == str2double(degree));
            databaseTT = app.TT_Database{i_degree,2}; 
            direction = app.TT_Database{i_degree,1};
            % find rows
            if app.exportextrapolationapproachButton.Value == 1
                rows = checkLabels(databaseTT, 'epsilonYieldCurve', 'sigmaYieldCurve','fitLudwik', 'fitGhosh', 'fitHockettSherby', ...
                                    'fitSwift', 'fitVoce');
            elseif app.bulgeextrapolaitionapproachButton.Value == 1
                rows = checkLabels(databaseTT, 'epsilonYieldCurveBulge', 'sigmaYieldCurveBulge', 'fitLudwik', 'fitGhosh', 'fitHockettSherby', ...
                                    'fitSwift', 'fitVoce');
            end
            sigmaYieldCurve = databaseTT{rows(2),2};
            epsilonYieldCurve = databaseTT{rows(1),2};
            yieldBegin = sigmaYieldCurve(1);
            epsilonXTrueRp = epsilonYieldCurve(2);
            sigmaXTrueRp = sigmaYieldCurve(2);
            xStep = 1e-6;
            x = epsilonXTrueRp:xStep:2;
                                
            % plot
            label = {'data points'};
            plot(app.UIAxes4,databaseTT{rows(1),2},databaseTT{rows(2),2},'LineWidth',1.2, 'Color', [0, 0.4470, 0.7410]);
            hold(app.UIAxes4,'on');

            if app.LudwikCheckBox.Value == 1
                fitLudwik = databaseTT{rows(3),2};
                plot(app.UIAxes4,[0 epsilonXTrueRp x],[yieldBegin sigmaXTrueRp fitLudwik(x-epsilonXTrueRp)'],'LineWidth',1.2, 'Color', [0.8500, 0.3250, 0.0980]);
                label{1,end+1} = 'Ludwik';
            end
            if app.GoshCheckBox.Value == 1
                fitGosh = databaseTT{rows(4),2};
                plot(app.UIAxes4,[0 epsilonXTrueRp x],[yieldBegin sigmaXTrueRp fitGosh(x-epsilonXTrueRp)'],'LineWidth',1.2, 'Color', [0.9290, 0.6940, 0.1250]);
                label{1,end+1} = 'Gosh';
            end
            if app.HockettSherbyCheckBox.Value == 1
                fitHS = databaseTT{rows(5),2};
                plot(app.UIAxes4,[0 epsilonXTrueRp x],[yieldBegin sigmaXTrueRp fitHS(x-epsilonXTrueRp)'],'LineWidth',1.2, 'Color', [0.4940, 0.1840, 0.5560]);
                label{1,end+1} = 'Hockett Sherby';
            end
            if app.SwiftCheckBox.Value == 1
                fitSwift = databaseTT{rows(6),2};
                plot(app.UIAxes4,[0 epsilonXTrueRp x],[yieldBegin sigmaXTrueRp fitSwift(x-epsilonXTrueRp)'],'LineWidth',1.2, 'Color', [0.4660, 0.6740, 0.1880]);
                label{1,end+1} = 'Swift';
            end
            if app.VoceCheckBox.Value == 1
                fitVoce = databaseTT{rows(7),2};
                plot(app.UIAxes4,[0 epsilonXTrueRp x],[yieldBegin sigmaXTrueRp fitVoce(x-epsilonXTrueRp)'],'LineWidth',1.2, 'Color', [0.3010, 0.7450, 0.9330]);
                label{1,end+1} = 'Voce';
            end
            
            app.UIAxes4.Title.String = ['extrapolation approaches in ' num2str(direction) '° direction'];
            app.UIAxes4.XLim = [0 2];
%             yMax = -inf;
%             app.UIAxes4.YLim = [0 yMax];
            legend(app.UIAxes4,label,'Orientation','horizontal','Location','southeast');
            app.UIAxes4.XLabel.String = '\epsilon_L^{eng} [%]';
            app.UIAxes4.YLabel.String = '\sigma_L^{eng} [N/mm^2]';
            app.UIAxes4.XLabel.FontSize = 12;
            app.UIAxes4.YLabel.FontSize = 12;
            app.UIAxes4.LineWidth = 1;
            hold(app.UIAxes4,'off');
        end
        
        function plot_tesnile_test_data(app,databaseTT,bulge)
            % get data
            rows = checkLabels(databaseTT, 'epsilonLEng', ...          % 1
                            'sigmaLEng', ...            % 2
                            'EExp', ...                 % 3
                            'epsilonLTrue', ...         % 4
                            'sigmaLTrue', ...           % 5
                            'epsilonYieldCurve', ...    % 6
                            'sigmaYieldCurve', ...      % 7
                            'epsilonBTruePl', ...       % 8
                            'slope', ...                % 9
                            'epsilonLTruePl', ...       % 10
                            'epsilonLEngRp', ...        % 11
                            'r', ...                    % 12
                            'nValue', ...               % 13
                            'nValueC', ...              % 14
                            'fitLudwik', ...            % 15
                            'fitGhosh', ...              % 16
                            'fitHockettSherby', ...     % 17
                            'fitSwift', ...             % 18
                            'fitVoce', ...              % 19
                            'epsilonYieldCurveExport', ... % 20
                            'sigmaYieldCurveExport');   % 211
                        
            epsilonLEng = databaseTT{rows(1),2};
            sigmaLEng = databaseTT{rows(2),2};
            EExp = databaseTT{rows(3),2};
            epsilonLTrue = databaseTT{rows(4),2};
            sigmaLTrue = databaseTT{rows(5),2};
            epsilonYieldCurve = databaseTT{rows(6),2};
            sigmaYieldCurve = databaseTT{rows(7),2};
%             epsilonBTruePl = databaseTT{rows(8),2};
%             slope = databaseTT{rows(9),2};
%             epsilonLTruePl = databaseTT{rows(10),2};
            epsilonLEngRp = databaseTT{rows(11),2};
%             r = databaseTT{rows(12),2};
%             nValue = databaseTT{rows(13),2};
%             nValueC = databaseTT{rows(14),2};
            fitLudwik = databaseTT{rows(15),2};
            fitGhosh = databaseTT{rows(16),2};
            fitHockettSherby = databaseTT{rows(17),2};
            fitSwift = databaseTT{rows(18),2};
            fitVoce = databaseTT{rows(19),2};
            epsilonYieldCurveExport = databaseTT{rows(20),2};
            sigmaYieldCurveExport  = databaseTT{rows(21),2};
            yieldBegin = sigmaYieldCurveExport(1);
            epsilonXTrueRp = epsilonYieldCurveExport(2);
            sigmaXTrueRp = sigmaYieldCurveExport(2);
            
            if bulge == 1
                rowsBulge = checkLabels(databaseTT, 'epsilonTruePlBulge', ...
                                                 'sigmaTruePlBulge', ...
                                                 'epsilonYieldCurveBulge', ...
                                                 'sigmaYieldCurveBulge', ...
                                                 'indexBulgeDataBegin', ...
                                                 'fitEpsilonYieldCurveBulge', ...
                                                 'fitSigmaYieldCurveBulge', ...
                                                 'alpha');
                epsilonTruePlBulge = databaseTT{rowsBulge(1),2};
                sigmaTruePlBulge = databaseTT{rowsBulge(2),2};
                epsilonYieldCurveBulge = databaseTT{rowsBulge(3),2};
                sigmaYieldCurveBulge = databaseTT{rowsBulge(4),2};
                indexBulgeDataBegin = databaseTT{rowsBulge(5),2};
                fitEpsilonYieldCurveBulge = databaseTT{rowsBulge(6),2};
                fitSigmaYieldCurveBulge = databaseTT{rowsBulge(7),2};
                alpha = databaseTT{rowsBulge(8),2};
            end
            
            % what is to be plotted?
            whattoplot = app.selectdatatoplotDropDown.Value;
            
            hold(app.UIAxes2,'off'); 
            
            if strcmp(whattoplot,'tensile test data')
                x = epsilonLEngRp:0.001:0.01;
                y = EExp*(x-epsilonLEngRp);
                sigmaMax = max(sigmaLTrue);
                index = find(y>sigmaMax,1);
                plot(app.UIAxes2,epsilonLEng, sigmaLEng,'LineWidth',1)  % engineering stres-strain-curve
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,x(1:index), y(1:index),'LineWidth',1)  % linear equation with slope E-Modul through Rp0.2
                plot(app.UIAxes2,epsilonLTrue, sigmaLTrue,'LineWidth',1)  % true stres-strain-curve
                plot(app.UIAxes2,epsilonYieldCurve, sigmaYieldCurve,'LineWidth',1)  % yield curve
                legend(app.UIAxes2,'engineering stress-strain','E-Modulus equation','true stress-strain', 'yield curve', 'Location', 'south')
                app.UIAxes2.XLabel.String = '\epsilon_L [-]';
                app.UIAxes2.YLabel.String = '\sigma [N/mm^2]';
                app.UIAxes2.Title.String = 'tensile test data';
                
            elseif strcmp(whattoplot,'Yield Curve and Extrapolations on tensile data')
                x = 0.01:0.001:2;
                plot(app.UIAxes2,epsilonYieldCurveExport, sigmaYieldCurveExport,'black','LineWidth',1);
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,[0 epsilonXTrueRp x], [yieldBegin sigmaXTrueRp fitLudwik(x)'],'Color',[0.8500, 0.3250, 0.0980],'LineWidth',1);
                plot(app.UIAxes2,[0 epsilonXTrueRp x], [yieldBegin sigmaXTrueRp fitGhosh(x)'],'Color',[0.9290, 0.6940, 0.1250],'LineWidth',1);
                plot(app.UIAxes2,[0 epsilonXTrueRp x], [yieldBegin sigmaXTrueRp fitHockettSherby(x)'],'Color',[0.4940, 0.1840, 0.5560],'LineWidth',1);
                plot(app.UIAxes2,[0 epsilonXTrueRp x], [yieldBegin sigmaXTrueRp fitSwift(x)'], 'Color',[0.4660, 0.6740, 0.1880],'LineWidth',1);
                plot(app.UIAxes2,[0 epsilonXTrueRp x], [yieldBegin sigmaXTrueRp fitVoce(x)'], 'Color',[0.3010, 0.7450, 0.9330],'LineWidth',1);
                legend(app.UIAxes2,'data points','Ludwik', 'Ghosh', 'HockettSherby', 'Swift', 'Voce', 'Location', 'south');
                app.UIAxes2.XLabel.String = '\epsilon^{pl} [-]';
                app.UIAxes2.YLabel.String = '\sigma [N/mm^2]';
                app.UIAxes2.Title.String = 'Yield Curve and Extrapolations on tensile data';
                
            elseif strcmp(whattoplot,'Data points tensile vs. bulge')
                if bulge == 1
                    plot(app.UIAxes2,epsilonLTrue, sigmaLTrue,'LineWidth',1); % data points tensile true
                    hold(app.UIAxes2,'on');
                    plot(app.UIAxes2,epsilonTruePlBulge, sigmaTruePlBulge,'LineWidth',1); % data points bulge true
                    legend(app.UIAxes2,'data points tensile test','data points bulge test', 'Location', 'south');
                    app.UIAxes2.XLabel.String = '\epsilon^{pl} [-]';
                    app.UIAxes2.YLabel.String = '\sigma [N/mm^2]';
                    app.UIAxes2.Title.String = 'Data points tensile vs. bulge';
                else
                    msgbox('no bulge interpolation has been made for this direction', 'Error','error');
                    return
                end
                
            elseif strcmp(whattoplot,'Bulge test results, alpha')
                if bulge == 1
                    plot(app.UIAxes2,epsilonYieldCurveBulge, sigmaYieldCurveBulge,'black','LineWidth',1);
                    hold(app.UIAxes2,'on');
                    plot(app.UIAxes2,fitEpsilonYieldCurveBulge, fitSigmaYieldCurveBulge,'LineWidth',1);
                    plot(app.UIAxes2,epsilonYieldCurveBulge(indexBulgeDataBegin), sigmaYieldCurveBulge(indexBulgeDataBegin), '.k', 'MarkerSize', 10,'LineWidth',1);
                    legend(app.UIAxes2,'data points','fit Alpha', 'equibiaxial point', 'Location', 'south');
                    app.UIAxes2.XLabel.String = '\epsilon^{pl} [-]';
                    app.UIAxes2.YLabel.String = '\sigma [N/mm^2]';
                    app.UIAxes2.Title.String = ['Bulge test results, alpha: ' num2str(alpha)];
                else
                    msgbox('no bulge interpolation has been made for this direction', 'Error','error');
                    return
                end
            end
            hold(app.UIAxes2,'off');
        end

        function plot_crush_test(app,databaseCT)
            rows = checkLabels(databaseCT, 'epsilonLEng', ...          % 1
                                'sigmaLEng', ...            % 2
                                'EExp', ...                 % 3
                                'epsilonLTrue', ...         % 4
                                'sigmaLTrue', ...           % 5
                                'epsilonYieldCurve', ...    % 6
                                'sigmaYieldCurve', ...      % 7
                                'epsilonLTruePl', ...       % 8
                                'epsilonLEngRp', ...        % 9
                                'epsilonYieldCurveExport', ... % 10
                                'sigmaYieldCurveExport', ...   % 11
                                'EFit');                    % 12
            epsilonLEng = databaseCT{rows(1),2};
            sigmaLEng = databaseCT{rows(2),2};
%             EExp = databaseCT{rows(3),2};
            epsilonLTrue = databaseCT{rows(4),2};
            sigmaLTrue = databaseCT{rows(5),2};
            epsilonYieldCurve = databaseCT{rows(6),2};
            sigmaYieldCurve = databaseCT{rows(7),2};
%             epsilonLTruePl = databaseCT{rows(8),2};
%             epsilonLEngRp = databaseCT{rows(9),2};
%             epsilonYieldCurveExport = databaseCT{rows(10),2};
%             sigmaYieldCurveExport = databaseCT{rows(11),2};
            EFit = databaseCT{rows(12),2};
            hold(app.UIAxes2,'off');
            x = 0:0.001:0.02;
            y = EFit(x);
            sigmaMax = max(sigmaLTrue);
            indexBegin = find(y>0,1);
            indexEnd = find(y>sigmaMax,1);
            plot(app.UIAxes2,epsilonLEng, sigmaLEng,'LineWidth',1)  % engineering stres-strain-curve
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,x(indexBegin:indexEnd), y(indexBegin:indexEnd),'LineWidth',1) % linear equation with slope E-Modul
            plot(app.UIAxes2,epsilonLTrue, sigmaLTrue,'LineWidth',1) % true stress-strain-curve
            plot(app.UIAxes2,epsilonYieldCurve, sigmaYieldCurve,'LineWidth',1) % yield curve
            legend(app.UIAxes2,'engineering stress-strain','E-Modulus equation','true stress-strain', 'yield curve', 'Location', 'south')
            app.UIAxes2.XLabel.String = '\epsilon_L [-]';
            app.UIAxes2.YLabel.String = '\sigma [N/mm^2]';
            app.UIAxes2.Title.String = 'crush test data';
            hold(app.UIAxes2,'off');
        end
        
        function plot_EMod_Index(app)
            hold(app.UIAxes,'off');
            value = app.PerformedtestDropDown.Value;
            if strcmp(value,'tensile/bulge') %tensile test
                if isempty(app.eModulusIndexTT)
                    app.eModulusIndexTT{size(app.TT_Database,1),2} = [];        
                end
                for i_direction = 1:size(app.TT_Database,1)
                    data = app.TT_Database{i_direction,2};
                    rows = checkLabels(data, 'epsilonLEng', 'sigmaLEng');
                    
                    x_data = data{rows(1),2};
                    y_data = data{rows(2),2};
                
                    i_label = [num2str(app.TT_Database{i_direction,1}),'-degree'];
                    index = app.eModulusIndexTT{i_direction,2};
                    
                    if isempty(index)
                        index = 1:1:size(data{rows(1),2},2);
                    end
                    
                    if i_direction == 1 
                        plot(app.UIAxes,x_data(index),y_data(index),'LineWidth',1.5);
                        hold(app.UIAxes,'on');
                        plot_label = {i_label};
                    else 
                        plot(app.UIAxes,x_data(index),y_data(index),'LineWidth',1.5);
                        plot_label{1,end+1} = i_label;
                    end
                end
                legend(app.UIAxes,plot_label,'Orientation','horizontal','Location','southeast');
                app.UIAxes.Title.String = 'E-Modulus index tensile test';
            elseif strcmp(value,'crush') % crush test
                rows = checkLabels(app.CT_Database, 'epsilonLEng', 'sigmaLEng');
                x_data = app.CT_Database{rows(1),2};
                y_data = app.CT_Database{rows(2),2};
                if isempty(app.eModulusIndexCT)
                    app.eModulusIndexCT = 1:1:size(app.CT_Database{rows(1),2},2);
                end
                plot(app.UIAxes,x_data(app.eModulusIndexCT),y_data(app.eModulusIndexCT),'LineWidth',1.5);
                legend(app.UIAxes,'crush test','Orientation','horizontal','Location','southeast');
                app.UIAxes.Title.String = 'E-Modulus index crush test';
            end
            
            app.UIAxes.XLabel.String = '\epsilon [-]';
            app.UIAxes.YLabel.String = '\sigma [N/mm^2]';
            app.UIAxes.XLabel.FontSize = 12;
            app.UIAxes.YLabel.FontSize = 12;
            app.UIAxes.LineWidth = 1;
            hold(app.UIAxes,'off');
        end
        
        function stiffness_corr(app)
            rowsData = checkLabels(app.CT_Database, 'fnCrushTest', 'pathCrushTest');
            fn = app.CT_Database{rowsData(1),2};
                
            rows = checkLabels(app.CT_Database, 'lengthCrushTestOriginal', 'forceCrushTestOriginal', 'timeCrushTestOriginal', ...
            'lengthCrushTestEdit', 'forceCrushTestEdit', 'timeCrushTestEdit', 'lengthStiffness', 'forceStiffness');
            
            lengthStiffness = app.CT_Database{rows(7),2};
            forceStiffness = app.CT_Database{rows(8),2};
            h = size(fn);
            n = h(2);
            clear h;
            for kk = 2:n+1 % Run on all specimen (columns in databaseCT)
                % Check if maximum value in stiffness is higher than in
                % experiment
                lengthExperiment = app.CT_Database{rows(4),kk};
                forceExperiment = app.CT_Database{rows(5),kk};
                if forceExperiment(end)>forceStiffness(end)
                    msgbox('evalCrushTest.m: The maximum stress values of your stiffness data is lower than the maximum stress values of your experiments. Supply a higher maxFoce value to datahandlingStiffnessTest.m.');
                    return;
                end
                % Run the correction
                for jj = 1:length(lengthExperiment) % For every data point in forceCrushTest of current specimen
                    indexEval = find(forceStiffness>forceExperiment(jj),1);
                    if indexEval == 1
                        epsilonCorr = lengthStiffness(1);
                    else
                        m = (forceStiffness(indexEval)-forceStiffness(indexEval-1))/(lengthStiffness(indexEval)-lengthStiffness(indexEval-1));
                        n = forceStiffness(indexEval)-m*lengthStiffness(indexEval);
                        epsilonCorr = (forceExperiment(jj)-n)/m;
                    end
                    app.CT_Database{rows(4),kk}(jj) = lengthExperiment(jj) - epsilonCorr;
                    clear indexEval m n epsilonCorr;
                end
            clear lengthExperiment forceExperiment;
            end
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              % handle visibilities of objects
              app.DataselectioncrushtestPanel.Visible = 'off';
              app.load_data_label.Visible = 'on';
              app.select_region_yield.Visible = 'off';
              app.selection_status.Visible = 'off';
              app.epsilonRpEditField.Visible = 'on';
              app.RpValueLabel.Visible = 'on';
              app.UITable_crush.Visible = 'off';
              app.UITable_TT.Visible = 'on';
              app.select_stiffness.Visible = 'off';
              app.DatafilestiffnesstestLabel.Visible = 'off';
              app.Panel_tensile_bulge.Visible = 'on';
              app.Panel_crush.Visible = 'off';
              app.ButtonGroup.Visible = 'off';
%               app.DatafilebulgetestLabel.Visible = 'off';
%               app.Select_bulge.Visible = 'off';
              app.select_region_yield.Visible = 'off';
              app.selection_status.Visible = 'off';
              app.TextEModulus.Visible = 'off';
              app.TextArea2.Visible = 'off';
              app.Panel_TT.Visible = 'on';
              app.Panel_CT.Visible = 'off';
              app.TextEModulus_2.Visible = 'off';
              app.select_region_yield_crush.Visible = 'off';
              app.selection_status_crush.Visible = 'off';
              app.epsilonRpEditField_crush.Visible = 'on';
              app.RpValueLabel_crush.Visible = 'on';
              app.UITable_approach.Visible = 'off';
              app.SelectedapproachforbulgeextrapolationLabel.Visible = 'off';
              app.Text_extrapolation.Visible = 'off';
              app.approachDropDown.Visible = 'on';
              app.firstselectionDropDown.Visible = 'off';
              app.secondselectionDropDown.Visible = 'off';
              app.FirstapproachLabel.Visible = 'off';
              app.SecondapproachLabel.Visible = 'off';
              app.Text_save.Visible = 'off';
              
              app.comparebasicdataseriesButton.Enable = 'off';
              app.manipulate_basic.Enable = 'off';
              app.maipualte_averaged.Enable = 'off';
              app.maipualte_averaged.Enable = 'off';
              app.EModulusButton.Enable = 'off';
              app.extrapolationButton.Enable = 'off';
              
              % fill drop down menu for visualisation tab
              app.selectdatatoplotDropDown.Items = {'tensile test data', 'Yield Curve and Extrapolations on tensile data', 'Data points tensile vs. bulge', 'Bulge test results, alpha'};
              
              % initialyse the parameter for the Extrapolation Apporaches ("app.expParameter")
              app.UpdateParametersButtonPushed;
              
              app.UIFigure.WindowState = 'normal';
              app.UIFigure.Scrollable ='on';
        end

        % Value changed function: PerformedtestDropDown
        function PerformedtestDropDownValueChanged(app, event)
            % handles the visibility of the different menus for the tensile or crush test
            % unfortunatley it wasn't possible to "bundle" the variables => function
            value = app.PerformedtestDropDown.Value;
            if strcmp(value,'tensile/bulge')
                app.DataselectiontensilebulgetestPanel.Visible = 'on';
                app.UITable_TT.Visible = 'on';
                app.Panel_tensile_bulge.Visible = 'on';
                app.DataselectioncrushtestPanel.Visible = 'off';
                app.UITable_crush.Visible = 'off';
                app.Panel_crush.Visible = 'off';
                app.Panel_TT.Visible = 'on';
                app.Panel_CT.Visible = 'off';
                app.DirectionDropDown_3.Visible = 'on';
                app.specimen_type.Visible = 'on';
                app.tensilespecimentypeLabel.Visible = 'on';
                
                app.rnvalueboundarydeterminationTab.Parent = app.TabGroup;
                app.extrapolationapproachTab.Parent = app.TabGroup;
                
                % fill drop down menu for visualisation tab
                app.selectdatatoplotDropDown.Items = {'tensile test data', 'Yield Curve and Extrapolations on tensile data', 'Data points tensile vs. bulge', 'Bulge test results, alpha'};
            elseif strcmp(value,'crush')
                app.Panel_TT.Visible = 'off';
                app.DataselectiontensilebulgetestPanel.Visible = 'off';
                app.UITable_TT.Visible = 'off';
                app.Panel_tensile_bulge.Visible = 'off';
                app.DataselectioncrushtestPanel.Visible = 'on';
                app.UITable_crush.Visible = 'on';
                app.Panel_crush.Visible = 'on';
                app.Panel_CT.Visible = 'on';
                app.DirectionDropDown_3.Visible = 'off';
                app.specimen_type.Visible = 'off';
                app.tensilespecimentypeLabel.Visible = 'off';
                
                app.rnvalueboundarydeterminationTab.Parent = [];
                app.extrapolationapproachTab.Parent = [];
                
                % fill drop down menu for visualisation tab
                app.selectdatatoplotDropDown.Items = {'crush test'};
            end
        end

        % Button pushed function: Select_TT
        function Select_TTButtonPushed(app, event)
            % this function activates when the user wants to select data for the tensile test
            % Required: none
            %===============================================================
            % check if the variables already exist (if a filepath already has been loaded)
            % if not => create the variable to store the filepaths in
            if isempty(app.TT_filepath)
                app.TT_filepath{1,4} = []; 
                insert_row = 1;          
            else
                insert_row = size(app.TT_filepath,1) + 1;
            end
            % which degree (test direction)
            direction = app.degree_EditField.Value;
            % check if direction already exists
            isfound = cellfun(@(x)isequal(x,direction),app.TT_filepath(:,1));
            if any(isfound)
                msgbox('You already selected files for this direction','warning','warn');
                return
            end
            % call the selection function
            [filepath,selection_made] = selectdata_tensiletest(direction); 
            %Check if user input has been made
            if selection_made ~= 0
                % Save data in TT_Filepath
                app.TT_filepath(insert_row,1:3) = filepath;
                app.TT_filepath{insert_row,4} = false;
                % clear all empty contents of app.TT_filepath
                app.TT_filepath(cellfun(@isempty,app.TT_filepath(:,1)),:) = [];
                % add data to table with the diferent file names
                % first add the data for the tensile test to the table
                app.tabledata = cell(1,3);
                insertrow = 1;
                for rowcount = 1:size(app.TT_filepath,1)
                    filename = app.TT_filepath{rowcount,2};
                    for rowcell = 1:size(filename,2)
                        app.tabledata(insertrow,1) = app.TT_filepath(rowcount,1);
                        app.tabledata(insertrow,2) = app.TT_filepath(rowcount,4);
                        app.tabledata(insertrow,3) = filename(1,rowcell);
                        insertrow = insertrow+1;
                    end
                end
                % now add the data fpr the bulge test to the table
                if ~isempty(app.BT_filepath)
                    if ~isempty(app.BT_filepath{1,1})
                        tablesize = size(app.tabledata,1);
                        filename = app.BT_filepath{1,2};
                        for rowcell = 1:size(filename,2)
                            app.tabledata{tablesize + rowcell,1} = 'bulge';
                            app.tabledata{tablesize + rowcell,2} = '-';
                            app.tabledata(tablesize + rowcell,3) = filename(1,rowcell);
                        end
                    end
                end 
                % asigne the data to the table
                app.UITable_TT.Data = app.tabledata;     
            end
            % just informational things for the user             
            %set light to red
            app.load_status.Color = 'red';
            app.calc_status.Color = 'red';
            app.calc_yield_status.Color = 'red';
            app.calc_n_value.Color = 'red';
            app.bulge_extrapolation.Color = 'red';
            % handle visibilities
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: Deletelast_TT
        function Deletelast_TTButtonPushed(app, event)
            % this function deletes the last entry of the path table
            % Required: none
            %============================================
            tablesize = size(app.tabledata,1);
            % check if there is any data in the table
            if isempty(app.tabledata)
                return
            else 
                if isempty(app.tabledata{1,1})
                    return
                end
            end
            % Remove the last entries from TT_filepath or B_filepath
            if strcmp(app.tabledata{tablesize,1},'bulge') == 1 % it's the bulge test
                last_row = 1; % only one bulge entry
                rowcell = size(app.BT_filepath{last_row,2},2);
                app.BT_filepath = cell(1,2); % entirely redefine app.B_filepath
            else % tensile test data
                last_row = size(app.TT_filepath,1);
                rowcell = size(app.TT_filepath{last_row,2},2);
                app.TT_filepath = app.TT_filepath(1:last_row-1,:);
            end
            % Remove the last entries from the table
            app.tabledata = app.tabledata(1:(tablesize-rowcell),:);
            app.UITable_TT.Data = app.tabledata;
            %set light to red
            app.load_status.Color = 'red';
            app.calc_status.Color = 'red';
            app.calc_yield_status.Color = 'red';
            app.calc_n_value.Color = 'red';
            app.export_status.Color = 'red';
            app.bulge_extrapolation.Color = 'red';
            % handle visibility
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: Select_bulge
        function Select_bulgeButtonPushed(app, event)
            % this function lets the user select the data for the bulge test.
            % if bulge data is selected, when some bulge data already got selected, the function overwrites the old data
            % Required: none
            %============================================
            % call the function
            [filepath,selection_made] = selectdata_bulgetest;
            %Check if user input has been made
            if selection_made ~= 0
                % create B_filepath
                app.BT_filepath{1,2} = [];
                % Save data in B_Filepath
                app.BT_filepath(1,:) = filepath;
                % delete empty entries
                app.BT_filepath(cellfun(@isempty,app.BT_filepath(:,1)),:) = [];
                % add data to filename table
                tablesize = size(app.tabledata,1);
%                 app.tabledata(strcmp(app.tabledata(:,1),'bulge'),:) = [];
                filename = app.BT_filepath{1,2};
                for rowcell = 1:size(filename,2)
                    app.tabledata{tablesize + rowcell,1} = 'bulge';
                    app.tabledata{tablesize + rowcell,2} = '-';
                    app.tabledata(tablesize + rowcell,3) = filename(1,rowcell);
                end
                % asign data to table
                app.UITable_TT.Data = app.tabledata;     
            end
            %set light to red
            app.load_status.Color = 'red';
            app.calc_status.Color = 'red';
            app.calc_yield_status.Color = 'red';
            app.calc_n_value.Color = 'red';
            app.bulge_extrapolation.Color = 'red';
            % visibility
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            % this function loads in weather the tensile or bulge data or the crush data
            % the data is stored in app.TT_Databse, app.BT_Datanase or app.CT_Database
            % required: loaded in paths of the data
            %========================================================================
            % set light to red
            app.load_status.Color = 'red';
            app.calc_status.Color = 'red';
            app.calc_yield_status.Color = 'red';
            % hide the Button group for tensile and bulge
            app.ButtonGroup.Visible = 'off';
            % For the thensile/bulge test
            if strcmp(app.PerformedtestDropDown.Value,'tensile/bulge')
                % check if input has been made
                if ~isempty(app.TT_filepath)
                    if isempty(app.TT_filepath{1,1})
                        msgbox('Please select the tensile test data first', 'Error','warn');
                        return
                    end
                else
                    msgbox('Please select the tensile test data first', 'Error','warn');
                    return
                end
                % check that if the bulge interpolation has been selected, that the bulge data is selected.
                if any(cellfun(@(x)isequal(x,1),app.TT_filepath(:,4))) % if for one direction the bulge extrapolation has been selected
                    if isempty(app.BT_filepath)
                        msgbox('Please select the bulge data first', 'Error','warn');
                        return
                    end
                end
                % handle the labels of the data. The function takes the labels out of the fields and sores it in the variables labesXX
                % tensile test
                displacementLabel = app.displacementLabelEditField.Value;
                forceLabel = app.forceLabelEditField.Value;
                widthLabel = app.widthLabelEditField.Value;
                timeLabel = app.timeLabelEditField.Value;
                widthInitialLabel = app.widthInitialLabelEditField.Value;
                lengthInitialLabel = app.lengthInitialLabelEditField.Value;
                thicknessInitialLabel = app.thicknessInitialLabelEditField.Value;
                % fill labels cell
                labelsTT = cell(7,1);
                labelsTT{1,1} = displacementLabel;
                labelsTT{2,1} = forceLabel;
                labelsTT{3,1} = widthLabel;
                labelsTT{4,1} = timeLabel;
                labelsTT{5,1} = widthInitialLabel;
                labelsTT{6,1} = lengthInitialLabel;
                labelsTT{7,1} = thicknessInitialLabel;
                % bulge test
                labelsBT = cell(3,1);
                labelsBT{1,1} = app.trueStressLabelEditField.Value;
                labelsBT{2,1} = app.trueStrainLabelEditField.Value;
                labelsBT{3,1} = app.timeLabelEditField_bulge.Value;
                % Waitbar (javacomponent)
                controller = 1;
                Message = 'loading tensile test data...';
                f = javawaitbar(app,controller,Message);
                
                try % try for tensile test
                
                %% Create Database for the tensile test data
                % ==========================
                % Create gloabal variable TT_Database (not necessary)
                if isempty(app.TT_Database)
                    app.TT_Database{1,2} = [];
%                     insert_row = 1;          
%                 else
%                     insert_row = size(app.TT_Database,1) + 1;
                end
                % for loop to create databses for each direction
                for insert_row = 1:size(app.TT_filepath,1)
                    % create the database for each direction
                    databaseTT = database;
                    rowsType = checkLabels(databaseTT, 'tensileTest');
                    databaseTT{rowsType(1),2} = true;
                    rowsData = checkLabels(databaseTT, 'fnTensileTest', 'pathTensileTest');
                    databaseTT{rowsData(1),2} = app.TT_filepath{insert_row,2};
                    databaseTT{rowsData(2),2} = app.TT_filepath{insert_row,3};
                    % get the specimen type from the field and save it in databaseTT
                    specimenType = app.specimen_type.Value;
                    rowsSpecimenType = checkLabels(databaseTT, 'specimenTypeTensileTest');
                    databaseTT{rowsSpecimenType(1),2} = specimenType;
                    % asign each databaseTT to main app.TT_Database
                    app.TT_Database(insert_row,1) = app.TT_filepath(insert_row,1); % assign direction to main tensile test data cell.
                    app.TT_Database{insert_row,2} = databaseTT; % assign TT_database.
                end                               
                
            %                 %% get the delimiter
            %                 % ========================
            %                 if strcmp(app.DropDown_delimiter.Value,'Semicolon (";")')
            %                     delimiter = ';';
            %                 elseif strcmp(app.DropDown_delimiter.Value,'comma (",")')
            %                     delimiter = ',';
            %                 elseif strcmp(app.DropDown_delimiter.Value,'colon (":")')
            %                     delimiter = ':';
            %                 elseif strcmp(app.DropDown_delimiter.Value,'tab ()')
            %                     delimiter = '    ';
            %                 end
                
                %% Datahandling Tesile Test
                % ========================
                %for loop for every dircection in app.TT_Database to lead the data
                for i_direction = 1:size(app.TT_filepath,1)
                    path = app.TT_filepath{i_direction,3};
                    fn = app.TT_filepath{i_direction,2};
                    databaseTT = app.TT_Database{i_direction,2};
                    % call function to load the data: 'datahandlingTensileTest'
                    databaseTT = datahandlingTensileTest(fn, path, databaseTT, labelsTT);
                    if isempty(databaseTT)
                        app.TT_Database{i_direction,2} = [];
                    else
                        app.TT_Database{i_direction,2} = databaseTT; %Store the tensile test data in app.TT_Database
                    end
                    app.TT_Database{i_direction,2} = databaseTT;
                end
                %% average Experimental Data
                % ========================
                % waitbar
                f.Name = 'averaging tensile test data...';
                figure(f);
                % for loop for every direction in app.TT_Database to average the data
                for i_direction = 1:size(app.TT_Database,1)
                    databaseTT = app.TT_Database{i_direction,2};
                    % check if base vector has been defined...if not: set to empty cell
                    if isempty(app.basevector)
                        baseVectorMin = [];
                        baseVectorStepSize = [];
                        baseVectorMax = [];  
                    else
                        baseVectorMin = app.basevector{1,1};
                        baseVectorStepSize = app.basevector{1,2};
                        baseVectorMax = app.basevector{1,3};
                    end
                    % get specimen type
                    rowsSpecimenType = checkLabels(databaseTT, 'specimenTypeTensileTest');
                    specimenType = databaseTT{rowsSpecimenType(1),2};
                    % call the averaging functions (one for flat and round specimen type)
                    if strcmp(specimenType, 'flat')
                        databaseTT = averageExperimentalData(databaseTT, {'lengthTensileTestEdit', 'forceTensileTestEdit', 'widthTensileTestEdit', 'timeTensileTestEdit', ...
                                                 'widthInitialTensileTestEdit', 'lengthInitialTensileTestEdit', 'thicknessInitialTensileTestEdit'}, ...
                                                 'labelsOut', {'lengthTensileTest', 'forceTensileTest', 'widthTensileTest', 'timeTensileTest', ...
                                                 'widthInitialTensileTest', 'lengthInitialTensileTest', 'thicknessInitialTensileTest'}, ...
                                      'baseVectorMin', baseVectorMin, 'baseVectorStepSize', baseVectorStepSize, 'baseVectorMax', baseVectorMax);
                    elseif strcmp(specimenType, 'round')
                        databaseTT = averageExperimentalData(databaseTT, {'lengthTensileTestEdit', 'forceTensileTestEdit', 'widthTensileTestEdit', 'timeTensileTestEdit', ...
                                                 'widthInitialTensileTestEdit', 'lengthInitialTensileTestEdit'}, ...
                                                 'labelsOut', {'lengthTensileTest', 'forceTensileTest', 'widthTensileTest', 'timeTensileTest', ...
                                                 'widthInitialTensileTest', 'lengthInitialTensileTest'}, ...
                                      'baseVectorMin', baseVectorMin, 'baseVectorStepSize', baseVectorStepSize, 'baseVectorMax', baseVectorMax);
                    end          
                    % Calculations Engineering
                    databaseTT = calculationsTensileTest(databaseTT);
                    app.TT_Database{i_direction,2} = databaseTT;
                end
                % find rows of the basevector in order to display the used base vector.
                rowsBaseVector = checkLabels(database, 'baseVectorMin', 'baseVectorStepSize', 'baseVectorMax');
                app.bv_begin.Value =  num2str(databaseTT{rowsBaseVector(1),2});
                app.bv_stepsize.Value =  num2str(databaseTT{rowsBaseVector(2),2});
                app.bv_end.Value =  num2str(databaseTT{rowsBaseVector(3),2});
                % plot tensile data
                app.load_data_label.Visible = 'off';
                plot_label = app.plot_TT;
                % fill drop down menu
                app.DropDown_averaged.Items = plot_label;
                app.DropDown_basic.Items = plot_label;
                app.DirectionDropDown_3.Items = plot_label;
                %% bulge test
                % ======================================================
                if any(cellfun(@(x)isequal(x,1),app.TT_filepath(:,4))) % if for one direction the bulge extrapolation has been selected
                    % rename the wait bar
                    f.Name = 'loading bulge test data...';
                    figure(f);
                    % create Database for bulge test
                    app.BT_Database = database;
                    %get rows of path and save it there
                    path = app.BT_filepath{1,1};
                    fn = app.BT_filepath{1,2};
                    rowsData = checkLabels(app.BT_Database, 'fnBulgeTest', 'pathBulgeTest');
                    app.BT_Database{rowsData(1),2} = fn;
                    app.BT_Database{rowsData(2),2} = path;
                    % load bulge data
                    app.BT_Database = datahandlingBulgeTest(fn, path, app.BT_Database,labelsBT);
                    % average bulge data
                    app.BT_Database = averageExperimentalData(app.BT_Database, {'epsilonTruePlBulgeEdit', 'sigmaTruePlBulgeEdit', 'timeBulgeEdit'}, ...
                                                                                'labelsOut', {'epsilonTruePlBulge', 'sigmaTruePlBulge', 'timeBulge'});                                            
                    % plot bulge data
                    app.ButtonGroup.Visible = 'on';
                    % fill drop down menu
                    app.DropDown_averaged.Items = [plot_label,{'bulge test'}];
                    app.DropDown_basic.Items = [plot_label,{'bulge test'}];
                end          
                % add extra field for all direction at the drop down menu to calculate the e modulus
                app.DropDown_emodulus.Items = [{'all directions'}, plot_label];
                % set light to green
                app.load_status.Color = 'green';
                app.calc_status.Color = 'red';
                app.calc_yield_status.Color = 'red';
                app.calc_n_value.Color = 'red';
                app.export_status.Color = 'red';
                app.bulge_extrapolation.Color = 'red';
                % enable buttons
                app.calculateEModulusRmvalueButton.Enable = 'on';
                app.select_region_yield.Enable = 'on';
                app.comparebasicdataseriesButton.Enable = 'on';
                app.manipulate_basic.Enable = 'on';
                app.maipualte_averaged.Enable = 'on';
                app.maipualte_averaged.Enable = 'on';
                app.EModulusButton.Enable = 'on';
                
                catch
                  close all
                  msgbox('An error occured', 'error', 'error');
                  return
                end
                
            %% chrush test
            % =============================================================
            elseif strcmp(app.PerformedtestDropDown.Value,'crush') 
                % check if crush test data has been selected
                if isempty(app.CT_Database)
                    msgbox('Please select the crush test data first', 'Warn','warn');
                    return
                end   
                % save the labes for the crush test
                labelsCT{1,1} = app.displacementLabelEditField_crush.Value;
                labelsCT{2,1} = app.forceLabelEditField_crush.Value;
                labelsCT{3,1} = app.diameterInitialLabelEditField.Value;
                labelsCT{4,1} = app.timeLabelEditField_crush.Value;
                labelsCT{5,1} = app.lengthInitialLabelEditField_crush.Value;
                % Waitbar (javacomponent)
                controller = 1;
                Message = 'loading crush test data...';
                f = javawaitbar(app,controller,Message);
                %% if stiffness correlation needed
                % ===================================
                if app.Stiffnes_needed.Value == 1
                    try
                    if isempty(app.Stiff_filepath)
                        msgbox('Please select the data for the stiffness correlation first', 'Warn','warn');
                        return
                    end                   
                    % get path
                    [fnStiff,pathStiff] = app.Stiff_filepath{1,:};
                    % call the function to load the stiffnes data: 'datahandling stiffness test'
                    app.CT_Database = datahandlingStiffnessTest(fnStiff, pathStiff, app.CT_Database, labelsCT);
                    % average Stiffness data
                    app.CT_Database = averageExperimentalData(app.CT_Database, {'lengthStiffness', 'forceStiffness'});
                    catch
                        close all
                        msgbox('An error occured during the stiffnes correlation', 'error', 'error');
                        return
                    end
                end             
                %% load and correct crush data
                % ======================================
                try
                rowsData = checkLabels(app.CT_Database, 'fnCrushTest', 'pathCrushTest');
                fn = app.CT_Database{rowsData(1),2};
                path = app.CT_Database{rowsData(2),2};
                % call the funtion to load the crush data: 'datahandlingCrushTest'
                app.CT_Database = datahandlingCrushTest(fn, path, app.CT_Database,labelsCT);
                % get needed row for this part
                rows = checkLabels(app.CT_Database, 'lengthCrushTestOriginal', 'forceCrushTestOriginal', 'timeCrushTestOriginal', ...
                        'lengthCrushTestEdit', 'forceCrushTestEdit', 'timeCrushTestEdit', 'lengthStiffness', 'forceStiffness');
                % If Stiffness correction was selected, correct data
                if ~isempty(app.CT_Database{rows(7),2}) && ~isempty(app.CT_Database{rows(8),2})
                    app.stiffness_corr;
                end
                %% average crush data
                % =============================
                % waitbar
                f.Name = 'averaging data...';
                figure(f);
                % check if base vector has been defined
                if isempty(app.basevector)
                    baseVectorMin = [];
                    baseVectorStepSize = [];
                    baseVectorMax = [];  
                else
                    baseVectorMin = app.basevector{1,1};
                    baseVectorStepSize = app.basevector{1,2};
                    baseVectorMax = app.basevector{1,3};
                end
                % call function to average data
                app.CT_Database = averageExperimentalData(app.CT_Database, {'lengthCrushTestEdit', 'forceCrushTestEdit', 'timeCrushTestEdit', ...
                    'diameterInitialCrushTestEdit', 'lengthInitialCrushTestEdit'}, ...
                    'labelsOut', {'lengthCrushTest', 'forceCrushTest', 'timeCrushTest', ...
                    'diameterInitialCrushTest', 'lengthInitialCrushTest'}, ...
                    'baseVectorMin', baseVectorMin, 'baseVectorStepSize', baseVectorStepSize, 'baseVectorMax', baseVectorMax);  
                % find rows of the basevector in order to display the used base vector.
                rowsBaseVector = checkLabels(app.CT_Database, 'baseVectorMin', 'baseVectorStepSize', 'baseVectorMax');
                app.bv_begin.Value =  num2str(app.CT_Database{rowsBaseVector(1),2});
                app.bv_stepsize.Value =  num2str(app.CT_Database{rowsBaseVector(2),2});
                app.bv_end.Value =  num2str(app.CT_Database{rowsBaseVector(3),2});
                % Engineering Calculations
                app.CT_Database = calculationsCrushTest(app.CT_Database);          
                %% plot average Experimental Data
                % ================================
                app.load_data_label.Visible = 'off';
                app.plot_CT;
                % set light to green
                app.load_status.Color = 'green';
                app.calc_EMod_crush.Color = 'red';
                app.calc_yield_crush.Color = 'red';
                % enable buttons
                app.calculation_crush.Enable = 'on';
                catch
                    close all
                    msgbox('An error occured', 'error', 'error');
                    return
                end
            end % End if comparison for tensile/bulge or crush test
            % close the waitbar
            close(f);
            % handle visibility
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: SavedatabaseButton
        function SaveDataButtonPushed(app, event)
            % function to save and export the data
            % required: none (empty cells get saved)
            %=====================================
            app.exportdata = cell(12,2);
            app.exportdata{1,1} = 'TT_Database';
            app.exportdata{1,2} = app.TT_Database;
            app.exportdata{2,1} = 'TT_filepath';
            app.exportdata{2,2} = app.TT_filepath;
            app.exportdata{3,1} = 'BT_Database';
            app.exportdata{3,2} = app.BT_Database;
            app.exportdata{4,1} = 'BT_filepath';
            app.exportdata{4,2} = app.BT_filepath;
            app.exportdata{5,1} = 'CT_Database';
            app.exportdata{5,2} = app.CT_Database;
            app.exportdata{6,1} = 'CT_filepath';
            app.exportdata{6,2} = app.CT_filepath;
            app.exportdata{7,1} = 'Stiff_filepath';
            app.exportdata{7,2} = app.Stiff_filepath;
            app.exportdata{8,1} = 'eModulusIndexTT';
            app.exportdata{8,2} = app.eModulusIndexTT;
            app.exportdata{9,1} = 'eModulusIndexCT';
            app.exportdata{9,2} = app.eModulusIndexCT;
            app.exportdata{10,1} = 'indexPronouncedYieldStrength';
            app.exportdata{10,2} = app.indexPronouncedYieldStrength;
            app.exportdata{11,1} = 'averagedindex';
            app.exportdata{11,2} = app.averagedindex;
            app.exportdata{12,1} = 'baseindex';
            app.exportdata{12,2} = app.baseindex;
            Exportdata = app.exportdata;
            uisave('Exportdata','Exportdata.mat');
            % Visibility
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: basevectorButton
        function basevectorButtonPushed(app, event)
            % function to let the user select the base vector
            % Ask the user if information for the base vactor should be submitted
            answerInpE = inputdlg({sprintf('If you want to define the base vector used for the averaging of your measuring data, submit values here. Otherwise leave the fields blank. \n\nBase Vector Begin \n(in the unit of your displacement data, typically mm)'), ...
                               sprintf('Base Vector Step Size \n(in the unit of your displacement data, typically mm, typically 5e-4 is a good starting point)'),...
                               sprintf('Base Vector End \n(in the unit of your displacement data, typically mm)')}, ...
                               'Base Vector Definition', [1 35]);
            % define base vector as specified in the edid fields
            app.basevector = cell(1,3);
            % check if input has been made
            if isempty(answerInpE)
                return;
            else
                if isnan(str2double(answerInpE{1,1})) == 1
                    app.basevector{1,1} = [];
                else
                    app.basevector{1,1} = str2double(answerInpE{1,1}); % begin
                end
                if isnan(str2double(answerInpE{2,1})) == 1
                    app.basevector{1,2} = [];
                else
                    app.basevector{1,2} = str2double(answerInpE{2,1}); % step size
                end
                if isnan(str2double(answerInpE{3,1})) == 1
                    app.basevector{1,3} = [];
                else
                    app.basevector{1,3} = str2double(answerInpE{3,1}); % end
                end
            end
            % write the base vector in the edid fields (just for user information)
            app.bv_begin.Value = num2str(app.basevector{1,1});
            app.bv_stepsize.Value = num2str(app.basevector{1,2});
            app.bv_end.Value = num2str(app.basevector{1,3});
        end

        % Button pushed function: manipulatedataButton
        function manipulatedataButtonPushed(app, event)
            % just changes active tab
            app.TabGroup.SelectedTab = app.datamanipulationTab;
        end

        % Button pushed function: maipualte_averaged
        function maipualte_averagedButtonPushed(app, event)
            % function to manipulate the averages data for the tensile and or bulge test
            % Required: The data of the tensile test should be loaded in
            %=======================================================================
            % if not already created, create the variable to store the index of the averaged data
            % ?x2 array: first col = name (degree, 'bulge'), second col = indices
            if isempty(app.averagedindex)
                app.averagedindex = cell(size(app.TT_Database,1)+1,2);
                app.averagedindex(1:(end-1),1) = app.TT_Database(:,1);
                app.averagedindex{end,1} = 'bulge';
            end
            % wich data series has been selected?
            % Check for bulge test
            dd_value = app.DropDown_averaged.Value;
            if strcmp(dd_value,'bulge test')
                rows = checkLabels(app.BT_Database, 'epsilonTruePlBulge', 'sigmaTruePlBulge', 'timeBulge');
                index = selectInput(app.BT_Database{rows(1),2}, app.BT_Database{rows(2),2}); % Let the user select the data points
                % Shorten all time dependent data 
                app.BT_Database{rows(1),2} = app.BT_Database{rows(1),2}(index);
                app.BT_Database{rows(2),2} = app.BT_Database{rows(2),2}(index);
                app.BT_Database{rows(3),2} = app.BT_Database{rows(3),2}(index);
                app.plot_BT;
                % store the index in app.averagedindex
                app.averagedindex{strcmp(app.averagedindex(:,1),'bulge'),2} = index;
            % in this case else means the tensile test
            else
                % get the direction of the test data to be manipulated
                degree = strsplit(dd_value,'-');
                degree = degree{1,1};
                i_degree = find([app.TT_Database{:,1}] == str2double(degree));
                databaseTT = app.TT_Database{i_degree,2};
                direction = app.TT_Database{i_degree,1};
                % get the row numbers
                rows = checkLabels(databaseTT, 'lengthTensileTest', 'forceTensileTest', 'widthTensileTest', 'timeTensileTest');
                % call the selection function
                index = selectInput(databaseTT{rows(1),2}, databaseTT{rows(2),2}, 'xlabelIn', 'displacement [mm]', 'ylabelIn', 'force [N]', 'titleIn', ['Please choose data points of averaged data in ' num2str(direction) '°-direction!']); % Let the user select the data points
                % Shorten all time dependent data 
                databaseTT{rows(1),2} = databaseTT{rows(1),2}(index);
                databaseTT{rows(2),2} = databaseTT{rows(2),2}(index);
                databaseTT{rows(3),2} = databaseTT{rows(3),2}(index);
                databaseTT{rows(4),2} = databaseTT{rows(4),2}(index);
                % save the index
                app.averagedindex{i_degree,2} = index;
                % Calculations Engineering
                databaseTT = calculationsTensileTest(databaseTT);       
                app.TT_Database{i_degree,2} = databaseTT;
                % plot data
                app.plot_TT;
                % plot graph for r value
                plot_label = app.plot_r;
                % fill r value table
                rtabledata = cell(size(app.TT_Database,1),2);
                for i_direction = 1:size(app.TT_Database,1)
                    rows = checkLabels(app.TT_Database{i_direction,2}, 'epsilonLEng', 'sigmaLEng');
                    databaseTT = app.TT_Database{i_direction,2};
                    rtabledata{i_direction,1} = 2;
                    epsilonLEng = databaseTT{rows(1),2};
                    rtabledata{i_direction,2} = epsilonLEng(end)*100-1;
                end
                % asign data to table
                app.UITable_rvalue.Data = rtabledata;
                app.UITable_rvalue.RowName = plot_label;
                % set the status of the data in the table as correct (will be checked later)
                app.r_input_correct = 1;
                %lamp
                app.calc_status.Color = 'red';
                app.calc_yield_status.Color = 'red';
                app.calc_n_value.Color = 'red';
                app.export_status.Color = 'red';
                app.bulge_extrapolation.Color = 'red';
            end
        end

        % Button pushed function: manipulate_basic
        function manipulate_basicButtonPushed(app, event)
            % function to manipulate the basic data and not the averaged data
            % Required: the data should already be loaded
            %================================================
            % create the index to store the indices of the base data in
            % ?x2 array: first col = name (degree, 'bulge'), second col = indices
            if isempty(app.baseindex)
                app.baseindex = cell(size(app.TT_Database,1)+1,2);
                app.baseindex(1:(end-1),1) = app.TT_Database(:,1);
                app.baseindex{end,1} = 'bulge';
            end
            % get the value of the dropdown fiel which specifies the data to be manipulated
            dd_value = app.DropDown_basic.Value;
            if strcmp(dd_value,'bulge test')
                % get row numbers of needed entries
                rows = checkLabels(app.BT_Database, 'epsilonTruePlBulgeOriginal', 'sigmaTruePlBulgeOriginal', 'timeBulgeOriginal', ...
                                      'epsilonTruePlBulgeEdit', 'sigmaTruePlBulgeEdit', 'timeBulgeEdit');
                h = size(app.BT_Database);
                n = h(2);
                clear h;
                indexbulge = cell(n-1,1);
                % n is the number of bulge data files the user loaded in
                for jj = 2:n % First column that contains the labels
                    X = ['Experimental Set ' num2str(jj-1) '/' num2str(n-1)];
                    disp(X);
                    % call the function to shorten the data (choose the index)
                    index = selectInput(app.BT_Database{rows(1),jj}, app.BT_Database{rows(2),jj}); % Let the user select the data points
                    % Shorten all time dependent data 
                    app.BT_Database{rows(4),jj} = app.BT_Database{rows(1),jj}(index); % Shorten all time dependent data 
                    app.BT_Database{rows(5),jj} = app.BT_Database{rows(2),jj}(index);
                    app.BT_Database{rows(6),jj} = app.BT_Database{rows(3),jj}(index);
                    % save the indices
                    indexbulge{jj-1} = index;
                end
%                 ind = find(strcmp([app.baseindex(:,1)],'bulge'));
                app.baseindex{strcmp(app.baseindex(:,1),'bulge'),2} = indexbulge;
            % else for the dorections of the tensile test 
            else
                % select the choosen direction
                degree = strsplit(dd_value,'-');
                degree = degree{1,1};
                i_degree = find([app.TT_Database{:,1}] == str2double(degree));
                databaseTT = app.TT_Database{i_degree,2};
                % get row numbers
                rows = checkLabels(databaseTT, 'lengthTensileTestOriginal', 'forceTensileTestOriginal', 'widthTensileTestOriginal', 'timeTensileTestOriginal', ...
                                           'lengthTensileTestEdit', 'forceTensileTestEdit', 'widthTensileTestEdit', 'timeTensileTestEdit');
                % n = the number of files for the direction  + 1
                h = app.TT_filepath{i_degree,2};
                n = size(h,2)+1;
                clear h;
                indextensile = cell(n-1,1);
                for jj = 2:n % First column contains the labels
                    index = selectInput(databaseTT{rows(1),jj}, databaseTT{rows(2),jj}, 'xlabelIn', 'displacement [mm]', 'ylabelIn', 'force [N]', 'titleIn', ['Please choose data points of experimental Set ' num2str(jj-1) '/' num2str(n-1)]); % Let the user select the data points
                    % Shorten all time dependent data 
                    databaseTT{rows(5),jj} = databaseTT{rows(1),jj}(index);
                    databaseTT{rows(6),jj} = databaseTT{rows(2),jj}(index);
                    databaseTT{rows(7),jj} = databaseTT{rows(3),jj}(index);
                    databaseTT{rows(8),jj} = databaseTT{rows(4),jj}(index);
                    % save indices
                    indextensile{jj-1} = index;
                end
                app.TT_Database{i_degree,2} = databaseTT;
                % save index variable for all files of one direction on the overall variable
                app.baseindex{i_degree,2} = indextensile;
                % lamp
                app.calc_status.Color = 'red';
                app.calc_yield_status.Color = 'red';
                app.calc_n_value.Color = 'red';
                app.bulge_extrapolation.Color = 'red';
            end
        end

        % Button pushed function: reaveragedataButton
        function reaveragedataButtonPushed(app, event)
            % this function averages and replots the data. This is necessary, because after the basic data series
            % got manipulated the data still need to get averaged. The averaging doesn't take place imediatly in case
            % the user wants to manupulate another data series
            % Required: data is already loaded in
            %===================================================
            % Waitbar
            controller = 1;
            Message = 'averaging data...';
            f = javawaitbar(app,controller,Message);
            
%             try

            %% Tensile test
            % ==================================
            % for loop for every dircection in app.TT_Database
            for i_direction = 1:size(app.TT_Database,1)
                databaseTT = app.TT_Database{i_direction,2};
                % restore data (undo the  manipulation of theaveraged data => no double averaging)
                rows = checkLabels(databaseTT, 'lengthTensileTest', 'forceTensileTest', 'widthTensileTest', 'timeTensileTest',...
                    'lengthTensileTestOriginal', 'forceTensileTestOriginal', 'widthTensileTestOriginal', 'timeTensileTestOriginal');
                databaseTT(rows(1),2:end) = databaseTT(rows(5),2:end);
                databaseTT(rows(2),2:end) = databaseTT(rows(6),2:end);
                databaseTT(rows(3),2:end) = databaseTT(rows(7),2:end);
                databaseTT(rows(4),2:end) = databaseTT(rows(8),2:end);
                % check if base vector has been defined
                if isempty(app.basevector)
                    baseVectorMin = [];
                    baseVectorStepSize = [];
                    baseVectorMax = [];  
                else
                    baseVectorMin = app.basevector{1,1};
                    baseVectorStepSize = app.basevector{1,2};
                    baseVectorMax = app.basevector{1,3};
                end
                % get row numbers
                rowsSpecimenType = checkLabels(databaseTT, 'specimenTypeTensileTest');
                specimenType = databaseTT{rowsSpecimenType(1),2};
                % call the function to average the data
                if strcmp(specimenType, 'flat')
                    databaseTT = averageExperimentalData(databaseTT, {'lengthTensileTestEdit', 'forceTensileTestEdit', 'widthTensileTestEdit', 'timeTensileTestEdit', ...
                                             'widthInitialTensileTestEdit', 'lengthInitialTensileTestEdit', 'thicknessInitialTensileTestEdit'}, ...
                                             'labelsOut', {'lengthTensileTest', 'forceTensileTest', 'widthTensileTest', 'timeTensileTest', ...
                                             'widthInitialTensileTest', 'lengthInitialTensileTest', 'thicknessInitialTensileTest'}, ...
                                  'baseVectorMin', baseVectorMin, 'baseVectorStepSize', baseVectorStepSize, 'baseVectorMax', baseVectorMax);
                elseif strcmp(specimenType, 'round')
                    databaseTT = averageExperimentalData(databaseTT, {'lengthTensileTestEdit', 'forceTensileTestEdit', 'widthTensileTestEdit', 'timeTensileTestEdit', ...
                                             'widthInitialTensileTestEdit', 'lengthInitialTensileTestEdit'}, ...
                                             'labelsOut', {'lengthTensileTest', 'forceTensileTest', 'widthTensileTest', 'timeTensileTest', ...
                                             'widthInitialTensileTest', 'lengthInitialTensileTest'}, ...
                                  'baseVectorMin', baseVectorMin, 'baseVectorStepSize', baseVectorStepSize, 'baseVectorMax', baseVectorMax);
                end            
                % Calculations Engineering
                databaseTT = calculationsTensileTest(databaseTT);
                % save data in main data cell
                app.TT_Database{i_direction,2} = databaseTT;
            end % end for loop
            %% bulge test
            % has the bulge extrapolation been selected for any direction?
            if any(cellfun(@(x)isequal(x,1),app.TT_filepath(:,4)))
                % get row numbers
                rows = checkLabels(app.BT_Database, 'epsilonTruePlBulgeOriginal', 'sigmaTruePlBulgeOriginal', 'timeBulgeOriginal', ...
                                                  'epsilonTruePlBulge', 'sigmaTruePlBulge', 'timeBulge');
                % reset data (no double averaging)
                app.BT_Database(rows(4),2:end) = app.BT_Database(rows(1),2:end);
                app.BT_Database(rows(5),2:end) = app.BT_Database(rows(2),2:end);
                app.BT_Database(rows(6),2:end) = app.BT_Database(rows(3),2:end);
                % average the data if existing
                if ~isempty(app.BT_Database)
                    % average bulge data
                    app.BT_Database = averageExperimentalData(app.BT_Database, {'epsilonTruePlBulgeEdit', 'sigmaTruePlBulgeEdit', 'timeBulgeEdit'}, ...
                                                                                'labelsOut', {'epsilonTruePlBulge', 'sigmaTruePlBulge', 'timeBulge'});                                                    
                    % plot bulge data
                    app.plot_BT
                end        
            end
            % find rows of the basevector in order to display the used base vector.
            rowsBaseVector = checkLabels(database, 'baseVectorMin', 'baseVectorStepSize', 'baseVectorMax');
            app.bv_begin.Value =  num2str(databaseTT{rowsBaseVector(1),2});
            app.bv_stepsize.Value =  num2str(databaseTT{rowsBaseVector(2),2});
            app.bv_end.Value =  num2str(databaseTT{rowsBaseVector(3),2});  
            % plot data
            app.plot_TT;
            % lamp
            app.calc_status.Color = 'red';
            app.calc_yield_status.Color = 'red';
            app.calc_n_value.Color = 'red';
            app.bulge_extrapolation.Color = 'red';
            % waitbar
            close(f)
            
%             catch
%                 close all
%                 msgbox('An error occured', 'error', 'error');
%                 return
%             end
        end

        % Button pushed function: EModulusButton
        function EModulusButtonPushed(app, event)
            % function to let the user choose data that is used to calculate the E-Modulus to save computation time
            % required: data should already be loaded
            %======================================================================================
            % check if the variable eModulusIndex already exists. Otherwise create it
            if isempty(app.eModulusIndexTT)
                app.eModulusIndexTT{size(app.TT_Database,1),2} = [];        
            end
            % get direction from dropdown menu
            dd_value = app.DropDown_emodulus.Value;
            if strcmp(dd_value,'all directions')
                numb_directions = size(app.TT_Database,1);
            else                
                numb_directions = 1;    
            end
            % run through all the directions
            for i_direction = 1:numb_directions
                if numb_directions == 1
                    if size(app.TT_Database,1) == 1 %if there is only one direction
                        databaseTT = app.TT_Database{i_direction,2};
                        direction = app.TT_Database{i_direction,1};
                        i_degree = i_direction;
                    else %if there is more than one direction
                        % select the choosen direction
                        degree = strsplit(dd_value,'-');
                        degree = degree{1,1};
                        i_degree = find([app.TT_Database{:,1}] == str2double(degree));
                        databaseTT = app.TT_Database{i_degree,2}; 
                        direction = app.TT_Database{i_degree,1};
                    end
                else
                    databaseTT = app.TT_Database{i_direction,2};
                    direction = app.TT_Database{i_direction,1};
                    i_degree = i_direction;
                end
                % get rows needed and call the function to select the area to calculate the E Modulus
                rows = checkLabels(databaseTT, 'epsilonLEng', 'sigmaLEng');
                epsilonLEng = databaseTT{rows(1),2};
                sigmaLEng = databaseTT{rows(2),2};
                index = selectInput(epsilonLEng, sigmaLEng, 'xlabelIn', 'epsilon [-]', 'ylabelIn', 'sigma [N/mm^2]', 'titleIn', ['Please choose data points to evaluate E-Modulus in ' num2str(direction) '°-direction!']); % Let the user select the data points
                % save the indices (the e modulus calculation needs this variable)
                app.eModulusIndexTT{i_degree,1} = direction;
                app.eModulusIndexTT{i_degree,2} = index;
                % colors
                app.calc_status.Color = 'red';
                app.calc_yield_status.Color = 'red';
                app.calc_n_value.Color = 'red';
                app.export_status.Color = 'red';
                app.bulge_extrapolation.Color = 'red';
                % plot the E Modulus index
                value = app.displayButton.Value;
                if value == 1
                    app.plot_EMod_Index;
                end
            end %end for loop
        end

        % Button pushed function: calculateEModulusRmvalueButton
        function calculateEModulusRmvalueButtonPushed(app, event)
            % function to calculate the Rm-Value, the E-Modulus and the yield curve
            % required: the data shoud be loaded in and already manipulated.
            % especially the area for the E Modulus calculation should be specified regarding the computational time
            % ====================================================================
            app.calc_status.Color = 'red';
            % waitbar
            controller = 1;
            Message = 'calculating...';
            f = javawaitbar(app,controller,Message);
            
            try
            
            for i_direction = 1:size(app.TT_Database,1)
                % get the data of the current direction
                databaseTT = app.TT_Database{i_direction,2};
                % Calculations Engineering
                databaseTT = calculationsTensileTest(databaseTT);
                % Rm Calculation
                databaseTT = Rm(databaseTT);
                % E-Modulus calculation
                % ==============
                % check if an area for the E Modulus calculation has been specified
                if isempty(app.eModulusIndexTT)
                    app.eModulusIndex{size(app.TT_Database,2),2} = [];        
                end
                % Choose data that are used to calculate the E-Modulus to save computation time
                if isempty(app.eModulusIndexTT{i_direction,2}) % if no data for the E-Modulus has been selected by the user
                    rows = checkLabels(databaseTT, 'epsilonLEng');
                    index = 1:1:size(databaseTT{rows(1),2},2);
                else
                    index = app.eModulusIndexTT{i_direction,2}; % for E-Modulus
                end
                % get the needed variables for the e modulus calculation
                rows = checkLabels(databaseTT, 'epsilonLEng', 'sigmaLEng', 'epsilonLTrue', 'sigmaLTrue');
                epsilonLTrue = databaseTT{rows(3),2};
                sigmaLTrue = databaseTT{rows(4),2};
                % Temporarly change contents of databaseTT
                databaseTT{rows(3),2} = epsilonLTrue(index);
                databaseTT{rows(4),2} = sigmaLTrue(index);
                % call the function for the E-Modulus calculation
                databaseTT = EModulus(databaseTT);
                % Restore the original entries for engineering strain and stress
                databaseTT{rows(3),2} = epsilonLTrue;
                databaseTT{rows(4),2} = sigmaLTrue;
                % display the calculated E-Modulus and Rm value in the table
                rows = checkLabels(databaseTT, 'EExp','Rm');
                if i_direction == 1
                    app.E_Rm_table = cell(size(app.TT_Database,1),4);
                end
                    app.E_Rm_table{i_direction,1} = app.TT_Database{i_direction,1};  % direction
                    app.E_Rm_table{i_direction,2} = databaseTT{rows(1),2}; % E-Modulus
                    app.E_Rm_table{i_direction,3} = '[]';
                    app.E_Rm_table{i_direction,4} = databaseTT{rows(2),2}; % Rm value
                % save new database of the direction
                app.TT_Database{i_direction,2} = databaseTT;
            end % end for loop
            
            % display table data
            app.UITable_E_Rm.Data = app.E_Rm_table;
            app.UITable_E_Rm.ColumnFormat = {[] 'bank' 'bank' []};
            app.E_input_correct = 1; % the data in the table is correct
            % color
            app.calc_status.Color = 'green';
            app.calc_yield_status.Color = 'red';
            app.calc_n_value.Color = 'red';
            app.export_status.Color = 'red';
            app.bulge_extrapolation.Color = 'red';
            % plot graph for r value
            plot_label = app.plot_r;
            app.load_data_label_r.Visible = 'off';
            % fill r value table
            rtabledata = cell(size(app.TT_Database,1),2);
            rows = checkLabels(app.TT_Database{i_direction,2}, 'epsilonLEng', 'sigmaLEng');
            for i_direction = 1:size(app.TT_Database,1)
                databaseTT = app.TT_Database{i_direction,2};
                rtabledata{i_direction,1} = 2;
                epsilonLEng = databaseTT{rows(1),2};
                rtabledata{i_direction,2} = epsilonLEng(end)*100-1;
            end
            app.UITable_rvalue.Data = rtabledata;
            app.UITable_rvalue.RowName = plot_label;
            app.r_input_correct = 1;
            % close the waitbar
            close(f);
            
            catch
                close all
                msgbox('An error occured', 'error', 'error');
                return
            end
            
            % enable buttons
            app.select_rangefornvaluecalculationButton.Enable = 'on';
            app.calculateyieldcurvervaluenvalueButton.Enable = 'on';
        end

        % Value changed function: pronouncedyieldstrenghCheckBox
        function pronouncedyieldstrenghCheckBoxValueChanged(app, event)
            % this funtion just changes the gui if the user wants to specify the pronounced yield strengh
            value = app.pronouncedyieldstrenghCheckBox.Value;
            if value == 1
                app.select_region_yield.Visible = 'on';
                app.selection_status.Visible = 'on';
                app.epsilonRpEditField.Visible = 'off';
                app.RpValueLabel.Visible = 'off';
            else
                app.select_region_yield.Visible = 'off';
                app.selection_status.Visible = 'off';
                app.epsilonRpEditField.Visible = 'on';
                app.RpValueLabel.Visible = 'on';
            end
        end

        % Button pushed function: select_region_yield
        function select_region_yieldButtonPushed(app, event)
            % selects the region of to select the region of the pronounced yield strengh
            % required: the yield curve and E modulus should already be calculated
            %======================================================================================
            % lamp
            app.selection_status.Color = 'red';
            % Ask the user to select the region of pronounced yield strength
            app.indexPronouncedYieldStrength = cell(size(app.TT_Database,1),2);
            % for every direction of the loaded data
            for i_direction = 1:size(app.TT_Database,1)
                % get each data base
                databaseTT = app.TT_Database{i_direction,2};
                % get needed data and call the selection function
                rows = checkLabels(databaseTT, 'epsilonLEng', 'sigmaLEng');
                epsilonLEng = databaseTT{rows(1),2};
                sigmaLEng = databaseTT{rows(2),2};
                index = selectInput(epsilonLEng, sigmaLEng, 'xlabelIn', 'epsilon L Eng', 'ylabelIn', 'sigma L Eng', 'titleIn', 'Please select the region of the pronounced yield strength!');
%                 if isempty(index)
%                     Rp=[];indexRp=[];forceRp=[];epsilonLEng0Theo=[];fRp=[];epsilonXTrueRp=[];ARp=[];sigmaLTrueRp=[];epsilonLTrueEl=[];
%                       epsilonLTruePl=[];sigmaLTruePl=[];epsilonYieldCurve=[];sigmaYieldCurve=[]; epsilonXRp = [];
%                     return;
%                 end
                % save the selected index
                app.indexPronouncedYieldStrength{i_direction,1} = app.TT_Database{i_direction,1};
                app.indexPronouncedYieldStrength{i_direction,2} = index;
                % lamp
                app.selection_status.Color = 'green';
                app.calc_yield_status.Color = 'red';
                app.calc_n_value.Color = 'red';
                app.export_status.Color = 'red';
                app.bulge_extrapolation.Color = 'red';
            end % end for loop
        end

        % Button pushed function: ExportAbaqusinpButton
        function ExportAbaqusinpButtonPushed(app, event)
            % function to export the data for an usage with abaqus
            % required: the calculation should be finished (E-Modulus, yield  curve, Rm)
            %=======================================================================
            % lamp
            app.export_status.Color = 'red';
            % calculate density (in tons/mm^3)
            density = app.DensityEditField.Value;
            if strcmp(app.DropDown_density.Value,'tons/mm³')
                % nothing
            elseif strcmp(app.DropDown_density.Value,'kg/m³')
                density = density*1/(1000^4);
            elseif strcmp(app.DropDown_density.Value,'kg/cm³')
                density = density*1/(1000*100^3);
            elseif strcmp(app.DropDown_density.Value,'g/cm³')
                density = density*1/(1000^2*100^3);
            end
            % Print results to Abaqus .inp-file
            name = app.MaterialEditField.Value;
            [fn,path] = uiputfile('*.inp','Where do you want me to save your MaterialModel?',[name '.inp']);
            % test if an output is possible: if the directions are 0°, 45° and 90°. Only then an output is possible
            if strcmp(app.PerformedtestDropDown.Value, 'tensile/bulge')
                status = 1;
                numdir = size(app.TT_Database,1);
                if numdir == 3
                    directions = zeros(3);
                    for i_direction = 1:numdir
                        directions(i_direction) = app.TT_Database{i_direction,1};
                    end
                    if ~find(directions == 0)
                        status = 0;
                    elseif ~find(directions == 45)
                        status = 0;
                    elseif ~find(directions == 90) 
                        status = 0; 
                    end
                else
                    status = 0;
                end
                % is the output possible?
                if status == 1
                    degree0 = app.TT_Database{directions == 0,2};
                    degree45 = app.TT_Database{directions == 45,2};
                    degree90 = app.TT_Database{directions == 90,2};
                    exportAbaqus(degree0, density, 'degree45', degree45, 'degree90', degree90, 'materialName', name, 'filename', fn, 'path', path);
                else
                    msgbox('The Abaqus export is only implemented for the directions 0°, 45° and 90°','warn');
                end
            elseif strcmp(app.PerformedtestDropDown.Value, 'crush') % crush test
                try
                    exportAbaqus(app.CT_Database, density, 'materialName', name, 'path', path, 'filename', fn);
                catch
                    msgbox('No path selected','info');
                end
            end
            
            app.export_status.Color = 'green';
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: select_crush
        function select_crushButtonPushed(app, event)
            % this function lets the user select the data for the crush test.
            % Required: None
            %============================================================
            % check if the variables app.CT_Database already exist
            if isempty(app.CT_Database)
                % Create database
                app.CT_Database = database;
                rowsType = checkLabels(app.CT_Database, 'crushTest');
                app.CT_Database{rowsType(1),2} = true;     
            end       
            % Select files with uigetfile
            [fn, path]=uigetfile('*.*','Select the crush test data ','MultiSelect','on');
            % If only one file has been selecte: fn = char
            if ischar(fn) == 1
                h{1,1} = fn;
                fn = h;
            % If more then one file has been selecte: fn = cell
            elseif iscell(fn) ==1
            else
                msgbox('Error while determining how many files have been selected.');
                return;
            end
            % get rows to store the paths
            rowsData = checkLabels(app.CT_Database, 'fnCrushTest', 'pathCrushTest');
            % store the path
            app.CT_Database{rowsData(1),2} = fn;
            app.CT_Database{rowsData(2),2} = path;
            % display the selected path in the table
            app.tabledata = app.UITable_crush.Data; % the table data is stored in app.tabeldata
            index = [];
            % check if already cell crush data is in the table
            if isempty(app.tabledata) == 0
                index = find(strcmp(app.tabledata(:,1), 'crush'));
            end
            app.tabledata(index,:) = []; % delete old crush entries
            size_table = size(app.tabledata,1);
            insertrow = 1;
            for rowcell = 1:size(fn,2)
                app.tabledata{size_table+insertrow+1,1} = 'crush';
                app.tabledata(size_table+insertrow+1,2) = fn(1,rowcell);
                insertrow = insertrow+1;
            end
            % delete empty cells in table data
%             index = find(cellfun(@isempty,app.tabledata(:,1)));
            app.tabledata(cellfun(@isempty,app.tabledata(:,1)),:) = [];
            % asign data to UITable
            app.UITable_crush.Data = app.tabledata;
            
            %set light to red
            app.load_status.Color = 'red';
            app.calc_status.Color = 'red';
            app.calc_yield_status.Color = 'red';
            app.calc_n_value.Color = 'red';
            app.bulge_extrapolation.Color = 'red';
                
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: select_stiffness
        function select_stiffnessButtonPushed(app, event)
            % lets the user select the stiffness test
            %Required: none
            %======================================
            % Select files of the stiffness experiments
            [fnStiff, pathStiff]=uigetfile('*.*','Select data of the stiffness experiments','MultiSelect','on');
            if ischar(fnStiff) == 1 % Wenn nur eine Datei ausgewählt ist, ist das Ergebnis ein char
                h{1,1} = fnStiff;
                fnStiff = h;
            end
            % save path
            app.Stiff_filepath = {fnStiff,pathStiff};
            %add data to table
            app.tabledata = app.UITable_crush.Data; 
            index = [];
            if isempty(app.tabledata) == 0
                index = find(strcmp(app.tabledata(:,1), 'stiffness'));
            end
            app.tabledata(index,:) = [];
            size_table = size(app.tabledata,1);
            insertrow = 1;
            for rowcell = 1:size(fnStiff,2)
                app.tabledata{size_table+insertrow+1,1} = 'stiffness';
                app.tabledata{size_table+insertrow+1,2} = fnStiff{1,rowcell};
                insertrow = insertrow+1;
            end
            app.tabledata(cellfun(@isempty,app.tabledata(:,1)),:) = [];
            app.UITable_crush.Data = app.tabledata;
            %set light to red
            app.load_status.Color = 'red';
            % handle visibility
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: Stiffnes_needed
        function Stiffnes_neededValueChanged(app, event)
            % shows the button to select the stiffness files, when stiffness correlation is selected
            value = app.Stiffnes_needed.Value;
            if value == 1
                app.select_stiffness.Visible = 'on';
                app.DatafilestiffnesstestLabel.Visible = 'on';
            else
                app.select_stiffness.Visible = 'off';
                app.DatafilestiffnesstestLabel.Visible = 'off';
            end
        end

        % Button pushed function: manipulate_basic_crush
        function manipulate_basic_crushButtonPushed(app, event)
            % function to let the user manipulate the basic crush data
            % Required: Crush data already should be loaded in
            %======================================
            rows = checkLabels(app.CT_Database,'fnCrushTest','lengthCrushTestEdit','forceCrushTestEdit','timeCrushTestEdit');  
            fn = app.CT_Database{rows(1),2};
            h = size(fn);
            n = h(2);
            clear h;
            if isempty(app.baseindex)
                app.baseindex = cell(n-1,1);
            end    
            for jj = 2:n+1 % All columns without the first column that contains the labels
                index = selectInput(app.CT_Database{rows(2),jj}, app.CT_Database{rows(3),jj}, 'xlabelIn', 'displacement [mm]', 'ylabelIn', 'force [N]', 'titleIn', ['Please choose data points of experimental Set ' num2str(jj-1) '/' num2str(n)]); % Let the user select the data points
                % Shorten all time dependent data
                app.CT_Database{rows(2),jj} = app.CT_Database{rows(2),jj}(index);
                app.CT_Database{rows(3),jj} = app.CT_Database{rows(3),jj}(index);
                app.CT_Database{rows(4),jj} = app.CT_Database{rows(4),jj}(index);
                % store the selected indices
                app.baseindex{jj-1,1} = index;
            end
            % lamp
            app.calc_status.Color = 'red';
            app.calc_yield_status.Color = 'red';
            app.calc_n_value.Color = 'red';
            app.bulge_extrapolation.Color = 'red';
        end

        % Button pushed function: maipualte_averaged_crush
        function maipualte_averaged_crushButtonPushed(app, event)
            % lets the  user manipulate the averaged crush data
            % Required: crush data should be loaded in
            %======================
            if isempty(app.averagedindex)
                app.averagedindex = [];
            end
            % get the rows of needed data and call input funciton
            rows = checkLabels(app.CT_Database, 'lengthCrushTest', 'forceCrushTest', 'timeCrushTest');
            index = selectInput(app.CT_Database{rows(1),2}, app.CT_Database{rows(2),2}, 'xlabelIn', 'displacement [mm]', 'ylabelIn', 'force [N]', 'titleIn', 'Please choose data points of averaged data!'); % Let the user select the data points
            % Shorten all time dependent data
            app.CT_Database{rows(1),2} = app.CT_Database{rows(1),2}(index);
            app.CT_Database{rows(2),2} = app.CT_Database{rows(2),2}(index);
            app.CT_Database{rows(3),2} = app.CT_Database{rows(3),2}(index);
            % store the selected indices
            app.averagedindex = index;
            % Engineering Calculations
            app.CT_Database = calculationsCrushTest(app.CT_Database);
            % plot data
            app.plot_CT;
            % lamp
            app.calc_status.Color = 'red';
            app.calc_yield_status.Color = 'red';
            app.calc_n_value.Color = 'red';
            app.bulge_extrapolation.Color = 'red';
        end

        % Button pushed function: replotreaveragedataButton_crush
        function replotreaveragedataButton_crushPushed(app, event)
                % replots the averaded data. This is necessary when the user manipulated the basic data.
                % the maniulated basic data doesn_t get averaged and plottet directly after the selection
                % in case the user wants to manipulate other series.
                % Required: data already loadad in
                %===========================
                % Waitbar
                controller = 1;
                Message = 'averaging data...';
                f = javawaitbar(app,controller,Message); 
                
                try
                
                % check if base vector has been defined
                if isempty(app.basevector)
                    baseVectorMin = [];
                    baseVectorStepSize = [];
                    baseVectorMax = [];  
                else
                    baseVectorMin = app.basevector{1,1};
                    baseVectorStepSize = app.basevector{1,2};
                    baseVectorMax = app.basevector{1,3};
                end
                % call averaging function
                app.CT_Database = averageExperimentalData(app.CT_Database, {'lengthCrushTestEdit', 'forceCrushTestEdit', 'timeCrushTestEdit', ...
                    'diameterInitialCrushTestEdit', 'lengthInitialCrushTestEdit'}, ...
                    'labelsOut', {'lengthCrushTest', 'forceCrushTest', 'timeCrushTest', ...
                    'diameterInitialCrushTest', 'lengthInitialCrushTest'}, ...
                    'baseVectorMin', baseVectorMin, 'baseVectorStepSize', baseVectorStepSize, 'baseVectorMax', baseVectorMax);
                % find rows of the basevector in order to display the used base vector.
                rowsBaseVector = checkLabels(app.CT_Database, 'baseVectorMin', 'baseVectorStepSize', 'baseVectorMax');
                app.bv_begin.Value =  num2str(app.CT_Database{rowsBaseVector(1),2});
                app.bv_stepsize.Value =  num2str(app.CT_Database{rowsBaseVector(2),2});
                app.bv_end.Value =  num2str(app.CT_Database{rowsBaseVector(3),2});
                % Engineering Calculations
                app.CT_Database = calculationsCrushTest(app.CT_Database);
                % plot average Experimental Data
                app.load_data_label.Visible = 'off';
                app.plot_CT;
                % close waitbar
                close(f);
                % lamp
                app.calc_status.Color = 'red';
                app.calc_yield_status.Color = 'red';
                app.calc_n_value.Color = 'red';
                app.bulge_extrapolation.Color = 'red';
                
                catch
                    close all
                    msgbox('An error occured', 'error', 'error');
                    return
                end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % this funciton calls the plot function according to the user selection
            %=============
            if app.tensiletestButton.Value == 1
                app.plot_TT;
            elseif app.bulgetestButton.Value == 1
                app.plot_BT;
            end
        end

        % Callback function
        function bulge_interpolationValueChanged(app, event)
            
        end

        % Cell edit callback: UITable_TT
        function UITable_TTCellEdit(app, event)
            % this function lets the user select the bulge interpolation for a direction
            % he can choose in the table
            % required: tabledata
            %==================
            tdata = app.UITable_TT.Data;
            for irow = 1:size(tdata,1)
                tabledirection = tdata{irow,1};
                if ~strcmp(tabledirection,'bulge')
                    tablevalue = tdata{irow,2};
            %                     tablefilename = tdata{irow,3};
                    ind = find([app.TT_filepath{:,1}] == tabledirection);
    %                 filenames = app.TT_filepath{ind,2};
    %                 ind = find(strcmp([filenames(1,:)],tablefilename));
                    filepathvalue = app.TT_filepath{ind,4};
                    if filepathvalue ~= tablevalue
                        if tablevalue == 1
                            app.TT_filepath{ind,4} = true;
                            break;
                        elseif tablevalue == 0
                            app.TT_filepath{ind,4} = false;
                            break;
                        end
                    end
                end
            end
            
            % add data to table
            % tensile test
            app.tabledata = cell(1,3);
            insertrow = 1;
            for rowcount = 1:size(app.TT_filepath,1)
                filename = app.TT_filepath{rowcount,2};
                for rowcell = 1:size(filename,2)
                    app.tabledata(insertrow,1) = app.TT_filepath(rowcount,1);
                    app.tabledata(insertrow,2) = app.TT_filepath(rowcount,4);
                    app.tabledata(insertrow,3) = filename(1,rowcell);
                    insertrow = insertrow+1;
                end
            end
            % bulge test
            if ~isempty(app.BT_filepath)
                if ~isempty(app.BT_filepath{1,1})
                    tablesize = size(app.tabledata,1);
                    filename = app.BT_filepath{1,2};
                    for rowcell = 1:size(filename,2)
                        app.tabledata{tablesize + rowcell,1} = 'bulge';
                        app.tabledata{tablesize + rowcell,2} = '-';
                        app.tabledata(tablesize + rowcell,3) = filename(1,rowcell);
                    end
                end
            end 
            app.UITable_TT.Data = app.tabledata;     
            
            app.bulge_interpolationValueChanged;
        end

        % Button pushed function: 
        % select_rangeforrvaluecalculationButton
        function select_rangeforrvaluecalculationButtonPushed(app, event)
            % jsut changes active tab
            app.TabGroup.SelectedTab = app.rnvalueboundarydeterminationTab;
        end

        % Button pushed function: 
        % calculateyieldcurvervaluenvalueButton
        function calculateyieldcurvervaluenvalueButtonPushed(app, event)
            % this function calculates the yield curve, the r-value and the n-value for the Tensile/Bulge test
            % required: data should be loaded iin and manipulated. Area to calculate the E Modulus should be specified.
            % ==========================================================================================
            % is the input in the tables correct?
            correct = 0;
            if app.E_input_correct == 1 % correct data in table
                if app.r_input_correct == 1
                    correct = 1; 
                else
                    msgbox('The data in the table for the r-Value-range has the wrong format.', 'Error','error');
                end
            else
                msgbox('The data in the table for the E-Modulus has the wrong format.', 'Error','error');
            end
            if ~app.r_input_correct == 1
                msgbox('The data in the table for the r-Value-range has the wrong format.', 'Error','error');
            end
            
%             try
            
            if correct == 1 % input is correct => start calculation
                % lamp
                app.calc_yield_status.Color = 'red';
                % waitbar
                controller = 1;
                Message = 'calculating...';
                f = javawaitbar(app,controller,Message);
                % loop for each  direction
                for i_direction = 1:size(app.TT_Database,1)
                    databaseTT = app.TT_Database{i_direction,2}; % get data of chooses direction
                    % yield curve
                    % =============
                    % experimental or literature value
                    tdata = app.UITable_E_Rm.Data;
                    if strcmp(tdata(i_direction,3),'[]') % no input has been made => experimental value
                        ELit = [];
                    else % use the literature value
                        ELit = str2double(tdata(i_direction,3));
                    end
                    % check if pronounced yield strengh has been selected
                    if app.pronouncedyieldstrenghCheckBox.Value == 1 %yes
                        index = app.indexPronouncedYieldStrength{i_direction,2};
                        databaseTT = yieldCurve_app(databaseTT, 'pronouncedYieldStrength', true, 'ELit', ELit, 'indexPronouncedYieldStrength', index);
                    else % no pronounced yield strengh
                        %use rp value from edit field
                        Rp_value = app.epsilonRpEditField.Value;
                        databaseTT = yieldCurve_app(databaseTT, 'epsilonLEngRp', Rp_value, 'ELit', ELit);
                    end
                    % extrapolation
                    % ====================
                    % run extrapolation
                    databaseTT = extrapolation(databaseTT,app.expParameter);
                    %% r Value
                    % ==============
                    evalMinRValue = app.UITable_rvalue.Data{i_direction,1};
                    evalMaxRValue = app.UITable_rvalue.Data{i_direction,2};
                    nue = app.Poissonsnumber.Value;
                    databaseTT = rValue(databaseTT, evalMinRValue, evalMaxRValue, 'nue', nue);
                    if isempty(databaseTT)
                        return;
                    end
                    %% n Value
                    % ==============
                    databaseTT = nValue(databaseTT);
                    if isempty(databaseTT)
                        return;
                    end           
                    %% bulge extrapolation
                    % ================================================
                    if app.TT_filepath{i_direction,4} == 1 % if for the respecive direction the bulge extrapolation is desired
                        % transfer data
                        databaseTT{6,2} = app.BT_Database{6,2};
                        databaseTT{7,2} = app.BT_Database{7,2};
                        for filenumb = 1:size(app.BT_Database,2)
                            for irow = 31:39 % the bulge data is stored in rows 31-39
                                databaseTT{irow,filenumb} = app.BT_Database{irow,filenumb};
                            end
                        end %end for exchange data
                        %% Extrapolate tensile test data
                        % =======================================
                        databaseTT = bulgeextrapolationTensileTest(databaseTT);
                        % create labels to show in the dropdown menu
                        i_label_bulge = [num2str(app.TT_Database{i_direction,1}),'-degree'];
                        if i_direction == 1 || ~exist('plot_label_bulge','var')
                            plot_label_bulge = {i_label_bulge};
                        else
                            plot_label_bulge{1,end+1} = i_label_bulge;
                        end
                    end % end if bulge test
                    % save manipualted data                        
                    app.TT_Database{i_direction,2} = databaseTT;
                    % create label to show in the dropdown menu (2)
                    i_label = [num2str(app.TT_Database{i_direction,1}),'-degree'];
                    if i_direction == 1
                        plot_label = {i_label};
                    else
                        plot_label{1,end+1} = i_label;
                    end
                end % end for
                %% plot the different approaches
                % ==================================
                index = cellfun(@(x)isequal(x,1),app.TT_filepath(:,4));
                if any(index) == 1 % if for one direction the bulge extrapolation has been selected
            %                     % set check box values to 1
            %                     app.LudwikCheckBox.Value = 1;
            %                     app.GoshCheckBox.Value = 1;
            %                     app.HockettSherbyCheckBox.Value = 1;
            %                     app.SwiftCheckBox.Value = 1;
            %                     app.VoceCheckBox.Value = 1;
            %                     % fill dropdown
            %                     app.DirectionDropDown.Items = plot_label_bulge;
            %                     app.DirectionDropDown_2.Items = plot_label_bulge;
            %                     app.plot_approaches;
                    
                    % data for approaches
                    approachtabledata = cell(size(plot_label_bulge,2),2); % approach table
                    for i_direction = 1:size(plot_label_bulge,2)
                            approachtabledata{i_direction,1} = 'Swift';
                            approachtabledata{i_direction,2} = 'Hockett Sherby';
                    end
                    app.UITable_approach.Data = approachtabledata;
                    app.UITable_approach.RowName = plot_label_bulge;
                end
                % set check box values to 1
                    app.LudwikCheckBox.Value = 1;
                    app.GoshCheckBox.Value = 1;
                    app.HockettSherbyCheckBox.Value = 1;
                    app.SwiftCheckBox.Value = 1;
                    app.VoceCheckBox.Value = 1;
                % fill dropdown
                    app.DirectionDropDown.Items = plot_label;
                    app.DirectionDropDown_2.Items = plot_label;
                    if any(index) == 1 % if for one direction the bulge extrapolation has been selected
                        app.DirectionDropDown.Value = plot_label_bulge{1};
                        app.DirectionDropDown_2.Value = plot_label_bulge{1};
                    end
                    app.plot_approaches;
                % data for approaches
                exportapproachtabledata = cell(size(plot_label,2),1); % approach table
                for i_direction = 1:size(plot_label,2)
                        exportapproachtabledata{i_direction,1} = '-';
                end
                app.UITable_approach_export.Data = exportapproachtabledata;
                app.UITable_approach_export.RowName = plot_label;
                %% plot "n value"
                % ====================================
                plot_label = app.plot_n;
                % fill table values
                ntabledata = cell(size(app.TT_Database,1),2); % n-table
                rntabledata = cell(size(app.TT_Database,1),3); % rn-table
                rows = checkLabels(app.TT_Database{i_direction,2}, 'epsilonLTruePl', 'sigmaLTruePl', 'r', 'nValue');
                for i_direction = 1:size(app.TT_Database,1)
                    databaseTT = app.TT_Database{i_direction,2};
                    ntabledata{i_direction,1} = '[]'; % lower boundary
                    ntabledata{i_direction,2} = '[]'; % upper boundary
                    
                    rntabledata{i_direction,1} = app.TT_Database{i_direction,1};
                    rntabledata{i_direction,2} = databaseTT{rows(3),2};
                    rntabledata{i_direction,3} = databaseTT{rows(4),2};
                end
                app.UITable_nvalue.Data = ntabledata;
                app.UITable_nvalue.RowName = plot_label;
                app.n_input_correct = 1;
                
                app.UITable_rn_value.Data = rntabledata;
                %lamp
                app.calc_yield_status.Color = 'green';
                app.calc_n_value.Color = 'red';
                app.export_status.Color = 'red';
                app.bulge_extrapolation.Color = 'red';
                % close waitbar
                close(f) 
                % enable buttons
                app.ExportAbaqusinpButton.Enable = 'on';
                app.recalculatenvalueButton.Enable = 'on';
                app.extrapolationButton.Enable = 'on';
            end % end if correct inputs
            
%             catch
%                 close all
%                 msgbox('An error occured', 'error', 'error');
%                 return
%             end
        end

        % Cell edit callback: UITable_E_Rm
        function UITable_E_RmCellEdit(app, event)
             % This function is called when the user puts input in the table which displays the E modulus
             % and checks whether the input has the right format.
             % Required: none
             %===================================
             data = app.UITable_E_Rm.Data;
             for i_direction = 1:size(data,1)
                if isempty(data{i_direction,3})
                    data{i_direction,3} = '[]';
                    app.UITable_E_Rm.Data = data;
                end
                if ~strcmp(data{i_direction,3},'[]')
                    EModul = str2double(data{i_direction,3});
                    if ~isnan(EModul) % correct input
                        app.E_input_correct = 1;
                    else % wrong input
                        msgbox('The data must either be numeric or "[]"', 'Error','error');
                        app.E_input_correct = 0;
                        break
                    end
                else
                    app.E_input_correct = 1;
                end
             end
             % lamp
             app.calc_yield_status.Color = 'red';
             app.calc_n_value.Color = 'red';
             app.export_status.Color = 'red';
        end

        % Cell edit callback: UITable_rvalue
        function UITable_rvalueCellEdit(app, event)
             % This function is called when the user puts input in the table which displays the r Value
             % and checks whether the input has the right format.
             % Required: none
             %===================================
             data = app.UITable_rvalue.Data;
             app.r_input_correct = 0;
             for i_direction = 1:size(data,1)
                lower = data{i_direction,1};
                upper = data{i_direction,2};
                
                if ~isnan(lower) && ~isnan(upper) % correct input
                    app.r_input_correct = 1;
                else % wrong input
                    msgbox('The data must be numeric', 'Error','error');
                    app.r_input_correct = 0;
                    break
                end
             end
             % lamp
             app.calc_yield_status.Color = 'red';
             app.calc_n_value.Color = 'red';
             app.export_status.Color = 'red';
        end

        % Button pushed function: returnButton_4
        function returnButton_4Pushed(app, event)
            % change tab
            app.TabGroup.SelectedTab = app.mainTab;
        end

        % Button pushed function: returnButton_3
        function returnButton_3Pushed(app, event)
            %change tab
            app.TabGroup.SelectedTab = app.mainTab;
        end

        % Button pushed function: helpButton
        function helpButtonPushed(app, event)
            % displays the info when the help button is pressed
            if strcmp(app.UITable_E_Rm.Visible,'on')
                app.TextEModulus.Visible = 'on';
                app.UITable_E_Rm.Visible = 'off';
            else % blend out info
                app.TextEModulus.Visible = 'off';
                app.UITable_E_Rm.Visible = 'on';
            end
        end

        % Button pushed function: 
        % select_rangefornvaluecalculationButton
        function select_rangefornvaluecalculationButtonPushed(app, event)
            % changes tab
            app.TabGroup.SelectedTab = app.rnvalueboundarydeterminationTab;
        end

        % Cell edit callback: UITable_nvalue
        function UITable_nvalueCellEdit(app, event)
            % This function is called when the user puts input in the table which displays the n Value
            % and checks whether the input has the right format.
            % Required: none
            %===================================
            data = app.UITable_nvalue.Data;
            app.n_input_correct = 1;
            for i_direction = 1:size(data,1)
                for column = 1:2
                    boundary = data{i_direction,column};
                    if isempty(boundary)
                        data{i_direction,column} = '[]';
                        app.UITable_nvalue.Data = data;
                    end
                    if ~strcmp(boundary,'[]')
                        boundary = str2double(boundary);
                        if isnan(boundary) % wrong input
                            msgbox('The data must either be numeric or "[]"', 'Error','error');
                            app.n_input_correct = 0;
                            break
                        end
                    end
                end
            end
            % lamp
            app.calc_n_value.Color = 'red';
            app.export_status.Color = 'red';
        end

        % Button pushed function: recalculatenvalueButton
        function recalculatenvalueButtonPushed(app, event)
            % this function recalculates the n value.
            % Required: yield  curve and e modulus
            %======================================
            % is the input in the tables correct?
            correct = 0;
            if app.E_input_correct == 1 % correct data in table
                if app.r_input_correct == 1
                    if app.n_input_correct == 1
                        correct = 1; 
                    else
                        msgbox('The data in the table for the n-Value-range has the wrong format.', 'Error','error');
                    end
                else
                    msgbox('The data in the table for the r-Value-range has the wrong format.', 'Error','error');
                    if ~app.n_input_correct == 1
                        msgbox('The data in the table for the n-Value-range has the wrong format.', 'Error','error');
                    end
                end
            else
                msgbox('The data in the table for the E-Modulus has the wrong format.', 'Error','error');
            end
            
            try
            
            % start the calculation
            if correct == 1
                % lamp
                app.calc_n_value.Color = 'red';
                % loop for every direction
                for i_direction = 1:size(app.TT_Database,1)
                    databaseTT = app.TT_Database{i_direction,2};
                    %% n Value
                    % ==============
                    % get the min and values
                    if strcmp(app.UITable_nvalue.Data{i_direction,1},'[]') % no input has been made
                        databaseTT = nValue(databaseTT);
                    else % an input has been made
                        evalMinNValue = str2double(app.UITable_nvalue.Data{i_direction,1});
                        evalMaxNValue = str2double(app.UITable_nvalue.Data{i_direction,2});
                        databaseTT = nValue(databaseTT, 'evalNMin', evalMinNValue, 'evalNMax', evalMaxNValue);
                    end
                    if isempty(databaseTT)
                        return;
                    end
                    % save changed data
                    app.TT_Database{i_direction,2} = databaseTT;
                end
                % plot "n value"
                % fill table values
                rntabledata = cell(size(app.TT_Database,1),3); % rn-table
                rows = checkLabels(app.TT_Database{i_direction,2}, 'epsilonLTruePl', 'sigmaLTruePl', 'r', 'nValue');
                for i_direction = 1:size(app.TT_Database,1)
                    databaseTT = app.TT_Database{i_direction,2};
                    rntabledata{i_direction,1} = app.TT_Database{i_direction,1};
                    rntabledata{i_direction,2} = databaseTT{rows(3),2};
                    rntabledata{i_direction,3} = databaseTT{rows(4),2};
                end
                % asign data to UITable
                app.UITable_rn_value.Data = rntabledata;
                % lamp
                app.calc_n_value.Color = 'green';
                app.export_status.Color = 'red';
            end
            
            catch
                close all
                msgbox('An error occured', 'error', 'error');
                return
            end
        end

        % Button pushed function: EModulusButton_crush
        function EModulusButton_crushPushed(app, event)
            % function to choose the data used to calculate the E-Modulus for the crush test (save computation time)
            % Required: data already loaded
            %===========
            % create the variable to store the e modulus in
            app.eModulusIndexCT = [];                 
            % get needed data (rows) and call the selection function
            rows = checkLabels(app.CT_Database, 'epsilonLEng', 'sigmaLEng', 'epsilonLTrue', 'sigmaLTrue');
            epsilonLEng = app.CT_Database{rows(1),2};
            sigmaLEng = app.CT_Database{rows(2),2};
            % call function
            index = selectInput(epsilonLEng, sigmaLEng, 'xlabelIn', 'epsilon [-]', 'ylabelIn', 'sigma [N/mm^2]', 'titleIn', 'Please choose data point to evaluate E-Modulus!'); % Let the user select the data points
            if isempty(index)
                return;
            end
            % save indices
            app.eModulusIndexCT = index;
            % plot the E modulus or the crush data
            value = app.displayButton_2.Value;
            if value == 1
                app.plot_EMod_Index;
            else
                app.plot_CT;
            end
        end

        % Value changed function: comparebasicdataseriesButton
        function comparebasicdataseriesButtonValueChanged(app, event)
            % this function calls the funtions to plot the basic data series.
            % Required: data loaded in
            %============================
            value = app.comparebasicdataseriesButton.Value;
            dd_value = app.DropDown_basic.Value;
            if value == 1
                if strcmp(dd_value,'bulge test')
                    % plot the selected data
                    app.plot_basic_BT;
                else
                    % plot the selected data
                    app.plot_basic_TT;
                end
            else
                if strcmp(dd_value,'bulge test')
                    % plot the selected data
                    app.plot_BT;
                else
                    % plot the selected data
                    app.plot_TT;
                end
            end 
        end

        % Button pushed function: helpButton_2
        function helpButton_2Pushed(app, event)
            % shows the help
            if strcmp(app.TextArea2.Visible,'on')
                app.TextArea2.Visible = 'off';
            else
                app.TextArea2.Visible = 'on';
            end
        end

        % Callback function
        function replotbasicdataButtonPushed(app, event)
            % this function calls the plot functions (Bulge or Tensile Test)
            dd_value = app.DropDown_basic.Value;
            if strcmp(dd_value,'bulge test')
                % plot the selected data
                app.plot_basic_BT;
            else
                % plot the selected data
                app.plot_basic_TT;
            end
        end

        % Value changed function: DropDown3
        function DropDown3ValueChanged(app, event)
            % This function is called when the user selects a material from the dropdown menu
            % and puts the density in the field
            value = app.DropDown3.Value;
            if ~strcmp(value,'select material')
                app.MaterialEditField.Value = value;
            else
                app.MaterialEditField.Value = '';
                app.DensityEditField.Value = 0;
            end
            % density    
            if strcmp(value,'Stahl')
                app.DensityEditField.Value = 7850;
                app.DropDown_density.Value = 'kg/m³';
                app.Poissonsnumber.Value = 0.3;
            elseif strcmp(value,'Aluminium')
                app.DensityEditField.Value = 2700;
                app.DropDown_density.Value = 'kg/m³';
                app.Poissonsnumber.Value = 0.34;
            end
        end

        % Value changed function: DropDown_basic
        function DropDown_basicValueChanged(app, event)
            % this function calls either the plot funtion for the bulge or tensile test.
            value = app.comparebasicdataseriesButton.Value;
            dd_value = app.DropDown_basic.Value;
            % decide
            if value == 1
                if strcmp(dd_value,'bulge test')
                    % plot the selected data
                    app.plot_basic_BT;
                else
                    % plot the selected data
                    app.plot_basic_TT;
                end
            end
        end

        % Button pushed function: selectextrapolationapproachButton
        function selectextrapolationapproachButtonPushed(app, event)
            % change tab
            app.TabGroup.SelectedTab = app.extrapolationapproachTab;
        end

        % Button pushed function: extrapolationButton
        function extrapolationButtonPushed(app, event)
            % this function extrapolated the data again by calling the "extrapolation" function
            % additionally it stores the extrapolation approach used for export in the database. 
            % required: E Modulus and Yield Curve computed
            %====================
            % waitbar
            controller = 1;
            Message = 'calculating...';
            f = javawaitbar(app,controller,Message); 
            
            try
            
            % lamp
            app.bulge_extrapolation.Color = 'red';
            % gets the approaches from the tables
            approachtablerows = app.UITable_approach.RowName;
            approachtabledata = app.UITable_approach.Data;
            exportapproachtablerows = app.UITable_approach_export.RowName;
            exportapproachtabledata = app.UITable_approach_export.Data;
            % loop for every directon
            for i_direction = 1:size(app.TT_Database,1)
                % get database
                 databaseTT = app.TT_Database{i_direction,2};
                 % name = current direction
                 name = app.TT_Database{i_direction,1};
                 name = [num2str(name) '-degree'];
                 % get the selected extrapolation approach from the table
                 exportapproach = char(exportapproachtabledata(cellfun(@(x)isequal(x,name),exportapproachtablerows)));
                 % which approach has been selected
                 if ~strcmp(exportapproach,'-')
                     if strcmp(exportapproach,'Swift')
                         export = 'Swift';
                     elseif strcmp(exportapproach,'Ludwik')
                         export = 'Ludwik';
                     elseif strcmp(exportapproach,'Ghosh')
                         export = 'Ghosh';
                     elseif strcmp(exportapproach,'Hockett Sherby')
                         export = 'HockettSherby';
                     elseif strcmp(exportapproach,'Voce')
                         export = 'Voce';
                     end
                     % call the extrapolation function and give the decided export with it
                     databaseTT = extrapolation(databaseTT, app.expParameter, 'export', export);
                 end
                 % if for the respecive direction the bulge extrapolation is desired
                 if app.TT_filepath{i_direction,4} == 1
                     %% alpha calculation
                     % ===============================
                     % get the approaches
                     firstselection = char(approachtabledata(cellfun(@(x)isequal(x,name),approachtablerows),1));
                     secondselection = char(approachtabledata(cellfun(@(x)isequal(x,name),approachtablerows),1));
                     % decide which approach to give to the function
                     if strcmp(firstselection,'Swift')
                         approach1 = 'fitSwift';
                     elseif strcmp(firstselection,'Ludwik')
                         approach1 = 'fitLudwik';
                     elseif strcmp(firstselection,'Ghosh')
                         approach1 = 'fitGhosh';
                     elseif strcmp(firstselection,'Hockett Sherby')
                         approach1 = 'fitHockettSherby';
                     elseif strcmp(firstselection,'Voce')
                         approach1 = 'fitVoce';
                     end
                     % second approach
                     if strcmp(secondselection,'Swift')
                         approach2 = 'fitSwift';
                     elseif strcmp(secondselection,'Ludwik')
                         approach2 = 'fitLudwik';
                     elseif strcmp(secondselection,'Ghosh')
                         approach2 = 'fitGhosh';
                     elseif strcmp(secondselection,'Hockett Sherby')
                         approach2 = 'fitHockettSherby';
                     elseif strcmp(secondselection,'Voce')
                         approach2 = 'fitVoce';
                     end
                     % call function to calculate the alpha value
                     databaseTT = fitAlpha(databaseTT, 'approach1', approach1, 'approach2', approach2);
                 end
                 
                 app.TT_Database{i_direction,2} = databaseTT;
             end % end for loop
             
             % waitbar
             close(f)
             % lamp
             app.bulge_extrapolation.Color = 'green';
             
             catch
                 close all
                 msgbox('An error occured', 'error', 'error');
                 return
             end
        end

        % Value changed function: LudwikCheckBox
        function LudwikCheckBoxValueChanged(app, event)
            % plot
            app.plot_approaches;
        end

        % Value changed function: GoshCheckBox
        function GoshCheckBoxValueChanged(app, event)
            % plot
            app.plot_approaches;
        end

        % Value changed function: HockettSherbyCheckBox
        function HockettSherbyCheckBoxValueChanged(app, event)
            % plot
            app.plot_approaches;
        end

        % Value changed function: SwiftCheckBox
        function SwiftCheckBoxValueChanged(app, event)
            app.plot_approaches;
        end

        % Value changed function: VoceCheckBox
        function VoceCheckBoxValueChanged(app, event)
            % plot
            app.plot_approaches;
        end

        % Value changed function: DirectionDropDown
        function DirectionDropDownValueChanged(app, event)
            % checks if the bulge extrapolation in the selected direction exists, and can therefore be plottet
            %=============
            if app.bulgeextrapolaitionapproachButton.Value == 1
                dir = app.DirectionDropDown.Value;
                plot_label = app.UITable_approach.RowName;
                % find the row number
                if ~cellfun(@(x)contains(x,dir),plot_label)
                    msgbox('no bulge interpolation in this direction','warn','warn');
                    return;
                end
            end
            app.plot_approaches;
        end

        % Button pushed function: returnButton_2
        function returnButton_2Pushed(app, event)
            % change tab
            app.TabGroup.SelectedTab = app.mainTab;
        end

        % Value changed function: firstselectionDropDown
        function firstselectionDropDownValueChanged(app, event)
            % this functions writes the extrapolation approaches selected by the user in the dropdown menu
            % in the table
            %==========================
            % get direction from dropdown
            dir = app.DirectionDropDown_2.Value;
            % decide if extrapolation or bulge extrapolation
            if app.exportextrapolationapproachButton.Value == 1
                plot_label = app.UITable_approach_export.RowName;
            elseif app.bulgeextrapolaitionapproachButton.Value == 1
                plot_label = app.UITable_approach.RowName;
            end
            % find the row number
            rownumb = find(strcmp(plot_label, dir));
            if isempty(rownumb)
                return;
            end
            % write data to table
            approachtabledata = app.UITable_approach.Data;
            approachtabledata{rownumb,1} = app.firstselectionDropDown.Value;
            approachtabledata{rownumb,2} = app.secondselectionDropDown.Value;     
            app.UITable_approach.Data = approachtabledata;
        end

        % Value changed function: secondselectionDropDown
        function secondselectionDropDownValueChanged(app, event)
            % this functions calls the function that writes the extrapolation approaches selected 
            % he user in the dropdown menu in the table
            %==========================
            app.firstselectionDropDownValueChanged;
        end

        % Button pushed function: select_region_yield_crush
        function select_region_yield_crushButtonPushed(app, event)
            % this function lets the user select the region for the pronounced yield strenght
            % required: Data loaded and E modulus already calculatet
            %================
            % lamp
            app.selection_status_crush.Color = 'red';
            % Ask the user to select the region of pronounced yield strength
            rows = checkLabels(app.CT_Database, 'epsilonLEng', 'sigmaLEng');
            epsilonLEng = app.CT_Database{rows(1),2};
            sigmaLEng = app.CT_Database{rows(2),2};
            % call function to select region
            app.indexPronouncedYieldStrength = selectInput(epsilonLEng, sigmaLEng, 'xlabelIn', 'epsilon L Eng', 'ylabelIn', 'sigma L Eng', 'titleIn', 'Please select the region of the pronounced yield strength!');
            % lamp
            app.selection_status_crush.Color = 'green';
            app.selection_status.Color = 'red';
        end

        % Button pushed function: calculation_crush
        function calculation_crushPushed(app, event)
            % this function does the calculations (E Modulus) for the crush test
            % required: crush test data loaded (and manipulated)
            %===================
            % waitbar
            controller = 1;
            Message = 'calculating...';
            f = javawaitbar(app,controller,Message); 
            
            try
            
            %% E-Modulus
            % ==============
            % Choose data that are used to calculate the E-Modulus to save computation time
            if isempty(app.eModulusIndexCT) % if no data for the E-Modulus has been selected by the user
                rows = checkLabels(app.CT_Database, 'epsilonLEng');
                index = 1:1:size(app.CT_Database{rows(1),2});
            else
                index = app.eModulusIndexCT; % for E-Modulus
            end
            % get needed data (find rows)
            rows = checkLabels(app.CT_Database, 'epsilonLTrue', 'sigmaLTrue');
            epsilonLTrue = app.CT_Database{rows(1),2};
            sigmaLTrue = app.CT_Database{rows(2),2};
            % Temporarly change contents of databaseCT
            app.CT_Database{rows(1),2} = epsilonLTrue(index);
            app.CT_Database{rows(2),2} = sigmaLTrue(index);
            % E-Modulus calculation
            app.CT_Database = EModulus(app.CT_Database);
            if isempty(app.CT_Database)
                return;
            end
            % Restore the original entries for engineering strain and stress
            app.CT_Database{rows(1),2} = epsilonLTrue;
            app.CT_Database{rows(2),2} = sigmaLTrue;
            % write calculated e Modulus iin table
            rows = checkLabels(app.CT_Database, 'EExp');
            EModtabledata = cell(1,2);
            EModtabledata{1,1} = app.CT_Database{rows(1),2};
            EModtabledata{1,2} = '[]';
            app.Table_EMod_Crush.Data = EModtabledata;
            app.Table_EMod_Crush.ColumnFormat = {'bank' 'bank'};
            app.E_input_correct = 1;
            % lamp
            app.calc_EMod_crush.Color = 'green';
            app.calc_yield_crush.Color = 'red';
            % enable buttons
            app.calculateyieldcurve_crush.Enable = 'on';
            % close waitbar
            close(f);
            
            catch
                close all
                msgbox('An error occured', 'error', 'error');
                return
            end
        end

        % Cell edit callback: Table_EMod_Crush
        function Table_EMod_CrushCellEdit(app, event)
             % this functin checks if user input in the E Modulus Table for the crush test is correct
             %========================
             data = app.Table_EMod_Crush.Data;
             app.E_input_correct = 1;
             if isempty(data{1,2})
                 data{1,2} = '[]';
                 app.Table_EMod_Crush.Data = data;
             end
             if ~strcmp(data{1,2},'[]')
                 EModul = str2double(data{1,2});
                 if isnan(EModul) % correct input
                     app.E_input_correct = 0;
                     msgbox('The data must either be numeric or "[]"', 'Error','error');
                 end
             end
        end

        % Button pushed function: calculateyieldcurve_crush
        function calculateyieldcurve_crushButtonPushed(app, event)
            % this function calculates the yield curve and Rp Value
            % required: E Modulus calculated
            %=====================================
            % is the input in the tables correct?
            correct = 0;
            if app.E_input_correct == 1 % correct data in table
                correct = 1; 
            else
                msgbox('The data in the table for the E-Modulus has the wrong format.', 'Error','error');
            end
            % do calculation
            if correct == 1
                % waitbar
                controller = 1;
                Message = 'calculating...';
                f = javawaitbar(app,controller,Message);
                
                try
                    
                % yield curve
                % =============
                % experimental or literature value
                tdata = app.Table_EMod_Crush.Data;
                if strcmp(tdata(1,2),'[]') % no input has been made => experimental value
                    ELit = [];
                else % use the literature value
                    ELit = str2double(tdata(1,2));
                end
                % check if pronounced yield strengh has been selected
                if app.pronouncedyieldstrenghCheckBox_crush.Value == 1 %yes
                    index = app.indexPronouncedYieldStrength;
                    app.CT_Database = yieldCurve_app(app.CT_Database, 'pronouncedYieldStrength', true, 'ELit', ELit, 'indexPronouncedYieldStrength', index);
                    if isempty(app.CT_Database)
                        return;
                    end 
                else % no pronounced yield strengh
                    %use rp value from edit field
                    Rp_value = app.epsilonRpEditField_crush.Value;
                    app.CT_Database = yieldCurve_app(app.CT_Database, 'epsilonLEngRp', Rp_value, 'ELit', ELit);
                    if isempty(app.CT_Database)
                        return;
                    end
                end 
                rowsNue = checkLabels(app.CT_Database, 'nue');
                app.CT_Database{rowsNue(1),2} = app.Poissonsnumber.Value;
                close(f);
                % lamp
                app.calc_yield_crush.Color = 'green';
                % enable buttons
                app.ExportAbaqusinpButton.Enable = 'on';
                
                catch
                    close all
                    msgbox('An error occured', 'error', 'error');
                    return
                end
                
            end % end if correct
        end

        % Button pushed function: helpButton_crush
        function helpButton_crushPushed(app, event)
            % shows help
            if strcmp(app.TextEModulus_2.Visible,'on')
                app.TextEModulus_2.Visible = 'off';
            else
                app.TextEModulus_2.Visible = 'on';
            end
        end

        % Value changed function: pronouncedyieldstrenghCheckBox_crush
        function pronouncedyieldstrenghCheckBox_crushValueChanged(app, event)
            % if the user clicks on the field to with pronounced yield strengh, this function
            % shows the hidden button needed for selection
            %================================================================
            value = app.pronouncedyieldstrenghCheckBox_crush.Value;
            if value == 1
                app.select_region_yield_crush.Visible = 'on';
                app.selection_status_crush.Visible = 'on';
                app.epsilonRpEditField_crush.Visible = 'off';
                app.RpValueLabel_crush.Visible = 'off';
            else
                app.select_region_yield_crush.Visible = 'off';
                app.selection_status_crush.Visible = 'off';
                app.epsilonRpEditField_crush.Visible = 'on';
                app.RpValueLabel_crush.Visible = 'on';
            end
        end

        % Value changed function: selectdatatoplotDropDown
        function selectdatatoplotDropDownValueChanged(app, event)
            % this function is called when the user selects the data to be plottet from the 
            % dropdown menu in the visulization tab. it checkes if the data can be plottet
            % and does so if possible
            %======================================
            % which direction is to be plotted?
            dd_value = app.DirectionDropDown_3.Value;
            test = app.PerformedtestDropDown.Value;
            try
                if strcmp(test,'tensile/bulge')
                    degree = strsplit(dd_value,'-');
                    degree = degree{1,1};
                    i_degree = find([app.TT_Database{:,1}] == str2double(degree));
                    databaseTT = app.TT_Database{i_degree,2}; % get database
                    % has a bulge extrapolation been used for the direction
                    bulge = 0;
                    if app.TT_filepath{i_degree,4} % bulge for selected direction
                        bulge = 1;
                    end
                    % call plot function
                    plot_tesnile_test_data(app,databaseTT,bulge);
                elseif strcmp(test, 'crush')
                    % call plot function
                    plot_crush_test(app,app.CT_Database);
                end
            catch
                msgbox('Not enough data to plot the desired graph', 'Info', 'help');
            end
        end

        % Button pushed function: createfigureButton
        function createfigureButtonPushed(app, event)
        % this function creates the figure with all the possible plots (postprocessing)
        % required: calculation finished
        %======================================
        test = app.PerformedtestDropDown.Value;
        try
            if strcmp(test,'tensile/bulge')
                % which direction is to be plotted?
                dd_value = app.DirectionDropDown_3.Value;
                degree = strsplit(dd_value,'-');
                degree = degree{1,1};
                i_degree = find([app.TT_Database{:,1}] == str2double(degree));
                databaseTT = app.TT_Database{i_degree,2};
                % bulge extrapolation?
                qdlgBulge0 = 'No';
                if app.TT_filepath{i_degree,4} == 1
                    qdlgBulge0 = 'Yes';
                end
                % call plot funciton
                plotTensileTest(databaseTT, qdlgBulge0, dd_value);
            elseif strcmp(test, 'crush')
                % call plot funciton
                plotCrushTest(app.CT_Database, 'crush test')
            end
        catch
            msgbox('Not enough data to plot the desired graph', 'Info', 'help');
        end
        end

        % Button pushed function: visualizeresultsButton
        function visualizeresultsButtonPushed(app, event)
            % changes the tab and then plots the results (similar to: "selectdatatoplotDropDownValueChanged(app, event)" )
            % ===========================
            % changes active tab
            app.TabGroup.SelectedTab = app.VisualisationTab;
            try
                % which direction is to be plotted?
                dd_value = app.DirectionDropDown_3.Value;
                test = app.PerformedtestDropDown.Value;
                if strcmp(test,'tensile/bulge')
                    degree = strsplit(dd_value,'-');
                    degree = degree{1,1};
                    i_degree = find([app.TT_Database{:,1}] == str2double(degree));
                    databaseTT = app.TT_Database{i_degree,2};
                    % bulge for selected direction
                    bulge = 0;
                    if app.TT_filepath{i_degree,4} % bulge yes
                        bulge = 1;
                    end
                    plot_tesnile_test_data(app,databaseTT,bulge);
                elseif strcmp(test, 'crush')
                    plot_crush_test(app,app.CT_Database);
                end
            catch
                msgbox('Not enough data to plot the desired graph', 'Info', 'help');
            end
        end

        % Value changed function: DirectionDropDown_3
        function DirectionDropDown_3ValueChanged(app, event)
            % plots the results when  dropdown is changed (similar to: "selectdatatoplotDropDownValueChanged(app, event)" )
            % ============================
            % which direction is to be plotted?
            dd_value = app.DirectionDropDown_3.Value;
            test = app.PerformedtestDropDown.Value;
            try
                if strcmp(test,'tensile/bulge')
                    degree = strsplit(dd_value,'-');
                    degree = degree{1,1};
                    i_degree = find([app.TT_Database{:,1}] == str2double(degree));
                    databaseTT = app.TT_Database{i_degree,2};
                    % bulge for selected direction
                    bulge = 0;
                    if app.TT_filepath{i_degree,4} % bulge for selected direction
                        bulge = 1;
                    end
                    plot_tesnile_test_data(app,databaseTT,bulge);
                elseif strcmp(test, 'crush')
                    plot_crush_test(app,app.CT_Database);
                end
            catch
                msgbox('Not enough data to plot the desired graph', 'Info', 'help');
            end
        end

        % Button pushed function: returnButton
        function returnButtonPushed(app, event)
            % change tab
            app.TabGroup.SelectedTab = app.mainTab;
        end

        % Value changed function: DirectionDropDown_2
        function DirectionDropDown_2ValueChanged(app, event)
            if app.exportextrapolationapproachButton.Value == 1
                
            elseif app.bulgeextrapolaitionapproachButton.Value == 1
                app.firstselectionDropDownValueChanged;
            end
        end

        % Value changed function: displayButton
        function displayButtonValueChanged(app, event)
            % displays the selected E Modulus Indices
            %=========================
            value = app.displayButton.Value;
            if value == 1
                app.plot_EMod_Index;
            else
                app.plot_TT;
            end
        end

        % Value changed function: DropDown_emodulus
        function DropDown_emodulusValueChanged(app, event)
            % displays the selected E Modulus Indices
            %=========================
            value = app.displayButton.Value;
            if value == 1
                app.plot_EMod_Index;
            else
                app.plot_TT;
            end
        end

        % Value changed function: displayButton_2
        function displayButton_2ValueChanged(app, event)
            % displays the selected E Modulus Indices
            %=========================
            value = app.displayButton_2.Value;
            if value == 1
                app.plot_EMod_Index;
            else
                app.plot_CT;
            end
        end

        % Button pushed function: resetdataButton
        function resetdataButtonPushed(app, event)
            % this function resets all the indices to the value directly after loading the data.
            % Required: data loaded
            %=====================
            value = app.PerformedtestDropDown.Value;
            if strcmp(value,'tensile/bulge')
                % tensile test
                % loop for all directions of the tensile test
                for i_direction = 1 : size(app.TT_Database,1)
                   databaseTT = app.TT_Database{i_direction,2};
                   rows = checkLabels(databaseTT, 'lengthTensileTestOriginal', 'forceTensileTestOriginal', 'widthTensileTestOriginal', 'timeTensileTestOriginal', ...
                                                  'lengthTensileTestEdit', 'forceTensileTestEdit', 'widthTensileTestEdit', 'timeTensileTestEdit', ...
                                                  'lengthTensileTest', 'forceTensileTest', 'widthTensileTest', 'timeTensileTest');
                   % reset data
                   databaseTT(rows(5),2:end) = databaseTT(rows(1),2:end);
                   databaseTT(rows(6),2:end) = databaseTT(rows(2),2:end);
                   databaseTT(rows(7),2:end) = databaseTT(rows(3),2:end);
                   databaseTT(rows(8),2:end) = databaseTT(rows(4),2:end);
                   databaseTT(rows(9),2:end) = databaseTT(rows(1),2:end);
                   databaseTT(rows(10),2:end) = databaseTT(rows(2),2:end);
                   databaseTT(rows(11),2:end) = databaseTT(rows(3),2:end);
                   databaseTT(rows(12),2:end) = databaseTT(rows(4),2:end);
                   % save resetet database
                   app.TT_Database{i_direction,2} = databaseTT;
                end
                
                % bulge test
                if any(cellfun(@(x)isequal(x,1),app.TT_filepath(:,4))) % if for one direction the bulge extrapolation has been selected
                    rows = checkLabels(app.BT_Database, 'epsilonTruePlBulgeOriginal', 'sigmaTruePlBulgeOriginal', 'timeBulgeOriginal', ...
                                                  'epsilonTruePlBulgeEdit', 'sigmaTruePlBulgeEdit', 'timeBulgeEdit', ...
                                                  'epsilonTruePlBulge', 'sigmaTruePlBulge', 'timeBulge');
                    % reset data
                    app.BT_Database(rows(4),2:end) = app.BT_Database(rows(1),2:end);
                    app.BT_Database(rows(5),2:end) = app.BT_Database(rows(2),2:end);
                    app.BT_Database(rows(6),2:end) = app.BT_Database(rows(3),2:end);
                    app.BT_Database(rows(7),2:end) = app.BT_Database(rows(1),2:end);
                    app.BT_Database(rows(8),2:end) = app.BT_Database(rows(2),2:end);
                    app.BT_Database(rows(9),2:end) = app.BT_Database(rows(3),2:end);
                end
                % reaverange and replot the data
                app.reaveragedataButtonPushed;
            % crush test
            elseif strcmp(value,'crush')
                rows = checkLabels(app.CT_Database, 'lengthCrushTestOriginal', 'forceCrushTestOriginal', 'timeCrushTestOriginal', ...
                                   'lengthCrushTestEdit', 'forceCrushTestEdit', 'timeCrushTestEdit', ...
                                   'lengthCrushTest', 'forceCrushTest', 'timeCrushTest');
                % reset data
                app.CT_Database(rows(4),2:end) = app.CT_Database(rows(1),2:end);
                app.CT_Database(rows(5),2:end) = app.CT_Database(rows(2),2:end);
                app.CT_Database(rows(6),2:end) = app.CT_Database(rows(3),2:end);
                app.CT_Database(rows(7),2:end) = app.CT_Database(rows(1),2:end);
                app.CT_Database(rows(8),2:end) = app.CT_Database(rows(2),2:end);
                app.CT_Database(rows(9),2:end) = app.CT_Database(rows(3),2:end);
                
                % stiffness correlation
                app.stiffness_corr;
                
                % reaverange and replot the data
                app.replotreaveragedataButton_crushPushed;
            end
            % reset E Modulus Indices
            app.eModulusIndexTT{size(app.TT_Database,1),2} = [];   
            app.eModulusIndexCT = []; 
        end

        % Button pushed function: importindicesButton
        function importindicesButtonPushed(app, event)
            % this function loads in previous saved indices for the base or averaged data
            % as well the indices for the E-Modulus calculation are loaded
            % Required: none
            %=======================================
            % waitbar
            controller = 1;
            Message = 'loading data...';
            f = javawaitbar(app,controller,Message);
            uiopen; % loads the mat file exportdata
            try
                % get the indices from the loaded Variable "Indices"
                value = app.PerformedtestDropDown.Value;
                % get position
                rows =  checkLabels(Indices,'eModulusIndexTT','eModulusIndexCT','averagedindex','baseindex');
                app.eModulusIndexTT = Indices{rows(1),2};
                app.eModulusIndexCT = Indices{rows(2),2};
                app.averagedindex = Indices{rows(3),2};
                app.baseindex = Indices{rows(4),2};
            catch
                msgbox('Loaded data has the wrong format', 'error','error');
                close(f);
                return
            end
            indexkind = app.DropDown_index.Value;
            %% tensile test
            % ==========================================
            if strcmp(value,'tensile/bulge')
                try
                    % fix data: ajusts the data to the new indices
                    rowstt = checkLabels(app.TT_Database{1,2}, 'lengthTensileTestOriginal', 'forceTensileTestOriginal', 'widthTensileTestOriginal', 'timeTensileTestOriginal', ...
                                               'lengthTensileTestEdit', 'forceTensileTestEdit', 'widthTensileTestEdit', 'timeTensileTestEdit', ...
                                               'lengthTensileTest', 'forceTensileTest', 'widthTensileTest', 'timeTensileTest');
                                               
                    rowsbulge = checkLabels(app.BT_Database,'epsilonTruePlBulgeOriginal', 'sigmaTruePlBulgeOriginal', 'timeBulgeOriginal', ...
                                                'epsilonTruePlBulgeEdit', 'sigmaTruePlBulgeEdit', 'timeBulgeEdit', ...
                                                'epsilonTruePlBulge', 'sigmaTruePlBulge', 'timeBulge');
                    % loop for every direction of the tensile test
                    for i_direction = 1:size(app.TT_Database,1)
                        direction = app.TT_Database{i_direction,1};
                        databaseTT = app.TT_Database{i_direction,2};
                        if strcmp(indexkind,'averaged index')
                            % load the indices according to the direction
                            index = app.averagedindex(cellfun(@(x)isequal(x,direction),app.averagedindex(:,1)),2);
                            databaseTT{rowstt(9),2} = databaseTT{rowstt(9),2}(index); % Shorten all time dependent data 
                            databaseTT{rowstt(10),2} = databaseTT{rowstt(10),2}(index);
                            databaseTT{rowstt(11),2} = databaseTT{rowstt(11),2}(index);
                            databaseTT{rowstt(12),2} = databaseTT{rowstt(12),2}(index);
                        elseif strcmp(indexkind,'base index')
                            h = app.TT_filepath{i_direction,2};
                            n = size(h,2)+1;
                            clear h;
                            % load the indices according to the direction
                            indices_dir = app.baseindex{cellfun(@(x)isequal(x,direction),app.baseindex(:,1)),2};
                            for jj = 2:n % All columns without the first column that contains the labels
                                index = indices_dir{jj-1,1};
                                databaseTT{rowstt(5),jj} = databaseTT{rowstt(1),jj}(index); % Shorten all time dependent data 
                                databaseTT{rowstt(6),jj} = databaseTT{rowstt(2),jj}(index);
                                databaseTT{rowstt(7),jj} = databaseTT{rowstt(3),jj}(index);
                                databaseTT{rowstt(8),jj} = databaseTT{rowstt(4),jj}(index);
                            end
                        end
                        app.TT_Database{i_direction,2} = databaseTT;
                     end
                catch
                    close(f);
                    msgbox('The loaded data is not compatible with the tensile test', 'error', 'error');
                    return
                end
                % bulge test
                if any(cellfun(@(x)isequal(x,1),app.TT_filepath(:,4))) % if for one direction the bulge extrapolation has been selected
                    try
                        if strcmp(indexkind,'averaged index')
                            % get indices
                            index = app.averagedindex{cellfun(@(x)isequal(x,'bulge'),app.averagedindex(:,1)),2};
                            app.BT_Database{rowsbulge(7),2} = app.BT_Database{rowsbulge(7),2}(index); % Shorten all time dependent data 
                            app.BT_Database{rowsbulge(8),2} = app.BT_Database{rowsbulge(8),2}(index);
                            app.BT_Database{rowsbulge(9),2} = app.BT_Database{rowsbulge(9),2}(index);
                        elseif strcmp(indexkind,'base index')  
                            % get indices
                            indices_dir = app.baseindex{cellfun(@(x)isequal(x,'bulge'),app.baseindex(:,1)),2};
                            h = size(app.BT_Database);
                            n = h(2);
                            clear h;
                            indexbulge = cell(n-1,1);
                            for jj = 2:n % All columns without the first column that contains the labels
                                index = indices_dir{jj-1,1};
                                % Shorten all time dependent data 
                                app.BT_Database{rowsbulge(4),jj} = app.BT_Database{rowsbulge(1),jj}(index); % Shorten all time dependent data 
                                app.BT_Database{rowsbulge(5),jj} = app.BT_Database{rowsbulge(2),jj}(index);
                                app.BT_Database{rowsbulge(6),jj} = app.BT_Database{rowsbulge(3),jj}(index);
                                indexbulge{jj-1} = index;
                            end
                        end
                    catch
                        % close waitbar
                        close(f);
                        msgbox('The loaded data is not compatible with the bulge test', 'error', 'error');
                        return
                    end
                end
                close(f);
                if strcmp(indexkind,'base index') 
                    % reaverage data
                    app.reaveragedataButtonPushed;
                elseif strcmp(indexkind,'averaged index')
                    app.plot_TT
                end
            %% crush test
            % ======================================
            elseif strcmp(value,'crush')
                try
                    rows = checkLabels(app.CT_Database, 'lengthCrushTest', 'forceCrushTest', 'timeCrushTest', ...
                                            'fnCrushTest','lengthCrushTestEdit','forceCrushTestEdit','timeCrushTestEdit');
                    if strcmp(indexkind,'averaged index')
                        index = app.averagedindex;
                        app.CT_Database{rows(1),2} = app.CT_Database{rows(1),2}(index); % Shorten all time dependent data
                        app.CT_Database{rows(2),2} = app.CT_Database{rows(2),2}(index);
                        app.CT_Database{rows(3),2} = app.CT_Database{rows(3),2}(index);
                        
                        app.plot_CT;
                    elseif strcmp(indexkind,'base index')
                        fn = app.CT_Database{rows(4),2};
                        h = size(fn);
                        n = h(2);
                        clear h;
                        for jj = 2:n+1 % All columns without the first column that contains the labels
                            index = app.baseindex{jj-1,1};
                            app.CT_Database{rows(5),jj} = app.CT_Database{rows(5),jj}(index); % Shorten all time dependent data
                            app.CT_Database{rows(6),jj} = app.CT_Database{rows(6),jj}(index);
                            app.CT_Database{rows(7),jj} = app.CT_Database{rows(7),jj}(index);
                        end
                        app.replotreaveragedataButton_crushPushed;
                    end
                catch
                    close(f);
                    msgbox('The loaded data is not compatible with the cruch test', 'error', 'error');
                    return
                end
                % Engineering Calculations
                app.CT_Database = calculationsCrushTest(app.CT_Database);
                close(f);
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: saveindicesButton
        function saveindicesButtonPushed(app, event)
            app.exportdata = cell(5,2);
            app.exportdata{1,1} = 'eModulusIndexTT';
            app.exportdata{1,2} = app.eModulusIndexTT;
            app.exportdata{2,1} = 'eModulusIndexCT';
            app.exportdata{2,2} = app.eModulusIndexCT;
            app.exportdata{3,1} = 'indexPronouncedYieldStrength';
            app.exportdata{3,2} = app.indexPronouncedYieldStrength;
            app.exportdata{4,1} = 'averagedindex';
            app.exportdata{4,2} = app.averagedindex;
            app.exportdata{5,1} = 'baseindex';
            app.exportdata{5,2} = app.baseindex;
            Indices = app.exportdata;
            
            uisave('Indices','Exportdata.mat');
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            % chagnes the GUI of the tab "extrapolation approach"
            % required: none
            %========================================
            if app.exportextrapolationapproachButton.Value == 1
                app.SelectedapproachforexportextrapolationLabel.Visible = 'on';
                app.SelectedapproachforbulgeextrapolationLabel.Visible = 'off';
                app.UITable_approach_export.Visible = 'on';
                app.UITable_approach.Visible = 'off';
                app.approachDropDown.Visible = 'on';
                app.firstselectionDropDown.Visible = 'off';
                app.secondselectionDropDown.Visible = 'off';
                app.FirstapproachLabel.Visible = 'off';
                app.SecondapproachLabel.Visible = 'off';
            elseif app.bulgeextrapolaitionapproachButton.Value == 1
                app.SelectedapproachforexportextrapolationLabel.Visible = 'off';
                app.SelectedapproachforbulgeextrapolationLabel.Visible = 'on';
                app.UITable_approach_export.Visible = 'off';
                app.UITable_approach.Visible = 'on';
                app.approachDropDown.Visible = 'off';
                app.firstselectionDropDown.Visible = 'on';
                app.secondselectionDropDown.Visible = 'on';
                app.FirstapproachLabel.Visible = 'on';
                app.SecondapproachLabel.Visible = 'on';
                
                dir = app.DirectionDropDown.Value;
                plot_label = app.UITable_approach.RowName;
                % find the row number
                if ~cellfun(@(x)isequal(x,dir),plot_label)
                    msgbox('no bulge interpolation in this direction','warn','warn');
                    return;
                end
            end
            app.plot_approaches;
        end

        % Button pushed function: helpButton_3
        function helpButton_3Pushed(app, event)
            % shows help
            if strcmp(app.Text_extrapolation.Visible,'on')
                app.Text_extrapolation.Visible = 'off';
            else
                app.Text_extrapolation.Visible = 'on';
            end
        end

        % Value changed function: approachDropDown
        function approachDropDownValueChanged(app, event)
            % funciton is called when the user selects an extrapolation approach from the dropdown menu
            % the extrapolation approach is writen to the table regarding the direction
            %==========================================================
            dir = app.DirectionDropDown_2.Value;
            if app.exportextrapolationapproachButton.Value == 1
                plot_label = app.UITable_approach_export.RowName;
            elseif app.bulgeextrapolaitionapproachButton.Value == 1
                plot_label = app.UITable_approach.RowName;
            end
            % find the row number
            rownumb = find(strcmp(plot_label, dir));
            if isempty(rownumb)
                return;
            end
            % get  data from table
            approachtabledata = app.UITable_approach_export.Data;
            % manipulate table data
            if strcmp(app.approachDropDown.Value,'None') == 1
                approachtabledata{rownumb,1} = '-'; 
            else
                approachtabledata{rownumb,1} = app.approachDropDown.Value; 
            end
            % asign data to table
            app.UITable_approach_export.Data = approachtabledata;
        end

        % Button pushed function: helpButton_4
        function helpButton_4Pushed(app, event)
            % shows help
            if strcmp(app.Text_save.Visible,'on')
                app.Text_save.Visible = 'off';
            else
                app.Text_save.Visible = 'on';
            end
        end

        % Button pushed function: helpButton_labelsTT
        function helpButton_labelsTTPushed(app, event)
            image = imread('Tensile_test_legend.png');
            imshow(image);
        end

        % Button pushed function: helpButton_labelsTT_2
        function helpButton_labelsTT_2Pushed(app, event)
            image = imread('Stiffness_test_legend.png');
            imshow(image);
        end

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
            % this function takes the parameter for the extrapolation approaches and stores them in the 
            % variable "app.expParameter"
            % Required: none
            %===============================================================
            % define variable
            app.expParameter = cell(5,2);
            app.expParameter{1,1} = 'Ludwik';
            app.expParameter{2,1} = 'Gosh';
            app.expParameter{3,1} = 'HocketSherby';
            app.expParameter{4,1} = 'Swift';
            app.expParameter{5,1} = 'Voce';
            % get values from fields
            app.expParameter{1,2} = [app.L1.Value app.L2.Value];
            app.expParameter{2,2} = [app.G1.Value app.G2.Value app.G3.Value];
            app.expParameter{3,2} = [app.HS1.Value app.HS2.Value app.HS3.Value];
            app.expParameter{4,2} = [app.S1.Value app.S2.Value app.S3.Value];
            app.expParameter{5,2} = [app.V1.Value app.V2.Value];
            % lamp
            app.ParameterUpdated.Color = 'green';
        end

        % Value changed function: G1, G2, G3, HS1, HS2, HS3, L1, L2, 
        % S1, S2, S3, V1, V2
        function L1ValueChanged(app, event)
            % changes the color of the lamp to show the user, that the parameters are not updated yet.
            app.ParameterUpdated.Color = 'red';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.mainTab = uitab(app.TabGroup);
            app.mainTab.Title = 'main';
            app.DropDown_density = uidropdown(app.mainTab);
            app.DropDown_density.Items = {'tons/mm³', 'kg/m³', 'kg/cm³', 'g/cm³'};
            app.DropDown_density.Value = 'tons/mm³';
            app.specimen_type = uidropdown(app.mainTab);
            app.specimen_type.Items = {'flat', 'round'};
            app.specimen_type.Value = 'flat';
            app.tensilespecimentypeLabel = uilabel(app.mainTab);
            app.tensilespecimentypeLabel.Text = 'tensile specimen type';
            app.MaterialEditFieldLabel = uilabel(app.mainTab);
            app.MaterialEditFieldLabel.Text = 'Material';
            app.MaterialEditField = uieditfield(app.mainTab, 'text');
            app.DensityEditFieldLabel = uilabel(app.mainTab);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.mainTab, 'numeric');
            app.PerformedtestDropDownLabel = uilabel(app.mainTab);
            app.PerformedtestDropDownLabel.Text = 'Performed test';
            app.PerformedtestDropDown = uidropdown(app.mainTab);
            app.PerformedtestDropDown.Items = {'tensile/bulge', 'crush'};
            app.PerformedtestDropDown.ValueChangedFcn = createCallbackFcn(app, @PerformedtestDropDownValueChanged, true);
            app.PerformedtestDropDown.Value = 'tensile/bulge';
            app.load_status = uilamp(app.mainTab);
            app.DataselectiontensilebulgetestPanel = uipanel(app.mainTab);
            app.DataselectiontensilebulgetestPanel.Title = 'Data selection tensile/bulge test';
            app.Select_TT = uibutton(app.DataselectiontensilebulgetestPanel, 'push');
            app.Select_TT.ButtonPushedFcn = createCallbackFcn(app, @Select_TTButtonPushed, true);
            app.Select_TT.Text = 'Select data file';
            app.DirectiondegreeLabel = uilabel(app.DataselectiontensilebulgetestPanel);
            app.DirectiondegreeLabel.Text = 'Direction [degree]';
            app.DatafilebulgetestLabel = uilabel(app.DataselectiontensilebulgetestPanel);
            app.DatafilebulgetestLabel.Text = 'Data file bulge test';
            app.Select_bulge = uibutton(app.DataselectiontensilebulgetestPanel, 'push');
            app.Select_bulge.ButtonPushedFcn = createCallbackFcn(app, @Select_bulgeButtonPushed, true);
            app.Select_bulge.Text = 'Select';
            app.Deletelast_TT = uibutton(app.DataselectiontensilebulgetestPanel, 'push');
            app.Deletelast_TT.ButtonPushedFcn = createCallbackFcn(app, @Deletelast_TTButtonPushed, true);
            app.Deletelast_TT.Text = 'Delete last added files';
            app.degree_EditField = uieditfield(app.DataselectiontensilebulgetestPanel, 'numeric');
            app.UITable_TT = uitable(app.mainTab);
            app.UITable_TT.ColumnName = {'direction'; 'bulge extrapolation'; 'filename'};
            app.UITable_TT.RowName = {};
            app.UITable_TT.ColumnEditable = [false true false];
            app.UITable_TT.CellEditCallback = createCallbackFcn(app, @UITable_TTCellEdit, true);
            app.DefineBaseVektorfortheaveragingofthemeasurementdataLabel = uilabel(app.mainTab);
            app.DefineBaseVektorfortheaveragingofthemeasurementdataLabel.Text = {'Define Base Vektor for the averaging'; 'of the measurement data (optional)'};
            app.basevectorButton = uibutton(app.mainTab, 'push');
            app.basevectorButton.ButtonPushedFcn = createCallbackFcn(app, @basevectorButtonPushed, true);
            app.basevectorButton.Text = 'base vector';
            app.beginmmLabel = uilabel(app.mainTab);
            app.beginmmLabel.Text = 'begin [mm]';
            app.bv_begin = uieditfield(app.mainTab, 'text');
            app.bv_begin.Editable = 'off';
            app.bv_begin.Value = '[]';
            app.stepsizemmLabel = uilabel(app.mainTab);
            app.stepsizemmLabel.Text = 'step size [mm]';
            app.bv_stepsize = uieditfield(app.mainTab, 'text');
            app.bv_stepsize.Editable = 'off';
            app.bv_stepsize.Value = '[]';
            app.endmmLabel = uilabel(app.mainTab);
            app.endmmLabel.Text = 'end [mm]';
            app.bv_end = uieditfield(app.mainTab, 'text');
            app.bv_end.Editable = 'off';
            app.bv_end.Value = '[]';
            app.loaddataButton = uibutton(app.mainTab, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.manipulatedataButton = uibutton(app.mainTab, 'push');
            app.manipulatedataButton.ButtonPushedFcn = createCallbackFcn(app, @manipulatedataButtonPushed, true);
            app.manipulatedataButton.Text = 'manipulate data';
            app.TextArea2 = uitextarea(app.mainTab);
            app.TextArea2.Editable = 'off';
            app.TextArea2.Value = {'After loading the data, it will be averaged, using the base vector, wich can be specified above. If the later one has not been specified it will be calculated automatically.'; 'After loading and averaging the data you can manipulate either the averaged data, or the basic data files. Moreover you can choose the data wich will be used for the calculation of the E-modulus.'};
            app.ExportAbaqusinpButton = uibutton(app.mainTab, 'push');
            app.ExportAbaqusinpButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAbaqusinpButtonPushed, true);
            app.ExportAbaqusinpButton.Enable = 'off';
            app.ExportAbaqusinpButton.Text = 'Export Abaqus (-.inp)';
            app.UITable_crush = uitable(app.mainTab);
            app.UITable_crush.ColumnName = {'test'; 'filename'};
            app.UITable_crush.RowName = {};
            app.DropDown3 = uidropdown(app.mainTab);
            app.DropDown3.Items = {'select material', 'Stahl', 'Aluminium'};
            app.DropDown3.ValueChangedFcn = createCallbackFcn(app, @DropDown3ValueChanged, true);
            app.DropDown3.Value = 'select material';
            app.export_status = uilamp(app.mainTab);
            app.helpButton_2 = uibutton(app.mainTab, 'push');
            app.helpButton_2.ButtonPushedFcn = createCallbackFcn(app, @helpButton_2Pushed, true);
            app.helpButton_2.Text = 'help';
            app.Panel_TT = uipanel(app.mainTab);
            app.pronouncedyieldstrenghCheckBox = uicheckbox(app.Panel_TT);
            app.pronouncedyieldstrenghCheckBox.ValueChangedFcn = createCallbackFcn(app, @pronouncedyieldstrenghCheckBoxValueChanged, true);
            app.pronouncedyieldstrenghCheckBox.Text = 'pronounced yield strengh';
            app.calculateEModulusRmvalueButton = uibutton(app.Panel_TT, 'push');
            app.calculateEModulusRmvalueButton.ButtonPushedFcn = createCallbackFcn(app, @calculateEModulusRmvalueButtonPushed, true);
            app.calculateEModulusRmvalueButton.Enable = 'off';
            app.calculateEModulusRmvalueButton.Text = 'calculate: E-Modulus, Rm value';
            app.calc_status = uilamp(app.Panel_TT);
            app.select_region_yield = uibutton(app.Panel_TT, 'push');
            app.select_region_yield.ButtonPushedFcn = createCallbackFcn(app, @select_region_yieldButtonPushed, true);
            app.select_region_yield.Text = 'select region';
            app.selection_status = uilamp(app.Panel_TT);
            app.UITable_E_Rm = uitable(app.Panel_TT);
            app.UITable_E_Rm.ColumnName = {'direction'; 'E-Modulus'; 'E-Modulus-Literature'; 'Rm Value'};
            app.UITable_E_Rm.RowName = {};
            app.UITable_E_Rm.ColumnEditable = [false false true false];
            app.UITable_E_Rm.CellEditCallback = createCallbackFcn(app, @UITable_E_RmCellEdit, true);
            app.calculateyieldcurvervaluenvalueButton = uibutton(app.Panel_TT, 'push');
            app.calculateyieldcurvervaluenvalueButton.ButtonPushedFcn = createCallbackFcn(app, @calculateyieldcurvervaluenvalueButtonPushed, true);
            app.calculateyieldcurvervaluenvalueButton.Enable = 'off';
            app.calculateyieldcurvervaluenvalueButton.Text = 'calculate: yield curve, r-value, n-value';
            app.select_rangeforrvaluecalculationButton = uibutton(app.Panel_TT, 'push');
            app.select_rangeforrvaluecalculationButton.ButtonPushedFcn = createCallbackFcn(app, @select_rangeforrvaluecalculationButtonPushed, true);
            app.select_rangeforrvaluecalculationButton.Text = 'select_range for r-value calculation';
            app.calc_yield_status = uilamp(app.Panel_TT);
            app.epsilonRpEditField = uieditfield(app.Panel_TT, 'numeric');
            app.epsilonRpEditField.Value = 0.002;
            app.RpValueLabel = uilabel(app.Panel_TT);
            app.RpValueLabel.Text = 'Rp-Value';
            app.recalculatenvalueButton = uibutton(app.Panel_TT, 'push');
            app.recalculatenvalueButton.ButtonPushedFcn = createCallbackFcn(app, @recalculatenvalueButtonPushed, true);
            app.recalculatenvalueButton.Enable = 'off';
            app.recalculatenvalueButton.Text = 'recalculate n-value';
            app.select_rangefornvaluecalculationButton = uibutton(app.Panel_TT, 'push');
            app.select_rangefornvaluecalculationButton.ButtonPushedFcn = createCallbackFcn(app, @select_rangefornvaluecalculationButtonPushed, true);
            app.select_rangefornvaluecalculationButton.Text = 'select_range for n-value calculation';
            app.UITable_rn_value = uitable(app.Panel_TT);
            app.UITable_rn_value.ColumnName = {'direction'; 'r-value'; 'n-value'};
            app.UITable_rn_value.RowName = {};
            app.calc_n_value = uilamp(app.Panel_TT);
            app.extrapolationButton = uibutton(app.Panel_TT, 'push');
            app.extrapolationButton.ButtonPushedFcn = createCallbackFcn(app, @extrapolationButtonPushed, true);
            app.extrapolationButton.Text = 'extrapolation';
            app.selectextrapolationapproachButton = uibutton(app.Panel_TT, 'push');
            app.selectextrapolationapproachButton.ButtonPushedFcn = createCallbackFcn(app, @selectextrapolationapproachButtonPushed, true);
            app.selectextrapolationapproachButton.Text = 'select extrapolation approach';
            app.TextEModulus = uitextarea(app.Panel_TT);
            app.TextEModulus.Editable = 'off';
            app.TextEModulus.Value = {'If the calculated E-Modulus is not similar to literature vaules e.g. due to bad experimental equipment, you can provide an E-Modulus here. This is used to calculate the theoretical point, where your yield curve should start if your test equipment was perfect. Later on this can be used to correct the epsilon values of the yield curve. This makes sense only when your provided E-Modulus is larger than the calculated one. '; ''; 'You can provide the E-Modulus by typing it''s value in the column for the literature E-Modulus. If you don''t specify a value the experimental E-Modulus will be used for the further calculation.'};
            app.bulge_extrapolation = uilamp(app.Panel_TT);
            app.helpButton_3 = uibutton(app.Panel_TT, 'push');
            app.helpButton_3.ButtonPushedFcn = createCallbackFcn(app, @helpButton_3Pushed, true);
            app.helpButton_3.Text = 'help';
            app.Text_extrapolation = uitextarea(app.Panel_TT);
            app.Text_extrapolation.Editable = 'off';
            app.Text_extrapolation.Value = {'Lets you select the extrapolation approaches for the data export   or the bulge extrapolation, if requested.'};
            app.InsertEModulushereLabel = uilabel(app.Panel_TT);
            app.InsertEModulushereLabel.Text = 'Insert E-Modulus here';
            app.helpButton = uibutton(app.Panel_TT, 'push');
            app.helpButton.ButtonPushedFcn = createCallbackFcn(app, @helpButtonPushed, true);
            app.helpButton.Text = 'help';
            app.PoissonsnumberEditField_2Label = uilabel(app.mainTab);
            app.PoissonsnumberEditField_2Label.Text = 'Poissons number';
            app.Poissonsnumber = uieditfield(app.mainTab, 'numeric');
            app.Poissonsnumber.Value = 0.3;
            app.visualizeresultsButton = uibutton(app.mainTab, 'push');
            app.visualizeresultsButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeresultsButtonPushed, true);
            app.visualizeresultsButton.Text = 'visualize results';
            app.SavedatabaseButton = uibutton(app.mainTab, 'push');
            app.SavedatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SavedatabaseButton.Text = 'Save database';
            app.helpButton_4 = uibutton(app.mainTab, 'push');
            app.helpButton_4.ButtonPushedFcn = createCallbackFcn(app, @helpButton_4Pushed, true);
            app.helpButton_4.Text = 'help';
            app.Text_save = uitextarea(app.mainTab);
            app.Text_save.Editable = 'off';
            app.Text_save.Value = {'Lets you save all the data used by the app, for further analysis.'; 'The data is saved in the way the app uses it.'};
            app.DataselectioncrushtestPanel = uipanel(app.mainTab);
            app.DataselectioncrushtestPanel.Title = 'Data selection crush test';
            app.select_crush = uibutton(app.DataselectioncrushtestPanel, 'push');
            app.select_crush.ButtonPushedFcn = createCallbackFcn(app, @select_crushButtonPushed, true);
            app.select_crush.Text = 'Select';
            app.DatafilecrushtestLabel = uilabel(app.DataselectioncrushtestPanel);
            app.DatafilecrushtestLabel.Text = 'Datafile crush test';
            app.Stiffnes_needed = uicheckbox(app.DataselectioncrushtestPanel);
            app.Stiffnes_needed.ValueChangedFcn = createCallbackFcn(app, @Stiffnes_neededValueChanged, true);
            app.Stiffnes_needed.Text = '';
            app.StiffnescorrelationneededLabel = uilabel(app.DataselectioncrushtestPanel);
            app.StiffnescorrelationneededLabel.Text = 'Stiffnes correlation needed';
            app.select_stiffness = uibutton(app.DataselectioncrushtestPanel, 'push');
            app.select_stiffness.ButtonPushedFcn = createCallbackFcn(app, @select_stiffnessButtonPushed, true);
            app.select_stiffness.Text = 'Select';
            app.DatafilestiffnesstestLabel = uilabel(app.DataselectioncrushtestPanel);
            app.DatafilestiffnesstestLabel.Text = 'Datafile stiffness test';
            app.Panel_CT = uipanel(app.mainTab);
            app.pronouncedyieldstrenghCheckBox_crush = uicheckbox(app.Panel_CT);
            app.pronouncedyieldstrenghCheckBox_crush.ValueChangedFcn = createCallbackFcn(app, @pronouncedyieldstrenghCheckBox_crushValueChanged, true);
            app.pronouncedyieldstrenghCheckBox_crush.Text = 'pronounced yield strengh';
            app.select_region_yield_crush = uibutton(app.Panel_CT, 'push');
            app.select_region_yield_crush.ButtonPushedFcn = createCallbackFcn(app, @select_region_yield_crushButtonPushed, true);
            app.select_region_yield_crush.Text = 'select region';
            app.selection_status_crush = uilamp(app.Panel_CT);
            app.epsilonRpEditField_crush = uieditfield(app.Panel_CT, 'numeric');
            app.epsilonRpEditField_crush.Value = 0.002;
            app.RpValueLabel_crush = uilabel(app.Panel_CT);
            app.RpValueLabel_crush.Text = 'Rp-Value';
            app.calculation_crush = uibutton(app.Panel_CT, 'push');
            app.calculation_crush.ButtonPushedFcn = createCallbackFcn(app, @calculation_crushPushed, true);
            app.calculation_crush.Enable = 'off';
            app.calculation_crush.Text = 'calculate: E-Modulus';
            app.calculateyieldcurve_crush = uibutton(app.Panel_CT, 'push');
            app.calculateyieldcurve_crush.ButtonPushedFcn = createCallbackFcn(app, @calculateyieldcurve_crushButtonPushed, true);
            app.calculateyieldcurve_crush.Enable = 'off';
            app.calculateyieldcurve_crush.Text = 'calculate: yield curve';
            app.Table_EMod_Crush = uitable(app.Panel_CT);
            app.Table_EMod_Crush.ColumnName = {'E-Modulus'; 'E-Modulus-Literature'};
            app.Table_EMod_Crush.RowName = {};
            app.Table_EMod_Crush.ColumnEditable = [false true];
            app.Table_EMod_Crush.CellEditCallback = createCallbackFcn(app, @Table_EMod_CrushCellEdit, true);
            app.helpButton_crush = uibutton(app.Panel_CT, 'push');
            app.helpButton_crush.ButtonPushedFcn = createCallbackFcn(app, @helpButton_crushPushed, true);
            app.helpButton_crush.Text = 'help';
            app.calc_yield_crush = uilamp(app.Panel_CT);
            app.calc_EMod_crush = uilamp(app.Panel_CT);
            app.InsertEModulushereLabel_2 = uilabel(app.Panel_CT);
            app.InsertEModulushereLabel_2.Text = 'Insert E-Modulus here';
            app.TextEModulus_2 = uitextarea(app.Panel_CT);
            app.TextEModulus_2.Editable = 'off';
            app.TextEModulus_2.Value = {'If the calculated E-Modulus is not similar to literature vaules e.g. due to bad experimental equipment, you can provide an E-Modulus here. This is used to calculate the theoretical point, where your yield curve should start if your test equipment was perfect. Later on this can be used to correct the epsilon values of the yield curve. This makes sense only when your provided E-Modulus is larger than the calculated one. '; ''; 'You can provide the E-Modulus by typing it''s value in the column for the literature E-Modulus. If you don''t specify a value the experimental E-Modulus will be used for the further calculation.'};
            app.TabGroup2 = uitabgroup(app.mainTab);
            app.labelstensiletestTab = uitab(app.TabGroup2);
            app.labelstensiletestTab.Title = 'labels tensile test';
            app.displacementLabelEditFieldLabel = uilabel(app.labelstensiletestTab);
            app.displacementLabelEditFieldLabel.Text = 'displacementLabel';
            app.displacementLabelEditField = uieditfield(app.labelstensiletestTab, 'text');
            app.displacementLabelEditField.Value = 'Standard travel';
            app.forceLabelEditFieldLabel = uilabel(app.labelstensiletestTab);
            app.forceLabelEditFieldLabel.Text = 'forceLabel';
            app.forceLabelEditField = uieditfield(app.labelstensiletestTab, 'text');
            app.forceLabelEditField.Value = 'Standard force';
            app.timeLabelEditFieldLabel = uilabel(app.labelstensiletestTab);
            app.timeLabelEditFieldLabel.Text = 'timeLabel';
            app.timeLabelEditField = uieditfield(app.labelstensiletestTab, 'text');
            app.timeLabelEditField.Value = 'Test time';
            app.widthLabel = uilabel(app.labelstensiletestTab);
            app.widthLabel.Text = 'widthLabel';
            app.widthLabelEditField = uieditfield(app.labelstensiletestTab, 'text');
            app.widthLabelEditField.Value = 'Transverse strain';
            app.thicknessInitialLabelEditField_2Label = uilabel(app.labelstensiletestTab);
            app.thicknessInitialLabelEditField_2Label.Text = 'thicknessInitialLabel';
            app.thicknessInitialLabelEditField = uieditfield(app.labelstensiletestTab, 'text');
            app.thicknessInitialLabelEditField.Value = 'Specimen thickness';
            app.lengthInitialLabelEditField_2Label = uilabel(app.labelstensiletestTab);
            app.lengthInitialLabelEditField_2Label.Text = 'lengthInitialLabel';
            app.lengthInitialLabelEditField = uieditfield(app.labelstensiletestTab, 'text');
            app.lengthInitialLabelEditField.Value = 'Marked initial gage length';
            app.widthInitialLabelEditField_2Label = uilabel(app.labelstensiletestTab);
            app.widthInitialLabelEditField_2Label.Text = 'widthInitialLabel';
            app.widthInitialLabelEditField = uieditfield(app.labelstensiletestTab, 'text');
            app.widthInitialLabelEditField.Value = 'Specimen width';
            app.helpButton_labelsTT = uibutton(app.labelstensiletestTab, 'push');
            app.helpButton_labelsTT.ButtonPushedFcn = createCallbackFcn(app, @helpButton_labelsTTPushed, true);
            app.helpButton_labelsTT.Text = 'help';
            app.labelsbulgetestTab = uitab(app.TabGroup2);
            app.labelsbulgetestTab.Title = 'labels bulge test';
            app.trueStressLabelEditFieldLabel = uilabel(app.labelsbulgetestTab);
            app.trueStressLabelEditFieldLabel.Text = 'trueStressLabel';
            app.trueStressLabelEditField = uieditfield(app.labelsbulgetestTab, 'text');
            app.trueStressLabelEditField.Value = 'True Stress (YC1)';
            app.trueStrainLabelEditFieldLabel = uilabel(app.labelsbulgetestTab);
            app.trueStrainLabelEditFieldLabel.Text = 'trueStrainLabel';
            app.trueStrainLabelEditField = uieditfield(app.labelsbulgetestTab, 'text');
            app.trueStrainLabelEditField.Value = 'True Strain (YC1)';
            app.timeLabelEditField_2Label = uilabel(app.labelsbulgetestTab);
            app.timeLabelEditField_2Label.Text = 'timeLabel';
            app.timeLabelEditField_bulge = uieditfield(app.labelsbulgetestTab, 'text');
            app.timeLabelEditField_bulge.Value = 'Time';
            app.labelscrushtestandstiffnesscorrelationTab = uitab(app.TabGroup2);
            app.labelscrushtestandstiffnesscorrelationTab.Title = 'labels crush test and stiffness correlation';
            app.displacementLabelEditField_2Label = uilabel(app.labelscrushtestandstiffnesscorrelationTab);
            app.displacementLabelEditField_2Label.Text = 'displacementLabel';
            app.displacementLabelEditField_crush = uieditfield(app.labelscrushtestandstiffnesscorrelationTab, 'text');
            app.displacementLabelEditField_crush.Value = 'Standard travel';
            app.forceLabelEditField_2Label = uilabel(app.labelscrushtestandstiffnesscorrelationTab);
            app.forceLabelEditField_2Label.Text = 'forceLabel';
            app.forceLabelEditField_crush = uieditfield(app.labelscrushtestandstiffnesscorrelationTab, 'text');
            app.forceLabelEditField_crush.Value = 'Standard force';
            app.diameterInitialLabel = uilabel(app.labelscrushtestandstiffnesscorrelationTab);
            app.diameterInitialLabel.Text = 'diameterInitialLabel';
            app.diameterInitialLabelEditField = uieditfield(app.labelscrushtestandstiffnesscorrelationTab, 'text');
            app.diameterInitialLabelEditField.Value = 'Diameter';
            app.lengthInitialLabelEditField_2Label_2 = uilabel(app.labelscrushtestandstiffnesscorrelationTab);
            app.lengthInitialLabelEditField_2Label_2.Text = 'lengthInitialLabel';
            app.lengthInitialLabelEditField_crush = uieditfield(app.labelscrushtestandstiffnesscorrelationTab, 'text');
            app.lengthInitialLabelEditField_crush.Value = 'Specimen height';
            app.timeLabelEditField_2Label_2 = uilabel(app.labelscrushtestandstiffnesscorrelationTab);
            app.timeLabelEditField_2Label_2.Text = 'timeLabel';
            app.timeLabelEditField_crush = uieditfield(app.labelscrushtestandstiffnesscorrelationTab, 'text');
            app.timeLabelEditField_crush.Value = 'Test time';
            app.helpButton_labelsTT_2 = uibutton(app.labelscrushtestandstiffnesscorrelationTab, 'push');
            app.helpButton_labelsTT_2.ButtonPushedFcn = createCallbackFcn(app, @helpButton_labelsTT_2Pushed, true);
            app.helpButton_labelsTT_2.Text = 'help';
            app.datamanipulationTab = uitab(app.TabGroup);
            app.datamanipulationTab.Title = 'data manipulation';
            app.Panel_tensile_bulge = uipanel(app.datamanipulationTab);
            app.DropDown_emodulus = uidropdown(app.Panel_tensile_bulge);
            app.DropDown_emodulus.ValueChangedFcn = createCallbackFcn(app, @DropDown_emodulusValueChanged, true);
            app.EModulusButton = uibutton(app.Panel_tensile_bulge, 'push');
            app.EModulusButton.ButtonPushedFcn = createCallbackFcn(app, @EModulusButtonPushed, true);
            app.EModulusButton.Text = 'E-Modulus';
            app.choosedataforEModuluscalculationLabel = uilabel(app.Panel_tensile_bulge);
            app.choosedataforEModuluscalculationLabel.Text = 'choose data for E Modulus calculation';
            app.TextArea = uitextarea(app.Panel_tensile_bulge);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Only necessary if the basic data series (bevore averaging) have been manipulated '};
            app.reaveragedataButton = uibutton(app.Panel_tensile_bulge, 'push');
            app.reaveragedataButton.ButtonPushedFcn = createCallbackFcn(app, @reaveragedataButtonPushed, true);
            app.reaveragedataButton.Text = 're-average data';
            app.manipulate_basic = uibutton(app.Panel_tensile_bulge, 'push');
            app.manipulate_basic.ButtonPushedFcn = createCallbackFcn(app, @manipulate_basicButtonPushed, true);
            app.manipulate_basic.Text = 'manipulate';
            app.DropDown_basic = uidropdown(app.Panel_tensile_bulge);
            app.DropDown_basic.ValueChangedFcn = createCallbackFcn(app, @DropDown_basicValueChanged, true);
            app.manipulatefitthedataseriesbevoraveragingLabel = uilabel(app.Panel_tensile_bulge);
            app.manipulatefitthedataseriesbevoraveragingLabel.Text = 'manipulate (fit) the data series bevor averaging';
            app.maipualte_averaged = uibutton(app.Panel_tensile_bulge, 'push');
            app.maipualte_averaged.ButtonPushedFcn = createCallbackFcn(app, @maipualte_averagedButtonPushed, true);
            app.maipualte_averaged.Text = 'manipulate';
            app.DropDown_averaged = uidropdown(app.Panel_tensile_bulge);
            app.manipulatefitaverageddataseriesLabel = uilabel(app.Panel_tensile_bulge);
            app.manipulatefitaverageddataseriesLabel.Text = 'manipulate (fit) averaged data series';
            app.comparebasicdataseriesButton = uibutton(app.Panel_tensile_bulge, 'state');
            app.comparebasicdataseriesButton.ValueChangedFcn = createCallbackFcn(app, @comparebasicdataseriesButtonValueChanged, true);
            app.comparebasicdataseriesButton.Text = 'compare basic data series';
            app.displayButton = uibutton(app.Panel_tensile_bulge, 'state');
            app.displayButton.ValueChangedFcn = createCallbackFcn(app, @displayButtonValueChanged, true);
            app.displayButton.Text = 'display';
            app.ButtonGroup = uibuttongroup(app.datamanipulationTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.tensiletestButton = uitogglebutton(app.ButtonGroup);
            app.tensiletestButton.Text = 'tensile test';
            app.tensiletestButton.Value = true;
            app.bulgetestButton = uitogglebutton(app.ButtonGroup);
            app.bulgetestButton.Text = 'bulge test';
            app.UIAxes = uiaxes(app.datamanipulationTab);
            title(app.UIAxes, 'averaged data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.load_data_label = uilabel(app.datamanipulationTab);
            app.load_data_label.Text = {'You have to load some data first'; ''; 'nothing to do here yet ;)'};
            app.returnButton_4 = uibutton(app.datamanipulationTab, 'push');
            app.returnButton_4.ButtonPushedFcn = createCallbackFcn(app, @returnButton_4Pushed, true);
            app.returnButton_4.Text = 'return';
            app.Panel_crush = uipanel(app.datamanipulationTab);
            app.manipulatefitaveragedcrushdataLabel = uilabel(app.Panel_crush);
            app.manipulatefitaveragedcrushdataLabel.Text = 'manipulate (fit) averaged crush data';
            app.maipualte_averaged_crush = uibutton(app.Panel_crush, 'push');
            app.maipualte_averaged_crush.ButtonPushedFcn = createCallbackFcn(app, @maipualte_averaged_crushButtonPushed, true);
            app.maipualte_averaged_crush.Text = 'manipulate';
            app.manipulatefitcrushdatabevoreaveragingLabel = uilabel(app.Panel_crush);
            app.manipulatefitcrushdatabevoreaveragingLabel.Text = 'manipulate (fit) crush data bevore averaging';
            app.manipulate_basic_crush = uibutton(app.Panel_crush, 'push');
            app.manipulate_basic_crush.ButtonPushedFcn = createCallbackFcn(app, @manipulate_basic_crushButtonPushed, true);
            app.manipulate_basic_crush.Text = 'manipulate';
            app.replotreaveragedataButton_crush = uibutton(app.Panel_crush, 'push');
            app.replotreaveragedataButton_crush.ButtonPushedFcn = createCallbackFcn(app, @replotreaveragedataButton_crushPushed, true);
            app.replotreaveragedataButton_crush.Text = 're-average data';
            app.choosedataforEModuluscalculationLabel_2 = uilabel(app.Panel_crush);
            app.choosedataforEModuluscalculationLabel_2.Text = 'choose data for E Modulus calculation';
            app.EModulusButton_crush = uibutton(app.Panel_crush, 'push');
            app.EModulusButton_crush.ButtonPushedFcn = createCallbackFcn(app, @EModulusButton_crushPushed, true);
            app.EModulusButton_crush.Text = 'E-Modulus';
            app.displayButton_2 = uibutton(app.Panel_crush, 'state');
            app.displayButton_2.ValueChangedFcn = createCallbackFcn(app, @displayButton_2ValueChanged, true);
            app.displayButton_2.Text = 'display';
            app.resetdataButton = uibutton(app.datamanipulationTab, 'push');
            app.resetdataButton.ButtonPushedFcn = createCallbackFcn(app, @resetdataButtonPushed, true);
            app.resetdataButton.Text = 'reset data';
            app.importindicesButton = uibutton(app.datamanipulationTab, 'push');
            app.importindicesButton.ButtonPushedFcn = createCallbackFcn(app, @importindicesButtonPushed, true);
            app.importindicesButton.Text = 'import indices';
            app.DropDown_index = uidropdown(app.datamanipulationTab);
            app.DropDown_index.Items = {'averaged index', 'base index'};
            app.DropDown_index.Value = 'averaged index';
            app.saveindicesButton = uibutton(app.datamanipulationTab, 'push');
            app.saveindicesButton.ButtonPushedFcn = createCallbackFcn(app, @saveindicesButtonPushed, true);
            app.saveindicesButton.Text = 'save indices';
            app.rnvalueboundarydeterminationTab = uitab(app.TabGroup);
            app.rnvalueboundarydeterminationTab.Title = 'r-,n-value boundary determination';
            app.rvalueboundarydeterminationinpercentLabel = uilabel(app.rnvalueboundarydeterminationTab);
            app.rvalueboundarydeterminationinpercentLabel.Text = 'r-value boundary determination in percent';
            app.UIAxes_r = uiaxes(app.rnvalueboundarydeterminationTab);
            title(app.UIAxes_r, 'r-value')
            xlabel(app.UIAxes_r, 'X')
            ylabel(app.UIAxes_r, 'Y')
            app.UIAxes_n = uiaxes(app.rnvalueboundarydeterminationTab);
            title(app.UIAxes_n, 'n-value')
            xlabel(app.UIAxes_n, 'X')
            ylabel(app.UIAxes_n, 'Y')
            app.UITable_rvalue = uitable(app.rnvalueboundarydeterminationTab);
            app.UITable_rvalue.ColumnName = {'lower boundary'; 'upper boundary'};
            app.UITable_rvalue.RowName = {};
            app.UITable_rvalue.ColumnEditable = true;
            app.UITable_rvalue.CellEditCallback = createCallbackFcn(app, @UITable_rvalueCellEdit, true);
            app.TextArea3 = uitextarea(app.rnvalueboundarydeterminationTab);
            app.TextArea3.Editable = 'off';
            app.TextArea3.Value = {'By typing own values in the table above, you can specify the lower and upper boundary for the calculation of the r-value, for each direction.'; 'Furthermore you can specify the Poissions number used below.'};
            app.PoissonsnumberEditFieldLabel = uilabel(app.rnvalueboundarydeterminationTab);
            app.PoissonsnumberEditFieldLabel.Text = 'Poissons number';
            app.PoissonsnumberEditField = uieditfield(app.rnvalueboundarydeterminationTab, 'numeric');
            app.PoissonsnumberEditField.Value = 0.3;
            app.returnButton_3 = uibutton(app.rnvalueboundarydeterminationTab, 'push');
            app.returnButton_3.ButtonPushedFcn = createCallbackFcn(app, @returnButton_3Pushed, true);
            app.returnButton_3.Text = 'return';
            app.load_data_label_r = uilabel(app.rnvalueboundarydeterminationTab);
            app.load_data_label_r.Text = {'You have to load some data first'; ''; 'nothing to do here yet ;)'};
            app.UITable_nvalue = uitable(app.rnvalueboundarydeterminationTab);
            app.UITable_nvalue.ColumnName = {'lower boundary'; 'upper boundary'};
            app.UITable_nvalue.RowName = {};
            app.UITable_nvalue.ColumnEditable = true;
            app.UITable_nvalue.CellEditCallback = createCallbackFcn(app, @UITable_nvalueCellEdit, true);
            app.nvalueboundarydeterminationinnumbersyouseeintheplotLabel = uilabel(app.rnvalueboundarydeterminationTab);
            app.nvalueboundarydeterminationinnumbersyouseeintheplotLabel.Text = {'n-value boundary determination'; 'in numbers you see in the plot'};
            app.extrapolationapproachTab = uitab(app.TabGroup);
            app.extrapolationapproachTab.Title = 'extrapolation approach';
            app.UIAxes4 = uiaxes(app.extrapolationapproachTab);
            title(app.UIAxes4, 'extrapolation approach')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.DirectionLabel = uilabel(app.extrapolationapproachTab);
            app.DirectionLabel.Text = 'Direction:';
            app.DirectionDropDown = uidropdown(app.extrapolationapproachTab);
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDownValueChanged, true);
            app.LudwikCheckBox = uicheckbox(app.extrapolationapproachTab);
            app.LudwikCheckBox.ValueChangedFcn = createCallbackFcn(app, @LudwikCheckBoxValueChanged, true);
            app.LudwikCheckBox.Text = 'Ludwik';
            app.GoshCheckBox = uicheckbox(app.extrapolationapproachTab);
            app.GoshCheckBox.ValueChangedFcn = createCallbackFcn(app, @GoshCheckBoxValueChanged, true);
            app.GoshCheckBox.Text = 'Gosh';
            app.HockettSherbyCheckBox = uicheckbox(app.extrapolationapproachTab);
            app.HockettSherbyCheckBox.ValueChangedFcn = createCallbackFcn(app, @HockettSherbyCheckBoxValueChanged, true);
            app.HockettSherbyCheckBox.Text = 'Hockett Sherby';
            app.SwiftCheckBox = uicheckbox(app.extrapolationapproachTab);
            app.SwiftCheckBox.ValueChangedFcn = createCallbackFcn(app, @SwiftCheckBoxValueChanged, true);
            app.SwiftCheckBox.Text = 'Swift';
            app.VoceCheckBox = uicheckbox(app.extrapolationapproachTab);
            app.VoceCheckBox.ValueChangedFcn = createCallbackFcn(app, @VoceCheckBoxValueChanged, true);
            app.VoceCheckBox.Text = 'Voce';
            app.ApproachLabel = uilabel(app.extrapolationapproachTab);
            app.ApproachLabel.Text = 'Approach:';
            app.firstselectionDropDown = uidropdown(app.extrapolationapproachTab);
            app.firstselectionDropDown.Items = {'Swift', 'Ludwik', 'Ghosh', 'Hockett Sherby', 'Voce'};
            app.firstselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @firstselectionDropDownValueChanged, true);
            app.firstselectionDropDown.Value = 'Swift';
            app.secondselectionDropDown = uidropdown(app.extrapolationapproachTab);
            app.secondselectionDropDown.Items = {'Hockett Sherby', 'Ludwik', 'Ghosh', 'Swift', 'Voce'};
            app.secondselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @secondselectionDropDownValueChanged, true);
            app.secondselectionDropDown.Value = 'Hockett Sherby';
            app.SelectedapproachforbulgeextrapolationLabel = uilabel(app.extrapolationapproachTab);
            app.SelectedapproachforbulgeextrapolationLabel.Text = 'Selected approach for bulge extrapolation';
            app.returnButton_2 = uibutton(app.extrapolationapproachTab, 'push');
            app.returnButton_2.ButtonPushedFcn = createCallbackFcn(app, @returnButton_2Pushed, true);
            app.returnButton_2.Text = 'return';
            app.UITable_approach = uitable(app.extrapolationapproachTab);
            app.UITable_approach.ColumnName = {'first selection'; 'second selection'};
            app.UITable_approach.RowName = {};
            app.DirectionDropDown_2Label = uilabel(app.extrapolationapproachTab);
            app.DirectionDropDown_2Label.Text = 'Direction';
            app.DirectionDropDown_2 = uidropdown(app.extrapolationapproachTab);
            app.DirectionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDown_2ValueChanged, true);
            app.ButtonGroup_2 = uibuttongroup(app.extrapolationapproachTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.exportextrapolationapproachButton = uitogglebutton(app.ButtonGroup_2);
            app.exportextrapolationapproachButton.Text = 'export extrapolation approach';
            app.exportextrapolationapproachButton.Value = true;
            app.bulgeextrapolaitionapproachButton = uitogglebutton(app.ButtonGroup_2);
            app.bulgeextrapolaitionapproachButton.Text = 'bulge extrapolaition approach';
            app.SelectedapproachforexportextrapolationLabel = uilabel(app.extrapolationapproachTab);
            app.SelectedapproachforexportextrapolationLabel.Text = 'Selected approach for export extrapolation';
            app.UITable_approach_export = uitable(app.extrapolationapproachTab);
            app.UITable_approach_export.ColumnName = {'export extrapolation approach'};
            app.UITable_approach_export.RowName = {};
            app.approachDropDown = uidropdown(app.extrapolationapproachTab);
            app.approachDropDown.Items = {'Swift', 'Ludwik', 'Ghosh', 'Hockett Sherby', 'Voce', 'None'};
            app.approachDropDown.ValueChangedFcn = createCallbackFcn(app, @approachDropDownValueChanged, true);
            app.approachDropDown.Value = 'Swift';
            app.FirstapproachLabel = uilabel(app.extrapolationapproachTab);
            app.FirstapproachLabel.Text = 'First approach';
            app.SecondapproachLabel = uilabel(app.extrapolationapproachTab);
            app.SecondapproachLabel.Text = 'Second approach';
            app.extrapolationparametersTab = uitab(app.TabGroup);
            app.extrapolationparametersTab.Title = 'extrapolation parameters';
            app.ExtrapolationApproachStartPointsPanel = uipanel(app.extrapolationparametersTab);
            app.ExtrapolationApproachStartPointsPanel.Title = 'Extrapolation Approach Start Points';
            app.L1 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.L1.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.L1.Value = 1;
            app.L2 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.L2.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.L2.Value = 0.5;
            app.LudwikLabel = uilabel(app.ExtrapolationApproachStartPointsPanel);
            app.LudwikLabel.Text = 'Ludwik';
            app.G1 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.G1.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.G1.Value = 1;
            app.G2 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.G2.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.G2.Value = 0.002;
            app.G3 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.G3.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.G3.Value = 0.5;
            app.GoshLabel = uilabel(app.ExtrapolationApproachStartPointsPanel);
            app.GoshLabel.Text = 'Gosh';
            app.HS1 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.HS1.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.HS1.Value = 400;
            app.HS2 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.HS2.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.HS2.Value = 1.5;
            app.HS3 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.HS3.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.HS3.Value = 0.58;
            app.HocketSherbyLabel_3 = uilabel(app.ExtrapolationApproachStartPointsPanel);
            app.HocketSherbyLabel_3.Text = 'Hocket Sherby';
            app.S1 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.S1.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.S1.Value = 1;
            app.S2 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.S2.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.S2.Value = 0.002;
            app.S3 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.S3.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.S3.Value = 0.5;
            app.SwiftLabel = uilabel(app.ExtrapolationApproachStartPointsPanel);
            app.SwiftLabel.Text = 'Swift';
            app.V1 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.V1.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.V1.Value = 400;
            app.V2 = uieditfield(app.ExtrapolationApproachStartPointsPanel, 'numeric');
            app.V2.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.V2.Value = 1.5;
            app.VoceLabel = uilabel(app.ExtrapolationApproachStartPointsPanel);
            app.VoceLabel.Text = 'Voce';
            app.UpdateParametersButton = uibutton(app.extrapolationparametersTab, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = 'Update Parameters';
            app.FreespacetoaddsomemoreparametersLabel = uilabel(app.extrapolationparametersTab);
            app.FreespacetoaddsomemoreparametersLabel.Text = 'Free space to add some more parameters';
            app.ParameterUpdated = uilamp(app.extrapolationparametersTab);
            app.VisualisationTab = uitab(app.TabGroup);
            app.VisualisationTab.Title = 'Visualisation';
            app.UIAxes2 = uiaxes(app.VisualisationTab);
            title(app.UIAxes2, 'title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.selectdatatoplotDropDownLabel = uilabel(app.VisualisationTab);
            app.selectdatatoplotDropDownLabel.Text = 'select data to plot';
            app.selectdatatoplotDropDown = uidropdown(app.VisualisationTab);
            app.selectdatatoplotDropDown.Items = {};
            app.selectdatatoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @selectdatatoplotDropDownValueChanged, true);
            app.selectdatatoplotDropDown.Value = {};
            app.DirectionDropDown_3 = uidropdown(app.VisualisationTab);
            app.DirectionDropDown_3.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDown_3ValueChanged, true);
            app.createfigureButton = uibutton(app.VisualisationTab, 'push');
            app.createfigureButton.ButtonPushedFcn = createCallbackFcn(app, @createfigureButtonPushed, true);
            app.createfigureButton.Text = 'create figure';
            app.TextArea4 = uitextarea(app.VisualisationTab);
            app.TextArea4.Editable = 'off';
            app.TextArea4.Value = {'For more visualisation tools, or to save the plots.'};
            app.returnButton = uibutton(app.VisualisationTab, 'push');
            app.returnButton.ButtonPushedFcn = createCallbackFcn(app, @returnButtonPushed, true);
            app.returnButton.Text = 'return';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Material_Modeler_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end